classdef Y_ShowPreRunData_MCQsimV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ViewAngle_EditField          matlab.ui.control.EditField
        ViewangleEditFieldLabel      matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        FigEditField                 matlab.ui.control.NumericEditField
        FigEditFieldLabel            matlab.ui.control.Label
        maxminvalueEditField         matlab.ui.control.EditField
        maxminvalueEditFieldLabel    matlab.ui.control.Label
        componenttoplotListBox       matlab.ui.control.ListBox
        componenttoplotListBoxLabel  matlab.ui.control.Label
        colormapPanel                matlab.ui.container.Panel
        holdonCheckBox               matlab.ui.control.CheckBox
        flipmapCheckBox              matlab.ui.control.CheckBox
        TurboCheckBox                matlab.ui.control.CheckBox
        GrayCheckBox                 matlab.ui.control.CheckBox
        SlipCheckBox                 matlab.ui.control.CheckBox
        BluetoRedCheckBox            matlab.ui.control.CheckBox
        RedCheckBox                  matlab.ui.control.CheckBox
        ParulaCheckBox               matlab.ui.control.CheckBox
        PlotPreRundataButton         matlab.ui.control.Button
        LoadPreRundataButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        GriddedFault    = struct;
        GriddedBound    = struct;
        DataLoaded      = 0;
        BlueWhiteRed = [0	0	0.500;0	0	0.523;0	0	0.547;0	0	0.570;0	0	0.594;0	0	0.617;0	0	0.640;0	0	0.664;0	0	0.687;0	0	0.710;0	0	0.734;0	0	0.757;0	0	0.781;0	0	0.804;0	0	0.827;0	0	0.851;0	0	0.874;0	0	0.897;0	0	0.921;0	0	0.944;0	0	0.968;0	0	0.991;0.0120	0.0120	1;0.0310	0.0310	1;0.0500	0.0500	1;0.0700	0.0700	1;0.0890	0.0890	1;0.108	0.108	1;0.128	0.128	1;0.147	0.147	1;0.166	0.166	1;0.185	0.185	1;0.205	0.205	1;0.224	0.224	1;0.243	0.243	1;0.263	0.263	1;0.282	0.282	1;0.301	0.301	1;0.321	0.321	1;0.340	0.340	1;0.359	0.359	1;0.379	0.379	1;0.398	0.398	1;0.417	0.417	1;0.436	0.436	1;0.456	0.456	1;0.475	0.475	1;0.494	0.494	1;0.514	0.514	1;0.533	0.533	1;0.552	0.552	1;0.572	0.572	1;0.591	0.591	1;0.610	0.610	1;0.630	0.630	1;0.649	0.649	1;0.668	0.668	1;0.687	0.687	1;0.707	0.707	1;0.726	0.726	1;0.745	0.745	1;0.765	0.765	1;0.784	0.784	1;0.825	0.800	0.975;0.975	0.800	0.825;1	0.784	0.784;1	0.765	0.765;1	0.745	0.745;1	0.726	0.726;1	0.707	0.707;1	0.687	0.687;1	0.668	0.668;1	0.649	0.649;1	0.630	0.630;1	0.610	0.610;1	0.591	0.591;1	0.572	0.572;1	0.552	0.552;1	0.533	0.533;1	0.514	0.514;1	0.494	0.494;1	0.475	0.475;1	0.456	0.456;1	0.436	0.436;1	0.417	0.417;1	0.398	0.398;1	0.379	0.379;1	0.359	0.359;1	0.340	0.340;1	0.321	0.321;1	0.301	0.301;1	0.282	0.282;1	0.263	0.263;1	0.243	0.243;1	0.224	0.224;1	0.205	0.205;1	0.185	0.185;1	0.166	0.166;1	0.147	0.147;1	0.128	0.128;1	0.108	0.108;1	0.0890	0.0890;1	0.0700	0.0700;1	0.0500	0.0500;1	0.0310	0.0310;1	0.0120	0.0120;0.991	0	0;0.968	0	0;0.944	0	0;0.921	0	0;0.897	0	0;0.874	0	0;0.851	0	0;0.827	0	0;0.804	0	0;0.781	0	0;0.757	0	0;0.734	0	0;0.710	0	0;0.687	0	0;0.664	0	0;0.640	0	0;0.617	0	0;0.594	0	0;0.570	0	0;0.547	0	0;0.523	0	0;0.500	0	0];
        Cols4Slip    = [1	1	1; 0.9450	0.9840	1;  0.8900	0.9670	1;0.8350	0.9500	1;0.7800	0.9340	1;0.7250	0.9170	1;0.6700	0.9010	1;0.6150	0.8840	1;0.5590	0.8680	1;0.5040	0.8510	1;0.4480	0.8340	1;0.3930	0.8180	1;0.3380	0.8020	1;0.2830	0.7850	1;0.2280	0.7690	1;0.1730	0.7520	1;0.1180	0.7350	1;0.06300	0.7190	1;0.008000	0.7020	1;0	0.7050	0.9530;0	0.7100	0.8980;0	0.7160	0.8430;0	0.7210	0.7880;0	0.7270	0.7330;0	0.7320	0.6770;0	0.7370	0.6220;0	0.7430	0.5670;0	0.7490	0.5120;0	0.7550	0.4560;0	0.7600	0.4010;0	0.7660	0.3460;0	0.7710	0.2910;0	0.7770	0.2360;0	0.7820	0.1810;0	0.7880	0.1260;0	0.7930	0.07100;0	0.7980	0.01600;0.02000	0.8080	0;0.04800	0.8190	0;0.07500	0.8300	0;0.1020	0.8410	0;0.1300	0.8520	0;0.1580	0.8630	0;0.1850	0.8740	0;0.2120	0.8850	0;0.2400	0.8960	0;0.2680	0.9070	0;0.2950	0.9180	0;0.3230	0.9290	0;0.3500	0.9400	0;0.3780	0.9510	0;0.4050	0.9630	0;0.4330	0.9730	0;0.4600	0.9840	0;0.4880	0.9950	0;0.5160	1	0;0.5440	1	0;0.5710	1	0;0.5980	1	0;0.6260	1	0;0.6540	1	0;0.6810	1	0;0.7090	1	0;0.7360	1	0;0.7640	1	0;0.7910	1	0;0.8190	1	0;0.8460	1	0;0.8740	1	0;0.9020	1	0;0.9290	1	0;0.9560	1	0;0.9840	1	0;1	0.9880	0;1	0.9600	0;1	0.9330	0;1	0.9050	0;1	0.8780	0;1	0.8500	0;1	0.8230	0;1	0.7950	0;1	0.7680	0;1	0.7400	0;1	0.7120	0;1	0.6850	0;1	0.6580	0;1	0.6300	0;1	0.6020	0;1	0.5750	0;1	0.5480	0;1	0.5200	0;1	0.4920	0;1	0.4640	0;1	0.4370	0;1	0.4090	0;1	0.3820	0;1	0.3540	0;1	0.3270	0;1	0.2990	0;1	0.2720	0;1	0.2440	0;1	0.2160	0;1	0.1890	0;1	0.1620	0;1	0.1340	0;1	0.1060	0;1	0.07900	0;1	0.05200	0;1	0.02400	0;0.9960	0	0;0.9680	0	0;0.9410	0	0;0.9130	0	0;0.8860	0	0;0.8580	0	0;0.8300	0	0;0.8030	0	0;0.7760	0	0;0.7480	0	0;0.7200	0	0;0.6930	0	0;0.6660	0	0;0.6380	0	0;0.6100	0	0;0.5830	0	0;0.5560	0	0;0.5280	0	0;0.5000	0	0];           

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ParulaCheckBox
        function ParulaCheckBoxValueChanged(app, event)
            app.ParulaCheckBox.Value    = 1;
            app.RedCheckBox.Value       = 0;
            app.BluetoRedCheckBox.Value = 0;
            app.SlipCheckBox.Value      = 0;
            app.GrayCheckBox.Value      = 0;
            app.TurboCheckBox.Value     = 0;
        end

        % Value changed function: RedCheckBox
        function RedCheckBoxValueChanged(app, event)
            app.ParulaCheckBox.Value    = 0;
            app.RedCheckBox.Value       = 1;
            app.BluetoRedCheckBox.Value = 0;
            app.SlipCheckBox.Value      = 0;
            app.GrayCheckBox.Value      = 0;
            app.TurboCheckBox.Value     = 0;
        end

        % Value changed function: BluetoRedCheckBox
        function BluetoRedCheckBoxValueChanged(app, event)
            app.ParulaCheckBox.Value    = 0;
            app.RedCheckBox.Value       = 0;
            app.BluetoRedCheckBox.Value = 1;
            app.SlipCheckBox.Value      = 0;
            app.GrayCheckBox.Value      = 0;
            app.TurboCheckBox.Value     = 0;    
        end

        % Value changed function: SlipCheckBox
        function SlipCheckBoxValueChanged(app, event)
            app.ParulaCheckBox.Value    = 0;
            app.RedCheckBox.Value       = 0;
            app.BluetoRedCheckBox.Value = 0;
            app.SlipCheckBox.Value      = 1;
            app.GrayCheckBox.Value      = 0;
            app.TurboCheckBox.Value     = 0;  
        end

        % Value changed function: GrayCheckBox
        function GrayCheckBoxValueChanged(app, event)
            app.ParulaCheckBox.Value    = 0;
            app.RedCheckBox.Value       = 0;
            app.BluetoRedCheckBox.Value = 0;
            app.SlipCheckBox.Value      = 0;
            app.GrayCheckBox.Value      = 1;
            app.TurboCheckBox.Value     = 0;           
        end

        % Value changed function: TurboCheckBox
        function TurboCheckBoxValueChanged(app, event)
            app.ParulaCheckBox.Value    = 0;
            app.RedCheckBox.Value       = 0;
            app.BluetoRedCheckBox.Value = 0;
            app.SlipCheckBox.Value      = 0;
            app.GrayCheckBox.Value      = 0;
            app.TurboCheckBox.Value     = 1;           
        end

        % Value changed function: componenttoplotListBox
        function componenttoplotListBoxValueChanged(app, event)
            if (app.DataLoaded == 0),       return;                 end
            %-----------------------------------------
            SelPara    = str2double(app.componenttoplotListBox.Value); 
            formatSpec = '%.2e';
            %-----------------------------------------
            if (SelPara <= 9),                      PlottedComp = app.GriddedFault.Parameter(:,SelPara);
            else,                                   PlottedComp = app.GriddedBound.Parameter(:,SelPara-9);
            end
            %-----------------------------------------
            minVal = min(PlottedComp(:));           maxVal      = max(PlottedComp(:));
            app.maxminvalueEditField.Value = strcat(num2str(minVal,formatSpec),"  ",num2str(maxVal,formatSpec));
            %-----------------------------------------
        end

        % Button pushed function: LoadPreRundataButton
        function LoadPreRundataButtonPushed(app, event)
       
            %-------------------------------------
            [LoadName,LoadPath] = uigetfile('*PreRunData.dat','Load selected parameter');
            if     (LoadName ~=0),      ParaPath  = strcat(LoadPath,LoadName);
            elseif (LoadName ==0),      return;         
            end
            fidbin    = fopen(ParaPath ,'rb');      
            if (fidbin == -1), errordlg('The entered filename has not been found.');    return;   end
            app.Lamp.Color = 'yellow';
            %-------------------------------------
            FPatchNum      = fread(fidbin,1,'uint32');
            FVertNum       = fread(fidbin,1,'uint32');
            BPatchNum      = fread(fidbin,1,'uint32');
            BVertNum       = fread(fidbin,1,'uint32');
            %-----------------------------------------   
            app.GriddedFault.SizeTrigList    = FPatchNum;                   app.GriddedFault.SizeVertList    = FVertNum;
            app.GriddedBound.SizeTrigList    = BPatchNum;                   app.GriddedBound.SizeVertList    = BVertNum;

            %-----------------------
            app.GriddedFault.TriangleList       = zeros(FPatchNum,5);       app.GriddedFault.GlobalVertices  = zeros(FVertNum,4); 
            app.GriddedFault.GlobCentPos        = zeros(FPatchNum,3);       app.GriddedFault.Parameter       = zeros(FPatchNum,11);
            
            app.GriddedBound.TriangleList       = zeros(BPatchNum,3);       app.GriddedBound.GlobalVertices  = zeros(BVertNum,3); 
            app.GriddedBound.Parameter          = zeros(BPatchNum,4);
            %-----------------------
            app.GriddedFault.TriangleList(:,1)  = fread(fidbin,FPatchNum,'uint32');
            app.GriddedFault.TriangleList(:,2)  = fread(fidbin,FPatchNum,'uint32');
            app.GriddedFault.TriangleList(:,3)  = fread(fidbin,FPatchNum,'uint32');
            app.GriddedFault.TriangleList(:,4)  = fread(fidbin,FPatchNum,'uint32');
            app.GriddedFault.TriangleList(:,5)  = fread(fidbin,FPatchNum,'uint32');
            
            app.GriddedFault.TriangleList(:,1)  = app.GriddedFault.TriangleList(:,1) +1;
            app.GriddedFault.TriangleList(:,2)  = app.GriddedFault.TriangleList(:,2) +1;
            app.GriddedFault.TriangleList(:,3)  = app.GriddedFault.TriangleList(:,3) +1;
            
            app.GriddedFault.GlobalVertices(:,1)= fread(fidbin,FVertNum,'single');
            app.GriddedFault.GlobalVertices(:,2)= fread(fidbin,FVertNum,'single');
            app.GriddedFault.GlobalVertices(:,3)= fread(fidbin,FVertNum,'single');
            app.GriddedFault.GlobalVertices(:,4)= fread(fidbin,FVertNum,'single');
            
            app.GriddedFault.GlobCentPos(:,1)   = fread(fidbin,FPatchNum,'single');
            app.GriddedFault.GlobCentPos(:,2)   = fread(fidbin,FPatchNum,'single');
            app.GriddedFault.GlobCentPos(:,3)   = fread(fidbin,FPatchNum,'single');
            
            if (BPatchNum >0)
                app.GriddedBound.TriangleList(:,1)  = fread(fidbin,BPatchNum,'uint32');
                app.GriddedBound.TriangleList(:,2)  = fread(fidbin,BPatchNum,'uint32');
                app.GriddedBound.TriangleList(:,3)  = fread(fidbin,BPatchNum,'uint32');
                app.GriddedBound.GlobalVertices(:,1)= fread(fidbin,BVertNum,'single');
                app.GriddedBound.GlobalVertices(:,2)= fread(fidbin,BVertNum,'single');
                app.GriddedBound.GlobalVertices(:,3)= fread(fidbin,BVertNum,'single');
                
                app.GriddedBound.TriangleList(:,1)  = app.GriddedBound.TriangleList(:,1) +1;
                app.GriddedBound.TriangleList(:,2)  = app.GriddedBound.TriangleList(:,2) +1;
                app.GriddedBound.TriangleList(:,3)  = app.GriddedBound.TriangleList(:,3) +1;
            end
            %-------------------------------------
            for ii = 1 : FPatchNum
                Vert1 = app.GriddedFault.TriangleList(ii,1);       Vert2 = app.GriddedFault.TriangleList(ii,2);       Vert3 = app.GriddedFault.TriangleList(ii,3);
                app.GriddedFault.Parameter(ii,1)  = (app.GriddedFault.GlobalVertices(Vert1,4) +app.GriddedFault.GlobalVertices(Vert2,4) +app.GriddedFault.GlobalVertices(Vert3,4))/3;
            end
            %-------------------------------------
            app.GriddedFault.Parameter(:,2)     = fread(fidbin,FPatchNum,'uint32'); %flagged status
            app.GriddedFault.Parameter(:,3)     = fread(fidbin,FPatchNum,'single'); %stressing strike
            app.GriddedFault.Parameter(:,4)     = fread(fidbin,FPatchNum,'single'); %stressin dip   
            app.GriddedFault.Parameter(:,5)     = sqrt(app.GriddedFault.Parameter(:,3).^2 +app.GriddedFault.Parameter(:,4).^2);
            app.GriddedFault.Parameter(:,6)     = fread(fidbin,FPatchNum,'single'); %strength initial
            app.GriddedFault.Parameter(:,7)     = fread(fidbin,FPatchNum,'single'); %stress drop initial
            app.GriddedFault.Parameter(:,8)     = fread(fidbin,FPatchNum,'single'); %D_c initial
            app.GriddedFault.Parameter(:,9)     = fread(fidbin,FPatchNum,'uint32');    %stability type
            
      %      app.GriddedFault.Parameter(:,9)     = app.GriddedFault.TriangleList(:,5);
            
            %-------------------------------------
            if (BPatchNum >0)
                app.GriddedBound.Parameter(:,1) = fread(fidbin,BPatchNum,'single'); % slip in strike
                app.GriddedBound.Parameter(:,2) = fread(fidbin,BPatchNum,'single'); % slip in dip
                app.GriddedBound.Parameter(:,3) = fread(fidbin,BPatchNum,'single'); % slip in open direction
                app.GriddedBound.Parameter(:,4) = sqrt(app.GriddedBound.Parameter(:,1).*app.GriddedBound.Parameter(:,1) +app.GriddedBound.Parameter(:,2).*app.GriddedBound.Parameter(:,2) +app.GriddedBound.Parameter(:,3).*app.GriddedBound.Parameter(:,3));
            end
            %-------------------------------------      
            fclose(fidbin);  
            %-------------------------------------  
            formatSpec = '%.2e';
            minVal = min(app.GriddedFault.Parameter(:,1));              maxVal = max(app.GriddedFault.Parameter(:,1));
            app.maxminvalueEditField.Value = strcat(num2str(minVal,formatSpec),"  ",num2str(maxVal,formatSpec));
            %-------------------------------------  
            app.DataLoaded = 1;
            app.Lamp.Color = 'green';
        end

        % Button pushed function: PlotPreRundataButton
        function PlotPreRundataButtonPushed(app, event)
            if (app.DataLoaded == 0),       return;                 end
            %---------------------------------------- 
            FigNum    = app.FigEditField.Value;
            ViewA     = app.ViewAngle_EditField.Value;                      ViewA     = sscanf(ViewA,'    %f')';
            MinMaxVal = app.maxminvalueEditField.Value;                     MinMax    = sscanf(MinMaxVal,'%f')';
            SelPara    = str2double(app.componenttoplotListBox.Value);      UseHold   = app.holdonCheckBox.Value;
            %----------------------------------------     
            figure(FigNum);  
            if (UseHold == 0),      clf;        end
            hold on; axis equal; %axis tight; 
            view(ViewA);
            xlabel('Easting (km)');ylabel('Northing (km)'); zlabel('Depth (km)');
            %-----------------------------------------------
            if (SelPara <= 9)
                PlottedComp     = app.GriddedFault.Parameter(:,SelPara);

% if SelPara == 2
%     PlottedComp     = (1: size(app.GriddedFault.TriangleList,1))';
% end

                AllV1s          = zeros(app.GriddedFault.SizeTrigList,3);
                AllV2s          = zeros(app.GriddedFault.SizeTrigList,3);
                AllV3s          = zeros(app.GriddedFault.SizeTrigList,3);
                Cntr            = 1;
                %-----------------------------------------------
                for j = 1 : size(app.GriddedFault.TriangleList,1)
                    AllV1s(Cntr,1) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,1),1);
                    AllV1s(Cntr,2) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,2),1);
                    AllV1s(Cntr,3) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,3),1);
            
                    AllV2s(Cntr,1) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,1),2);
                    AllV2s(Cntr,2) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,2),2);
                    AllV2s(Cntr,3) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,3),2);
            
                    AllV3s(Cntr,1) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,1),3);
                    AllV3s(Cntr,2) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,2),3);
                    AllV3s(Cntr,3) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,3),3);
                    Cntr           = Cntr +1;
                end
            elseif ((SelPara > 9) && (app.GriddedBound.SizeTrigList > 0))
                PlottedComp     = app.GriddedBound.Parameter(:,SelPara-9);
                AllV1s          = zeros(app.GriddedBound.SizeTrigList,3);
                AllV2s          = zeros(app.GriddedBound.SizeTrigList,3);
                AllV3s          = zeros(app.GriddedBound.SizeTrigList,3);
                Cntr            = 1;
                %-----------------------------------------------
                for j = 1 : size(app.GriddedBound.TriangleList,1)
                    AllV1s(Cntr,1) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,1),1);
                    AllV1s(Cntr,2) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,2),1);
                    AllV1s(Cntr,3) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,3),1);
            
                    AllV2s(Cntr,1) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,1),2);
                    AllV2s(Cntr,2) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,2),2);
                    AllV2s(Cntr,3) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,3),2);
            
                    AllV3s(Cntr,1) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,1),3);
                    AllV3s(Cntr,2) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,2),3);
                    AllV3s(Cntr,3) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,3),3);
                    Cntr           = Cntr +1;
                end
            end
     %       min(PlottedComp(:) )
     %       max(PlottedComp(:) )
     %       mean(PlottedComp(:) )
            %-----------------------------------------------
            if (app.BluetoRedCheckBox.Value == 1)
                if (app.flipmapCheckBox.Value == 0),        colormap(       app.BlueWhiteRed);
                else,                                       colormap(flipud(app.BlueWhiteRed));
                end
            end
            if (app.SlipCheckBox.Value == 1)
                if (app.flipmapCheckBox.Value == 0),        colormap(       app.Cols4Slip);
                else,                                       colormap(flipud(app.Cols4Slip));
                end
            end
            if (app.RedCheckBox.Value == 1)
                if (app.flipmapCheckBox.Value == 0),        colormap(                'pink');
                else,                                       colormap(flipud(colormap('pink')));
                end
            end
            if (app.TurboCheckBox.Value == 1)
                if (app.flipmapCheckBox.Value == 0),        colormap(                'turbo');
                else,                                       colormap(flipud(colormap('turbo')));
                end
            end
            if (app.GrayCheckBox.Value == 1)
                if (app.flipmapCheckBox.Value == 0),        colormap(                'gray');
                else,                                       colormap(flipud(colormap('gray')));
                end
            end
            if (app.ParulaCheckBox.Value == 1)
                if (app.flipmapCheckBox.Value == 0),        colormap(                'parula');
                else,                                       colormap(flipud(colormap('parula')));
                end
            end 
            colorbar;
            %-----------------------------%-----------------------------
            patch(AllV1s',AllV2s',AllV3s',PlottedComp','EdgeColor','none');      
            if (MinMax(1,1) < MinMax(1,2)),         caxis([MinMax(1,1), MinMax(1,2)]);          end

%set(gca,'color',[0.7 0.7 0.7])
%set(gcf,'color',[0.7 0.7 0.7])
            
            % AllV1s = [];
% AllV2s = [];
% AllV3s = [];
% AllV1s(1,1) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(1,1),1);
% AllV1s(1,2) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(1,2),1);
% AllV1s(1,3) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(1,3),1);
% 
% AllV2s(1,1) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(1,1),2);
% AllV2s(1,2) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(1,2),2);
% AllV2s(1,3) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(1,3),2);
% 
% AllV3s(1,1) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(1,1),3);
% AllV3s(1,2) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(1,2),3);
% AllV3s(1,3) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(1,3),3);
% 
% patch(AllV1s',AllV2s',AllV3s','magenta');      
% % 
% %             %-----------------------------%-----------------------------
% %             BinValues = linspace(min(PlottedComp),max(PlottedComp),100);
% %             %---------------------%-------
% %             [SelYvals,SelXvals] = histcounts(PlottedComp,BinValues);        
% %             SelXvals(1:end-1)   = SelXvals(1:end-1) + (SelXvals(1,2)-SelXvals(1,1))/2;
% %             SelXvals            = SelXvals(1:end-1);
% %             
% %             SelYvals = cumsum(SelYvals);
% %             SelYvals = SelYvals - min(SelYvals);
% %             SelYvals = SelYvals./max(SelYvals);
% % 
% %             figure(FigNum*10+1);  hold on;
% %             plot(SelXvals(1:end), SelYvals(1:end),'o-','LineWidth',1);    

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadPreRundataButton = uibutton(app.UIFigure, 'push');
            app.LoadPreRundataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPreRundataButtonPushed, true);
            app.LoadPreRundataButton.Text = 'Load PreRun data';
            app.PlotPreRundataButton = uibutton(app.UIFigure, 'push');
            app.PlotPreRundataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPreRundataButtonPushed, true);
            app.PlotPreRundataButton.Text = 'Plot PreRun data';
            app.colormapPanel = uipanel(app.UIFigure);
            app.colormapPanel.Title = 'colormap';
            app.ParulaCheckBox = uicheckbox(app.colormapPanel);
            app.ParulaCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParulaCheckBoxValueChanged, true);
            app.ParulaCheckBox.Text = 'Parula';
            app.ParulaCheckBox.Value = true;
            app.RedCheckBox = uicheckbox(app.colormapPanel);
            app.RedCheckBox.ValueChangedFcn = createCallbackFcn(app, @RedCheckBoxValueChanged, true);
            app.RedCheckBox.Text = 'Red';
            app.BluetoRedCheckBox = uicheckbox(app.colormapPanel);
            app.BluetoRedCheckBox.ValueChangedFcn = createCallbackFcn(app, @BluetoRedCheckBoxValueChanged, true);
            app.BluetoRedCheckBox.Text = 'Blue to Red';
            app.SlipCheckBox = uicheckbox(app.colormapPanel);
            app.SlipCheckBox.ValueChangedFcn = createCallbackFcn(app, @SlipCheckBoxValueChanged, true);
            app.SlipCheckBox.Text = 'Slip';
            app.GrayCheckBox = uicheckbox(app.colormapPanel);
            app.GrayCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrayCheckBoxValueChanged, true);
            app.GrayCheckBox.Text = 'Gray';
            app.TurboCheckBox = uicheckbox(app.colormapPanel);
            app.TurboCheckBox.ValueChangedFcn = createCallbackFcn(app, @TurboCheckBoxValueChanged, true);
            app.TurboCheckBox.Text = 'Turbo';
            app.flipmapCheckBox = uicheckbox(app.colormapPanel);
            app.flipmapCheckBox.Text = 'flip map';
            app.holdonCheckBox = uicheckbox(app.colormapPanel);
            app.holdonCheckBox.Text = 'hold on';
            app.componenttoplotListBoxLabel = uilabel(app.UIFigure);
            app.componenttoplotListBoxLabel.Text = 'component to plot';
            app.componenttoplotListBox = uilistbox(app.UIFigure);
            app.componenttoplotListBox.Items = {'Fault, roughness', 'Fault, interaction flagged', 'Fault, strike stress rate', 'Fault, dip stress rate', 'Fault, comb. stress rate', 'Fault strength', 'Fault stress drop', 'Fault D_c', 'Fault stab-type', 'Boundary, strike slip-rate', 'Boundary, dip slip-rate', 'Boundary, open slip-rate', 'Boundary, comb. slip-rate'};
            app.componenttoplotListBox.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.componenttoplotListBox.ValueChangedFcn = createCallbackFcn(app, @componenttoplotListBoxValueChanged, true);
            app.componenttoplotListBox.Value = '1';
            app.maxminvalueEditFieldLabel = uilabel(app.UIFigure);
            app.maxminvalueEditFieldLabel.Text = 'max/min value';
            app.maxminvalueEditField = uieditfield(app.UIFigure, 'text');
            app.FigEditFieldLabel = uilabel(app.UIFigure);
            app.FigEditFieldLabel.Text = 'Fig #';
            app.FigEditField = uieditfield(app.UIFigure, 'numeric');
            app.FigEditField.Value = 1;
            app.Lamp = uilamp(app.UIFigure);
            app.ViewangleEditFieldLabel = uilabel(app.UIFigure);
            app.ViewangleEditFieldLabel.Text = 'View angle';
            app.ViewAngle_EditField = uieditfield(app.UIFigure, 'text');
            app.ViewAngle_EditField.Value = '40 20';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Y_ShowPreRunData_MCQsimV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end