classdef Z1_Output_MCQsimV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        wseismomentCheckBox           matlab.ui.control.CheckBox
        PlotHockeyStickButton         matlab.ui.control.Button
        BinMaxEditField               matlab.ui.control.NumericEditField
        binnumberLabel_3              matlab.ui.control.Label
        BinMinrEditField              matlab.ui.control.NumericEditField
        binnumberLabel_2              matlab.ui.control.Label
        BinNumberEditField            matlab.ui.control.NumericEditField
        binnumberLabel                matlab.ui.control.Label
        wcolorXaxisselectionCheckBox  matlab.ui.control.CheckBox
        PlotMomentCentroidLocButton   matlab.ui.control.Button
        PlotHypoCenterLocButton       matlab.ui.control.Button
        gridCheckBox                  matlab.ui.control.CheckBox
        FigEditField                  matlab.ui.control.NumericEditField
        FigEditFieldLabel             matlab.ui.control.Label
        PlotCatalogButton             matlab.ui.control.Button
        MaxTimeEditField              matlab.ui.control.NumericEditField
        toEditField_2Label            matlab.ui.control.Label
        MinTimeEditField              matlab.ui.control.NumericEditField
        timefromEditFieldLabel        matlab.ui.control.Label
        Y_DropDown                    matlab.ui.control.DropDown
        X_DropDown                    matlab.ui.control.DropDown
        MaxMagEditField               matlab.ui.control.NumericEditField
        toEditFieldLabel              matlab.ui.control.Label
        MinMagEditField               matlab.ui.control.NumericEditField
        magnituderangefromEditFieldLabel  matlab.ui.control.Label
        maxmagnitudeEditField         matlab.ui.control.NumericEditField
        maxmagnitudeEditFieldLabel    matlab.ui.control.Label
        minmagnitudeEditField         matlab.ui.control.NumericEditField
        minmagnitudeEditFieldLabel    matlab.ui.control.Label
        YaxisDropDown                 matlab.ui.control.DropDown
        YaxisDropDownLabel            matlab.ui.control.Label
        XaxisDropDown                 matlab.ui.control.DropDown
        XaxisDropDownLabel            matlab.ui.control.Label
        catalogeventnumberEditField   matlab.ui.control.NumericEditField
        catalogeventnumberEditFieldLabel  matlab.ui.control.Label
        cataloglengthyearsEditField   matlab.ui.control.NumericEditField
        cataloglengthyearsEditFieldLabel  matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        LoadCatalogButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        EQcatalog = []; % Description
        CatalogLoaded = 0; % Description
        ShearMod = 0;
        DeltaT   = 0;
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadCatalogButton
        function LoadCatalogButtonPushed(app, event)
            %-------------------------------------
            [LoadName,LoadPath] = uigetfile('*Catalog.dat','Load level-1 catalog');
            if     (LoadName ~=0),      ParaPath  = strcat(LoadPath,LoadName);
            elseif (LoadName ==0),      return;         
            end
            fidbin    = fopen(ParaPath ,'rb');      
            if (fidbin == -1), errordlg('The entered filename has not been found.');    return;   end
            app.Lamp.Color = 'yellow';
            %-------------------------------------
            EQnum        = fread(fidbin,1,'uint32');
            CatType      = fread(fidbin,1,'uint32');
            app.ShearMod = fread(fidbin,1,'single');
            app.DeltaT   = fread(fidbin,1,'single');
            if (CatType < 1), disp('the catalog has the wrong type; only catalog 1 or higher can be read with this');  return;
            end
            if (CatType == 3)
                PatchNum = fread(fidbin,1,'uint32');
                VertNum  = fread(fidbin,1,'uint32');
                dumm1    = fread(fidbin,PatchNum,'uint32');
                dumm1    = fread(fidbin,PatchNum,'uint32');
                dumm1    = fread(fidbin,PatchNum,'uint32');
                dumm1    = fread(fidbin,PatchNum,'uint32');
                dumm1    = fread(fidbin,PatchNum,'uint32');
                dumm1    = fread(fidbin,VertNum,'single');
                dumm1    = fread(fidbin,VertNum,'single');
                dumm1    = fread(fidbin,VertNum,'single');
                dumm1    = fread(fidbin,VertNum,'single'); %is the element's height
 
                dumm1    = fread(fidbin,PatchNum,'single');
                dumm1    = fread(fidbin,PatchNum,'single');
                dumm1    = fread(fidbin,PatchNum,'single');
            end
            %-----------------------------------------   
            app.EQcatalog = zeros(11, EQnum);
            %-----------------------------------------   
            for i = 1 : EQnum
                app.EQcatalog(1,i)  = fread(fidbin,1,'double'); %EQ time
                app.EQcatalog(2,i)  = fread(fidbin,1,'single'); %EQ magnitude
                app.EQcatalog(8,i)  = fread(fidbin,1,'single'); %Hypocenter_E
                app.EQcatalog(9,i)  = fread(fidbin,1,'single'); %Hypocenter_N
                app.EQcatalog(6,i)  = fread(fidbin,1,'single'); %Hypocenter_Z
                app.EQcatalog(10,i) = fread(fidbin,1,'single'); %MomCentroid_E
                app.EQcatalog(11,i) = fread(fidbin,1,'single'); %MomCentroid_N
                app.EQcatalog(7,i)  = fread(fidbin,1,'single'); %MomCentroid_Z
                app.EQcatalog(3,i)  = fread(fidbin,1,'single'); %RuptArea
                app.EQcatalog(4,i)  = fread(fidbin,1,'single'); %MeanSlip
                app.EQcatalog(5,i)  = fread(fidbin,1,'single'); %Mean Stress drop
                %-----------------------------------------  
                if ((CatType == 2)&&(i < EQnum))
                    dumm1      = fread(fidbin,1,'uint32');
                    dumm2      = fread(fidbin,dumm1,'float');
                end
                %-----------------------------------------  
                if ((CatType == 3)&&(i < EQnum))
                    dumm1      = fread(fidbin,1,'uint32');
                    dumm2      = fread(fidbin,dumm1,'float');
                    dumm1      = fread(fidbin,1,'uint32');
                    dumm2      = fread(fidbin,dumm1,'uint32');
                    dumm2      = fread(fidbin,dumm1,'uint32');
                    dumm2      = fread(fidbin,dumm1,'float');
                    dumm2      = fread(fidbin,dumm1,'float');
                    dumm2      = fread(fidbin,dumm1,'float');
                    dumm2      = fread(fidbin,dumm1,'uint32');
                end
            end   
            fclose(fidbin);  
            %-----------------------------------------  
            app.EQcatalog(3,:) =  app.EQcatalog(3,:) *1E-6; 
            app.EQcatalog(5,:) =  app.EQcatalog(5,:) *1E-6; 
            app.cataloglengthyearsEditField.Value = (max(app.EQcatalog(1,:)) - min(app.EQcatalog(1,:)));
            app.catalogeventnumberEditField.Value = EQnum;
            app.minmagnitudeEditField.Value = min(app.EQcatalog(2,:));
            app.maxmagnitudeEditField.Value = max(app.EQcatalog(2,:));
            app.MinMagEditField.Value = min(app.EQcatalog(2,:));
            app.MaxMagEditField.Value = max(app.EQcatalog(2,:));
            app.MinTimeEditField.Value = min(app.EQcatalog(1,:));
            app.MaxTimeEditField.Value = max(app.EQcatalog(1,:));
            %-----------------------------------------  
            app.Lamp.Color = 'green';
            app.CatalogLoaded = 1;
        end

        % Value changed function: X_DropDown
        function X_DropDownValueChanged(app, event)
            value = app.X_DropDown.Value;
            
        end

        % Value changed function: Y_DropDown
        function Y_DropDownValueChanged(app, event)
            value = app.Y_DropDown.Value;
            
        end

        % Button pushed function: PlotCatalogButton
        function PlotCatalogButtonPushed(app, event)
            FigNum = app.FigEditField.Value;
            GridOn = app.gridCheckBox.Value;
            BinMin = app.BinMinrEditField.Value;
            BinMax = app.BinMaxEditField.Value;
            BinNum = app.BinNumberEditField.Value;
            %-------------------------------
            Xsel = app.XaxisDropDown.Value;
            Ysel = app.YaxisDropDown.Value;
            Xlinlog = app.X_DropDown.Value;
            Ylinlog = app.Y_DropDown.Value;
            MinMag = app.MinMagEditField.Value; 
            MaxMag = app.MaxMagEditField.Value;
            MinTim = app.MinTimeEditField.Value;
            MaxTim = app.MaxTimeEditField.Value;
            %-------------------------------
            SelCatalog = app.EQcatalog;
            SelCatalog = SelCatalog(:,SelCatalog(1,:) >= MinTim);
            SelCatalog = SelCatalog(:,SelCatalog(1,:) <= MaxTim);
            SelCatalog = SelCatalog(:,SelCatalog(2,:) >= MinMag);
            SelCatalog = SelCatalog(:,SelCatalog(2,:) <= MaxMag);
            %-------------------------------
           
            Sel_X = SelCatalog(Xsel,:);
            Sel_Y = SelCatalog(Ysel,:);
            if (Xsel == 8),     Sel_X = [0, diff(SelCatalog(1,:))];     end
            if (Ysel == 8),     Sel_Y = [0, diff(SelCatalog(1,:))];     end
            %-------------------------------
            figure(FigNum); hold on;   if (GridOn == 1),     grid on;        end
            %-------------------------------
            if (Ysel <= 8)
                plot(Sel_X,Sel_Y,'.','color','b');
                if     ((Xlinlog == 1) && (Ylinlog == 1)),  set(gca,'Xscale','linear','YScale','linear');
                elseif ((Xlinlog == 1) && (Ylinlog == 2)),  set(gca,'Xscale','linear','YScale','log');
                elseif ((Xlinlog == 2) && (Ylinlog == 1)),  set(gca,'Xscale','log','YScale','linear');
                elseif ((Xlinlog == 2) && (Ylinlog == 2)),  set(gca,'Xscale','log','YScale','log');
                end
            end
            %-------------------------------
            if (Ysel > 8)
                if (BinMin == BinMax),      BinMin    = min(Sel_X);     BinMax = max(Sel_X);        end
                if (BinNum > 1),            BinValues = linspace(BinMin,BinMax,BinNum);
                else,                       BinValues = linspace(BinMin, BinMax,round(sqrt(size(Sel_X,2))));
                end
                %------------------------
                [SelYvals,SelXvals] = histcounts(Sel_X,BinValues);     
                SelXvals(1:end-1)   = SelXvals(1:end-1) + (SelXvals(1,2)-SelXvals(1,1))/2;
                SelXvals            = SelXvals(1:end-1);
                %SelYvals = SelYvals./sum(SelYvals); 
                %------------------------
                if (Ysel == 9)
                    bar(SelXvals, SelYvals); 
                    if     ((Xlinlog == 1) && (Ylinlog == 1)),  set(gca,'Xscale','linear','YScale','linear');
                    elseif ((Xlinlog == 1) && (Ylinlog == 2)),  set(gca,'Xscale','linear','YScale','log');
                    elseif ((Xlinlog == 2) && (Ylinlog == 1)),  set(gca,'Xscale','log','YScale','linear');
                    elseif ((Xlinlog == 2) && (Ylinlog == 2)),  set(gca,'Xscale','log','YScale','log');
                    end
                elseif (Ysel == 10)
                    SelYvals = fliplr(cumsum(fliplr(SelYvals)));
                    plot(SelXvals, SelYvals, 'o-','color','b','LineWidth',1); 
                    if     ((Xlinlog == 1) && (Ylinlog == 1)),  set(gca,'Xscale','linear','YScale','linear');
                    elseif ((Xlinlog == 1) && (Ylinlog == 2)),  set(gca,'Xscale','linear','YScale','log');
                    elseif ((Xlinlog == 2) && (Ylinlog == 1)),  set(gca,'Xscale','log','YScale','linear');
                    elseif ((Xlinlog == 2) && (Ylinlog == 2)),  set(gca,'Xscale','log','YScale','log');
                    end
                end
                %------------------------
            end
        end

        % Button pushed function: PlotHypoCenterLocButton
        function PlotHypoCenterLocButtonPushed(app, event)
            FigNum = app.FigEditField.Value*10+1;
            %-------------------------------
            MinMag = app.MinMagEditField.Value; 
            MaxMag = app.MaxMagEditField.Value;
            MinTim = app.MinTimeEditField.Value;
            MaxTim = app.MaxTimeEditField.Value;
            Xsel   = app.XaxisDropDown.Value;
            %-------------------------------
            SelCatalog = app.EQcatalog;
            SelCatalog = SelCatalog(:,SelCatalog(1,:) >= MinTim);
            SelCatalog = SelCatalog(:,SelCatalog(1,:) <= MaxTim);
            SelCatalog = SelCatalog(:,SelCatalog(2,:) >= MinMag);
            SelCatalog = SelCatalog(:,SelCatalog(2,:) <= MaxMag);
            %-------------------------------
            E = SelCatalog(8,:);         N = SelCatalog(9,:);         Z = SelCatalog(6,:);
            %-------------------------------
            figure(FigNum); clf; view(40,20);

            if (app.wcolorXaxisselectionCheckBox.Value == 0)
                plot3(E,N,Z,'o');
            else
                Sel_X = SelCatalog(Xsel,:);
                if (Xsel == 8),     Sel_X = [0, diff(SelCatalog(1,:))];     end
                scatter3(E,N,Z,12,Sel_X,'filled');         
            end
        end

        % Button pushed function: PlotMomentCentroidLocButton
        function PlotMomentCentroidLocButtonPushed(app, event)
            FigNum = app.FigEditField.Value*10+1;
            %-------------------------------
            MinMag = app.MinMagEditField.Value; 
            MaxMag = app.MaxMagEditField.Value;
            MinTim = app.MinTimeEditField.Value;
            MaxTim = app.MaxTimeEditField.Value;
            Xsel   = app.XaxisDropDown.Value;
            %-------------------------------
            SelCatalog = app.EQcatalog;
            SelCatalog = SelCatalog(:,SelCatalog(1,:) >= MinTim);
            SelCatalog = SelCatalog(:,SelCatalog(1,:) <= MaxTim);
            SelCatalog = SelCatalog(:,SelCatalog(2,:) >= MinMag);
            SelCatalog = SelCatalog(:,SelCatalog(2,:) <= MaxMag);
            %-------------------------------
            E = SelCatalog(10,:);         N = SelCatalog(11,:);         Z = SelCatalog(7,:);
            %-------------------------------
            figure(FigNum); clf; view(40,20);

            if (app.wcolorXaxisselectionCheckBox.Value == 0)
                plot3(E,N,Z,'o');
            else
                Sel_X = SelCatalog(Xsel,:);
                if (Xsel == 8),     Sel_X = [0, diff(SelCatalog(1,:))];     end
                scatter3(E,N,Z,12,Sel_X,'filled');         
            end
        end

        % Button pushed function: PlotHockeyStickButton
        function PlotHockeyStickButtonPushed(app, event)
            FigNum = app.FigEditField.Value*10+1;
            %-------------------------------
            MinMag = app.MinMagEditField.Value; 
            MaxMag = app.MaxMagEditField.Value;
            MinTim = app.MinTimeEditField.Value;
            MaxTim = app.MaxTimeEditField.Value;
            %-------------------------------
            SelCatalog = app.EQcatalog;
            SelCatalog = SelCatalog(:,SelCatalog(1,:) >= MinTim);
            SelCatalog = SelCatalog(:,SelCatalog(1,:) <= MaxTim);
            SelCatalog = SelCatalog(:,SelCatalog(2,:) >= MinMag);
            SelCatalog = SelCatalog(:,SelCatalog(2,:) <= MaxMag);
            %-------------------------------
            Xvalues    = SelCatalog(1,:);
            Yvalues    = 1:size(SelCatalog,2);
            if (app.wseismomentCheckBox.Value == 1)
                Yvalues = cumsum(SelCatalog(3,:).*SelCatalog(4,:).*30E+9.*1E+6);%the 30 is for 30GPa, the 1E+6 for km^2 to m^2
            end
            %-------------------------------
            figure(FigNum); clf; 
            plot(Xvalues,Yvalues,'.');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadCatalogButton = uibutton(app.UIFigure, 'push');
            app.LoadCatalogButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCatalogButtonPushed, true);
            app.LoadCatalogButton.Text = 'Load Catalog';
            app.Lamp = uilamp(app.UIFigure);
            app.cataloglengthyearsEditFieldLabel = uilabel(app.UIFigure);
            app.cataloglengthyearsEditFieldLabel.Text = 'catalog length (years)';
            app.cataloglengthyearsEditField = uieditfield(app.UIFigure, 'numeric');
            app.cataloglengthyearsEditField.Editable = 'off';
            app.catalogeventnumberEditFieldLabel = uilabel(app.UIFigure);
            app.catalogeventnumberEditFieldLabel.Text = 'catalog event number';
            app.catalogeventnumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.catalogeventnumberEditField.Editable = 'off';
            app.XaxisDropDownLabel = uilabel(app.UIFigure);
            app.XaxisDropDownLabel.Text = 'X-axis';
            app.XaxisDropDown = uidropdown(app.UIFigure);
            app.XaxisDropDown.Items = {'Time', 'Magnitude', 'RuptArea', 'MeanSlip', 'MeanDTau', 'HypoDepth', 'CentroidDepth', 'InterEventTime'};
            app.XaxisDropDown.ItemsData = [1 2 3 4 5 6 7 8];
            app.XaxisDropDown.Value = 1;
            app.YaxisDropDownLabel = uilabel(app.UIFigure);
            app.YaxisDropDownLabel.Text = 'Y-axis';
            app.YaxisDropDown = uidropdown(app.UIFigure);
            app.YaxisDropDown.Items = {'Time', 'Magnitude', 'RuptArea', 'MeanSlip', 'MeanDTau', 'HypoDepth', 'CentroidDepth', 'InterEventTime', 'Frequency', 'Frequency (cum)'};
            app.YaxisDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10];
            app.YaxisDropDown.Value = 2;
            app.minmagnitudeEditFieldLabel = uilabel(app.UIFigure);
            app.minmagnitudeEditFieldLabel.Text = 'min. magnitude';
            app.minmagnitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.minmagnitudeEditField.Editable = 'off';
            app.maxmagnitudeEditFieldLabel = uilabel(app.UIFigure);
            app.maxmagnitudeEditFieldLabel.Text = 'max. magnitude';
            app.maxmagnitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxmagnitudeEditField.Editable = 'off';
            app.magnituderangefromEditFieldLabel = uilabel(app.UIFigure);
            app.magnituderangefromEditFieldLabel.Text = 'magnitude range from';
            app.MinMagEditField = uieditfield(app.UIFigure, 'numeric');
            app.toEditFieldLabel = uilabel(app.UIFigure);
            app.toEditFieldLabel.Text = 'to';
            app.MaxMagEditField = uieditfield(app.UIFigure, 'numeric');
            app.X_DropDown = uidropdown(app.UIFigure);
            app.X_DropDown.Items = {'linear', 'log10'};
            app.X_DropDown.ItemsData = [1 2];
            app.X_DropDown.ValueChangedFcn = createCallbackFcn(app, @X_DropDownValueChanged, true);
            app.X_DropDown.Value = 1;
            app.Y_DropDown = uidropdown(app.UIFigure);
            app.Y_DropDown.Items = {'linear', 'log10'};
            app.Y_DropDown.ItemsData = [1 2];
            app.Y_DropDown.ValueChangedFcn = createCallbackFcn(app, @Y_DropDownValueChanged, true);
            app.Y_DropDown.Value = 1;
            app.timefromEditFieldLabel = uilabel(app.UIFigure);
            app.timefromEditFieldLabel.Text = 'time from';
            app.MinTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.toEditField_2Label = uilabel(app.UIFigure);
            app.toEditField_2Label.Text = 'to';
            app.MaxTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotCatalogButton = uibutton(app.UIFigure, 'push');
            app.PlotCatalogButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCatalogButtonPushed, true);
            app.PlotCatalogButton.Text = 'Plot Catalog';
            app.FigEditFieldLabel = uilabel(app.UIFigure);
            app.FigEditFieldLabel.Text = 'Fig #';
            app.FigEditField = uieditfield(app.UIFigure, 'numeric');
            app.FigEditField.Value = 2;
            app.gridCheckBox = uicheckbox(app.UIFigure);
            app.gridCheckBox.Text = 'grid';
            app.PlotHypoCenterLocButton = uibutton(app.UIFigure, 'push');
            app.PlotHypoCenterLocButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHypoCenterLocButtonPushed, true);
            app.PlotHypoCenterLocButton.Text = 'Plot HypoCenterLoc';
            app.PlotMomentCentroidLocButton = uibutton(app.UIFigure, 'push');
            app.PlotMomentCentroidLocButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMomentCentroidLocButtonPushed, true);
            app.PlotMomentCentroidLocButton.Text = 'Plot MomentCentroidLoc';
            app.wcolorXaxisselectionCheckBox = uicheckbox(app.UIFigure);
            app.wcolorXaxisselectionCheckBox.Text = 'w/ color (= X-axis selection)';
            app.binnumberLabel = uilabel(app.UIFigure);
            app.binnumberLabel.Text = 'bin number';
            app.BinNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.BinNumberEditField.RoundFractionalValues = 'on';
            app.BinNumberEditField.ValueDisplayFormat = '%d';
            app.binnumberLabel_2 = uilabel(app.UIFigure);
            app.binnumberLabel_2.Text = 'bin min';
            app.BinMinrEditField = uieditfield(app.UIFigure, 'numeric');
            app.BinMinrEditField.ValueDisplayFormat = '%3.4f';
            app.binnumberLabel_3 = uilabel(app.UIFigure);
            app.binnumberLabel_3.Text = 'bin max';
            app.BinMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.BinMaxEditField.ValueDisplayFormat = '%3.4f';
            app.PlotHockeyStickButton = uibutton(app.UIFigure, 'push');
            app.PlotHockeyStickButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHockeyStickButtonPushed, true);
            app.PlotHockeyStickButton.Text = 'Plot HockeyStick';
            app.wseismomentCheckBox = uicheckbox(app.UIFigure);
            app.wseismomentCheckBox.Text = 'w/ seis. moment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Z1_Output_MCQsimV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end