classdef B_RoughStrength_MCQsim_v1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HalfspaceDensity_EditField      matlab.ui.control.NumericEditField
        halfspacedensitykgm3EditFieldLabel  matlab.ui.control.Label
        LoadRoughnessStrength_Button    matlab.ui.control.Button
        SaveName_EditField              matlab.ui.control.EditField
        SavenameLabel                   matlab.ui.control.Label
        HalfspacePoisson_EditField      matlab.ui.control.NumericEditField
        PoissonsratioEditFieldLabel     matlab.ui.control.Label
        HalfspaceShearMod_EditField     matlab.ui.control.NumericEditField
        shearmodulusGPaLabel            matlab.ui.control.Label
        HalfspaceOverburden_EditField   matlab.ui.control.NumericEditField
        tooverburdenstressMPaEditFieldLabel  matlab.ui.control.Label
        addLabel                        matlab.ui.control.Label
        SaveRoughStrenth_Button         matlab.ui.control.Button
        SaveCurrentModelNum_EditField   matlab.ui.control.NumericEditField
        currmodelLabel                  matlab.ui.control.Label
        SaveModels2Generate_EditField   matlab.ui.control.NumericEditField
        ofmodelstogenerateEditFieldLabel  matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        FricDynFricVari_EditField       matlab.ui.control.NumericEditField
        variationLabel                  matlab.ui.control.Label
        SetTextArea_2                   matlab.ui.control.TextArea
        SetTextArea                     matlab.ui.control.TextArea
        SetStrengthFieldButton          matlab.ui.control.Button
        PlotWithRoughness_CheckBox      matlab.ui.control.CheckBox
        AverageStrength_EditField       matlab.ui.control.NumericEditField
        averagestrengthacrossallcellsEditFieldLabel  matlab.ui.control.Label
        PlotStrength_CheckBox           matlab.ui.control.CheckBox
        sameforallsectionsoffaultPanel  matlab.ui.container.Panel
        StrengthWaterLevel_EditField    matlab.ui.control.NumericEditField
        waterlevelcutoffLabel           matlab.ui.control.Label
        StrengthHurstExp_EditField      matlab.ui.control.NumericEditField
        HurstexponentEditFieldLabel     matlab.ui.control.Label
        StrengthCorrLengthDip_EditField  matlab.ui.control.NumericEditField
        CorrlengthinstrikeanddipkmEditField_2Label  matlab.ui.control.Label
        StrengthCorrLengthStrike_EditField  matlab.ui.control.NumericEditField
        andEditField_3Label             matlab.ui.control.Label
        StrengthSeed_DropDown           matlab.ui.control.DropDown
        StrengthUseFractalDistrib_CheckBox  matlab.ui.control.CheckBox
        FricGeoThermStartTemp_EditField  matlab.ui.control.NumericEditField
        degreestartingtemperatureatsurfaceLabel  matlab.ui.control.Label
        FricAllowReset_CheckBox         matlab.ui.control.CheckBox
        FricDcVari_EditField            matlab.ui.control.NumericEditField
        variEditFieldLabel              matlab.ui.control.Label
        FricDc_EditField                matlab.ui.control.NumericEditField
        D_cLabel                        matlab.ui.control.Label
        forweakeinguseLabel             matlab.ui.control.Label
        PlotStrength_Button             matlab.ui.control.Button
        GenerateStrength_Button         matlab.ui.control.Button
        FricGeoTherm_EditField          matlab.ui.control.NumericEditField
        geothermgradientKkmforabprofileEditFieldLabel  matlab.ui.control.Label
        FricShowAmB_ShowButton          matlab.ui.control.Button
        FricAmBType_DropDown            matlab.ui.control.DropDown
        FricUseAmB_CheckBox             matlab.ui.control.CheckBox
        FricDynFric_EditField           matlab.ui.control.NumericEditField
        dynfrictioncoeffLabel           matlab.ui.control.Label
        FricStatFricVari_EditField      matlab.ui.control.NumericEditField
        variationEditFieldLabel         matlab.ui.control.Label
        FricStatFric_EditField          matlab.ui.control.NumericEditField
        staticfrictioncoeffEditFieldLabel  matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        Use_OnlyCopy_CheckBox           matlab.ui.control.CheckBox
        RoughLengthScale_EditField      matlab.ui.control.NumericEditField
        kmroughnesslengthscaleLabel     matlab.ui.control.Label
        Use_SurfTraceCopyDown_CheckBox  matlab.ui.control.CheckBox
        ExtractSave_SurfTrace_Button    matlab.ui.control.Button
        Use_SurfTrace_CheckBox          matlab.ui.control.CheckBox
        Load_SurfTrace_Button           matlab.ui.control.Button
        RoughRMD_decay_EditField        matlab.ui.control.NumericEditField
        RMDdecayLabel                   matlab.ui.control.Label
        RoughNormalizePreFact_EditField  matlab.ui.control.NumericEditField
        normalizeprefactorwithlengthkmLabel  matlab.ui.control.Label
        ShowRoughnessStats_CheckBox     matlab.ui.control.CheckBox
        PlotRoughness_CheckBox          matlab.ui.control.CheckBox
        RMSL_EditField                  matlab.ui.control.NumericEditField
        RMSLEditFieldLabel              matlab.ui.control.Label
        CombinewGrid_Button             matlab.ui.control.Button
        GenerateRoughness_Button        matlab.ui.control.Button
        RoughPreFactVariType_DropDown   matlab.ui.control.DropDown
        RoughPreFactVari_EditField      matlab.ui.control.NumericEditField
        PrefactorvariabilityEditFieldLabel  matlab.ui.control.Label
        RoughPreFact_EditField          matlab.ui.control.NumericEditField
        PrefactorforunitlengthisfractionEditFieldLabel  matlab.ui.control.Label
        RoughNonLinScalingExp_EditField  matlab.ui.control.NumericEditField
        nonlinscalingexpEditFieldLabel  matlab.ui.control.Label
        RoughCornerWaveNum_EditField    matlab.ui.control.NumericEditField
        CornerwavenumberEditFieldLabel  matlab.ui.control.Label
        RoughFractalDimension_EditField  matlab.ui.control.NumericEditField
        FractaldimensionEditFieldLabel  matlab.ui.control.Label
        RoughHurstVari_EditField        matlab.ui.control.NumericEditField
        HurstexpvariabilityLabel        matlab.ui.control.Label
        RoughHurstDip_EditField         matlab.ui.control.NumericEditField
        HurstexpinstrikeanddipLabel     matlab.ui.control.Label
        RoughHurstStrike_EditField      matlab.ui.control.NumericEditField
        andEditField_2Label             matlab.ui.control.Label
        RoughHurstVariType_DropDown     matlab.ui.control.DropDown
        RoughCorrLengthVariType_DropDown  matlab.ui.control.DropDown
        RoughCorrLengthVari_EditField   matlab.ui.control.NumericEditField
        CorrlengthvariabilitykmEditFieldLabel  matlab.ui.control.Label
        RoughCorrLengthDip_EditField    matlab.ui.control.NumericEditField
        CorrlengthinstrikeanddipkmEditFieldLabel  matlab.ui.control.Label
        RoughCorrLengthStrike_EditField  matlab.ui.control.NumericEditField
        andEditFieldLabel               matlab.ui.control.Label
        RoughType_DropDown              matlab.ui.control.DropDown
        RoughSeed_DropDown              matlab.ui.control.DropDown
        Panel                           matlab.ui.container.Panel
        Plotfaults_Button               matlab.ui.control.Button
        ElementEditField                matlab.ui.control.NumericEditField
        ElementEditFieldLabel           matlab.ui.control.Label
        FigNum_EditField                matlab.ui.control.NumericEditField
        FigEditFieldLabel               matlab.ui.control.Label
        ViewAngle_EditField             matlab.ui.control.EditField
        ViewangleEditFieldLabel         matlab.ui.control.Label
        FaultList_ListBox               matlab.ui.control.ListBox
        FaultlistListBoxLabel           matlab.ui.control.Label
        LoadFaults_Button               matlab.ui.control.Button
    end

    
    properties (Access = private)
        %----------------------------------------------
        Color_Blue2Red =  [ 0	0	0.5000;     0	0	0.5313;     0	0	0.5625;     0	0	0.5938;...
            0	0	0.6250;     0	0	0.6563;     0	0	0.6875;     0	0	0.7188;...
            0	0	0.7500;     0	0	0.7813;     0	0	0.8125;     0	0	0.8438;...
            0	0	0.8750;     0	0	0.9063;     0	0	0.9375;     0	0	0.9688;...
            0	0	1;          0.02580	0.02580	1;  0.05160	0.05160	1;  0.07740	0.07740	1;...
            0.1032	0.1032	1;  0.1290	0.1290	1;  0.1548	0.1548	1;  0.1806	0.1806	1;...
            0.2065	0.2065	1;  0.2323	0.2323	1;  0.2581	0.2581	1;  0.2839	0.2839	1;...
            0.3097	0.3097	1;  0.3355	0.3355	1;  0.3613	0.3613	1;  0.3871	0.3871	1;...
            0.4129	0.4129	1;  0.4387	0.4387	1;  0.4645	0.4645	1;  0.4903	0.4903	1;...
            0.5161	0.5161	1;  0.5419	0.5419	1;  0.5677	0.5677	1;  0.5935	0.5935	1;...
            0.6194	0.6194	1;  0.6452	0.6452	1;  0.6710	0.6710	1;  0.6968	0.6968	1;...
            0.7226	0.7226	1;  0.7484	0.7484	1;  0.7742	0.7742	1;  0.8000	0.8000	1;...
            1	0.8000	0.8000; 1	0.7742	0.7742; 1	0.7484	0.7484; 1	0.7226	0.7226;...
            1	0.6968	0.6968; 1	0.6710	0.6710; 1	0.6452	0.6452; 1	0.6194	0.6194;...
            1	0.5935	0.5935; 1	0.5677	0.5677; 1	0.5419	0.5419; 1	0.5161	0.5161;...
            1	0.4903	0.4903; 1	0.4645	0.4645; 1	0.4387	0.4387; 1	0.4129	0.4129;...
            1	0.3871	0.3871; 1	0.3613	0.3613; 1	0.3355	0.3355; 1	0.3097	0.3097;...
            1	0.2839	0.2839; 1	0.2581	0.2581; 1	0.2323	0.2323; 1	0.2065	0.2065;...
            1	0.1806	0.1806; 1	0.1548	0.1548; 1	0.1290	0.1290; 1	0.1032	0.1032;...
            1	0.07740	0.0774; 1	0.05160	0.05160;1	0.02580	0.02580;    1	0	0;...
            0.9688	0	0;      0.9375	0	0;      0.9063	0	0;      0.8750	0	0;
            0.8438	0	0;      0.8125	0	0;      0.7813	0	0;      0.7500	0	0;...
            0.7188	0	0;      0.6875	0	0;      0.6563	0	0;      0.6250	0	0;
            0.5938	0	0;      0.5625	0	0;      0.5313	0	0;      0.5000	0	0];
        
        Gravity      = 9.81; %m/s^2
        AmBparabolic = @(x)4.7e-7.*x.^2 -0.00017.*x +0.006;
        AmB_p1       = @(x)-8.47e-5.*x +0.0038;
        AmB_p2       = @(x)-0.0042;
        AmB_p3       = @(x)0.00021.*x -0.0697;
        AmB_p4       = @(x)5.5e-5.*x -0.007;
        AmB_p1_temp  = [0,    93.2];
        AmB_p2_temp  = [93.2, 312];
        AmB_p3_temp  = [312,  401];
        AmB_p4_temp  = [401,  550];

        %----------------------------------------------
        SavePath            = [];
        GridImported        = 0;
        RoughnessGenerated  = 0;
        StrengthGenerated   = 0;
        FaultValues         = [];
        FaultNames          = [];
        GriddedFault        = struct;
        RoughParasPerFault  = [];
        StrgthParasPerSectn = [];
        RandParasRough      = struct;
        RandParasStrgt      = struct;
        RMSoverLength       = [];
        RoughFault          = struct;
            dlga            = 1 ;
        Manual_Strength     = struct ;
        Manual_Strength2     = struct ;
        Manual_Strength3     = struct ;

        Strength_Field      = struct;
                
        CallStrengthFromSave = 0;
        SurfTraceImported    = 0;
        SurfTraceVals        = [];
        %----------------------------------------------
    end
    
    methods (Access = private)
        %---------------------------------------%---------------------------------------
        %---------------------------------------%---------------------------------------
        function RoughSurf = SpecSyn2(app, N,samp,corr,acf,NonLinSclExp,PreFact,Rseed,ii)
            %  Written by Martin Mai (martin@seismo.ifg.ethz.ch)
            %  originally from 07/16/98, based on SRB-toolbox (Ph. Rio)
            %  last changes 03/01/2000; Nov. 2002;
            % Modified by Olaf Zielke for fault roughness generation
            %----------------------------------- % get data values on the correlation length/fractal dimension
            if  (strcmp(acf,'FR'))
                if (length(corr) == 2),              D = corr(1);        kc = corr(2);
                else,                                D = corr(1);        kc = 0.1;     %      disp('** Corner wavenumber kc not given: set to 0.1 **')
                end
            end
            if ((strcmp(acf,'EX')) || (strcmp(acf,'GS'))),      ax = corr(2);   az = corr(1);                   end
            if (strcmp(acf,'AK')),                              ax = corr(2);   az = corr(1);   H = corr(3);    end
            %---------------------------------- % set size for spectral synthesis tool that generates fields of size (2*rmz+1, 2*rmx+1), i.e. the method requires an ODD number of points in each direction
            nptsX = round(N(2)/samp(2));     %% number of grid-points in X
            nptsZ = round(N(1)/samp(1));     %% number of grid-points in Z
            
            if     mod(nptsX,2) == 0,       rmx = nptsX/2;
            elseif mod(nptsX,2) == 1,       rmx = (nptsX-1)/2;
            end
            if     mod(nptsZ,2) == 0,       rmz = nptsZ/2;
            elseif mod(nptsZ,2) == 1,       rmz = (nptsZ-1)/2;
            end
            %---------------------------------- % compose power spectrum for two of the four quadrant wavenumber vector in [-pi,pi]
            kx = (rmx./((2*rmx+1)*samp(2)))*linspace(-2*pi,2*pi,2*rmx+1);
            kz = (rmz./((2*rmz+1)*samp(1)))*linspace(-2*pi,2*pi,2*rmz+1);
            kr = zeros(rmz+1,2*rmx+1);
            %----------------------------------
            for j = 1:(2*rmx+1)
                for i = 1:(rmz+1)
                    if  (strcmp(acf,'FR'))
                        kr(i,j) = sqrt((kz(i)^2) + (kx(j)^2));
                    else
                        kr(i,j) = sqrt((az^2)*(kz(i)^2) + (ax^2)*(kx(j)^2));
                    end
                end
            end
            %---------------------------------- % calculate power spectral density, depending on selected ACF
            if (strcmp(acf,'GS')),                                                                                  PS = 0.5*ax*az*exp(-0.25*kr.^2);            end
            if (strcmp(acf,'EX')),                                                                                  PS = (ax * az)./(1 + (kr.^2)).^1.5;         end
            if (strcmp(acf,'AK')),      k  = kr(:); k = k(k>0);        coef = 4*pi*H*ax*az./besselk(H,min(k));      PS = coef./(1 + (kr.^2)).^(H+1);            end
            if (strcmp(acf,'FR'))
                decay = 0.5*(8-2*D); % to ensure proper scaling of the power spectrum  in k-space we cannot allow kr == 0
                if (min(kr(:)) == 0),  [p,q] = find(kr == 0);          k(p,q)= mean(mean(kr(p-1:p,q-1:q)));        end
                kr(kr <= pi*kc) = pi*kc;                               PS    = 1./((kr.^2).^decay);    % set values below k< kc to constant pi*kc
            end
            
            % the IFFT needs the spectrum normalized to max.amp unity
            PS = PS./max(PS(:));                            AM = sqrt(PS);
            % compose the random phase initialize random number generator
            rng(Rseed);
            % random phase in [0,pi]
            PH = 2*pi*rand(size(kr));
            % assemble the random field in FT-domain add small random high-wavenumber components
            %x = (1 + 0.5*randn(size(kr)));
            x = 1;
            RAD = AM.*x;
            % set DC-component to different value, if desired NOTE that this only changes the overall 'level' of
            % the field, not its appearance, but has significant impact on the Fourier transform which reflects the
            % DC-value at the smallest wavenumber ("Nugget Effect")
            Neff             = 0;               % "Nugget" value
            RAD(rmz+1,rmx+1) = Neff;		% "Nugget" effect, zero-mean field
            AM(rmz+1,rmx+1)  = RAD(rmz+1,rmx+1);
            RoughSurf = RAD.*cos(PH) + sqrt(-1)*RAD.*sin(PH);
            
            % the following takes care of the conjugate symmetry condition
            % in order to ensure the final random field is purely real
            U = zeros(2*rmz+1,2*rmx+1);	        %% will be conj. sym. field
            RoughSurf = [RoughSurf ; conj(fliplr(flipud(RoughSurf(1:rmz,:))))];
            %----------------------------------
            for i = 1:rmx
                RoughSurf(rmz+1,-i+2*rmx+2) = conj(RoughSurf(rmz+1,i));
            end
            for i = 1:rmz+1
                for j = 1:rmx+1
                    U(i,j) = RoughSurf(i+rmz,j+rmx);
                end
            end
            for i = rmz+2:2*rmz+1
                for j = rmx+2:2*rmx+1
                    U(i,j) = RoughSurf(i-rmz-1,j-rmx-1);
                end
            end
            for i = 1:rmz+1
                for j = rmx+2:2*rmx+1
                    U(i,j) = RoughSurf(i+rmz,j-rmx-1);
                end
            end
            for i = rmz+2:2*rmz+1
                for j = 1:rmx+1
                    U(i,j) = RoughSurf(i-1-rmz,j+rmx);
                end
            end
            %----------------------------------
            % take 2D-inverse FFT to obtain spatial field; imaginary parts
            % of order 1e-13 due to machine precision are removed;
            % also, remove mean and scale to unit variance
            RoughSurf = real(ifft2(U));
            
            %should detrend the surface
            A         = 1: size(RoughSurf,2);
            B         = 1: size(RoughSurf,1);
            [BB,AA]   = meshgrid(A,B);
            SurfPCA   = pca([AA(:), BB(:), RoughSurf(:)]);
            PlaneNorm = SurfPCA(:,3)./norm(SurfPCA(:,3));
            RoughSurf = (AA.*PlaneNorm(1,1) + BB.*PlaneNorm(2,1) + RoughSurf.*PlaneNorm(3,1));
            %---------------------------------------
            % perform a non-linear transformation, if desired, to create models with higher peak-roughness values
            if (NonLinSclExp ~= 1)
                RoughSurf = RoughSurf - min(RoughSurf);
                RoughSurf = RoughSurf.^NonLinSclExp;
                RoughSurf = RoughSurf - mean(RoughSurf);
            end
            %---------------------------------------
            app.SurfTraceVals(ii).TraceVals = RoughSurf(1,:);  

            RoughSurf = RoughSurf -mean(RoughSurf(:));
            RoughSurf = RoughSurf./std2(RoughSurf);		% standard deviation of unity
            RoughSurf = RoughSurf.*PreFact;

            
        end
        %---------------------------------------%---------------------------------------
        %---------------------------------------%---------------------------------------
        function RoughSurf = Synth2DFault_Candela2009(app, N,H_Stk,H_Dip,NonLinSclExp,PreFact,Rseed,ii)
            
            LStk      = 1/H_Stk;
            LDip      = 1/H_Dip;
            N         = N-1; %my own modification, b/c it seems more plausible that way...
            %--------------------------------
            X         = (-2*2^N:2:2*2^N)/(2^(N+1));              X(2^N+1) = 1/2^N;
            Y         = (-2*2^N:2:2*2^N)/(2^(N+1));              Y(2^N+1) = 1/2^N;
            XX        = X(ones(1,2*2^N+1),:);                    YY       = Y(ones(1,2*2^N+1),:)';
            clear X  Y;
            %--------------------------------
            rho       = sqrt(abs(XX).^(2/LStk) +abs(YY).^(2/LDip));
            clear XX YY;
            phi       = rho.^(1 +(LStk +LDip)/2);
            clear rho;
            %--------------------------------
            if isempty(Rseed) == 1,                         Rseed = rng('shuffle');
            end
            rng(Rseed);
            %--------------------------------
            Z         = randn(2*2^N+1,2*2^N+1);
            W         = fftshift(fft2(Z))./phi;
            clear Z;
            %--------------------------------
            T         = real(ifft2(ifftshift(W)));
            RoughSurf = T -T(2^N+1,2^N+1);
            %--------------------------------
            %should detrend the surface
            A         = 1: size(RoughSurf,2);
            B         = 1: size(RoughSurf,1);
            [BB,AA]   = meshgrid(A,B);
            SurfPCA   = pca([AA(:), BB(:), RoughSurf(:)]);
            PlaneNorm = SurfPCA(:,3)./norm(SurfPCA(:,3));
            RoughSurf = (AA.*PlaneNorm(1,1) + BB.*PlaneNorm(2,1) + RoughSurf.*PlaneNorm(3,1));
            %---------------------------------------
            % perform a non-linear transformation, if desired, to create models with higher peak-roughness values
            if (NonLinSclExp ~= 1)
                RoughSurf = RoughSurf - min(RoughSurf);
                RoughSurf = RoughSurf.^NonLinSclExp;
                RoughSurf = RoughSurf - mean(RoughSurf);
            end
            %---------------------------------------
            app.SurfTraceVals(ii).TraceVals = RoughSurf(1,:);  

            RoughSurf = RoughSurf -mean(RoughSurf(:));
            RoughSurf = RoughSurf./std2(RoughSurf);		% standard deviation of unity
            RoughSurf = RoughSurf.*PreFact;
        end
        %---------------------------------------%---------------------------------------
        %---------------------------------------%---------------------------------------
        function RoughSurf = RandomMidPointDisplacement(app, ItNum, RMDvals, FaultDim, Rseed, sampsize, FaultTrace, iii)
            
            %--------------------------------------
            if isempty(Rseed) == 1,                         Rseed = rng('shuffle');
            end
            rng(Rseed);
            %--------------------------------------
            ItNumStk     = ceil(log2(FaultDim(1,2)));
            ItNumDip     = ceil(log2(FaultDim(1,1)));
            SurfSize     = 2^ItNum;
            StkSize      = 2^ItNumStk;
            DipSize      = 2^ItNumDip;
            RoughSurf    = zeros(SurfSize+1,SurfSize+1);
            TopTrace     = zeros(1,SurfSize+1);
            BotTrace     = zeros(1,SurfSize+1);
            LftTrace     = zeros(SurfSize+1,1);
            RgtTrace     = zeros(SurfSize+1,1);
            dHght        = RMDvals(1,1)/app.RoughNormalizePreFact_EditField.Value * (SurfSize*mean(sampsize));

            %is length of generated square*patch size (so the fault length for example)*2*RAmp (the Amplitude,..)  dHght the amplitude, (so that effectively not +/-0.5*dHight but +/-1*dHght)
            dHghtCopy    = dHght;
            %--------------------------------------
            dHght        = dHghtCopy*(ItNumStk/ItNum);
            if (app.Use_SurfTrace_CheckBox.Value == 1)
                %TopTrace(1,1:FaultDim(1,2))  = interp1(1:size(FaultTrace,2),FaultTrace, 1:FaultDim(1,2),'linear');

                TopTrace = app.SurfTraceVals(iii).TraceVals;

            else
                for run = 0 : ItNumStk-1
                    if (run > 0),       dHght = dHght*2^(-RMDvals(1,2));              end
                    CrnrNum  = 2^run+1;
                    CrnrVal  = linspace(1,StkSize+1,CrnrNum);
                    
                    for run1 = 1 : CrnrNum-1
                        CPos_Stk1 = CrnrVal(1,run1);
                        CPos_Stk2 = CrnrVal(1,run1+1);
                        RandOffs  = (rand(1)*2-1)*dHght;
                        NewStk    = (CPos_Stk1 + CPos_Stk2)/2;
                        
                        if (NewStk <= FaultDim(1,2))
                            TopTrace(1,NewStk) = (TopTrace(1,CPos_Stk1) + TopTrace(1,CPos_Stk2) )/2 + RandOffs;
                end,end,end
            end
            %--------------------------------------
            dHght        = dHghtCopy*(ItNumStk/ItNum);
            if (app.Use_SurfTraceCopyDown_CheckBox.Value == 1)
                BotTrace     = TopTrace;
            else
                for run = 0 : ItNumStk-1
                    if (run > 0),       dHght = dHght*2^(-RMDvals(1,2));              end
                    CrnrNum  = 2^run+1;
                    CrnrVal  = linspace(1,StkSize+1,CrnrNum);
                    
                    for run1 = 1 : CrnrNum-1
                        CPos_Stk1 = CrnrVal(1,run1);
                        CPos_Stk2 = CrnrVal(1,run1+1);
                        RandOffs  = (rand(1)*2-1)*dHght;
                        NewStk    = (CPos_Stk1 + CPos_Stk2)/2;
                        
                        if (NewStk <= FaultDim(1,2))
                            BotTrace(1,NewStk) = (BotTrace(1,CPos_Stk1) + BotTrace(1,CPos_Stk2) )/2 + RandOffs;
                end,end,end
            end
            %--------------------------------------
            TopTrace = TopTrace - mean(TopTrace(1,1:FaultDim(1,2))); %TopTrace(1,FaultDim(1,2)+1:end) = 0;
            BotTrace = BotTrace - mean(BotTrace(1,1:FaultDim(1,2))); %BotTrace(1,FaultDim(1,2)+1:end) = 0;
            LftTrace(1,1) = TopTrace(1,1);                  LftTrace(FaultDim(1,1):end,1) = BotTrace(1,1);
            RgtTrace(1,1) = TopTrace(1,FaultDim(1,2));      RgtTrace(FaultDim(1,1):end,1) = BotTrace(1,FaultDim(1,2));
            %--------------------------------------
            dHght        = dHghtCopy*(ItNumDip/ItNum);
            for run = 0 : ItNumDip-1
                if (run > 0),       dHght = dHght*2^(-RMDvals(1,2));              end
                CrnrNum  = 2^run+1;
                CrnrVal  = linspace(1,DipSize+1,CrnrNum);
                
                for run1 = 1 : CrnrNum-1
                    CPos_Dip1 = CrnrVal(1,run1);
                    CPos_Dip2 = CrnrVal(1,run1+1);
                    RandOffs  = (rand(1)*2-1)*dHght;
                    NewDip    = (CPos_Dip1 + CPos_Dip2)/2;
                    
                    if (NewDip <= FaultDim(1,1))
                        LftTrace(NewDip,1) = (LftTrace(CPos_Dip1,1) + LftTrace(CPos_Dip2,1) )/2 + RandOffs;
            end,end,end
            %--------------------------------------
            dHght        = dHghtCopy*(ItNumDip/ItNum);
            for run = 0 : ItNumDip-1
                if (run > 0),       dHght = dHght*2^(-RMDvals(1,2));              end
                CrnrNum  = 2^run+1;
                CrnrVal  = linspace(1,DipSize+1,CrnrNum);
                
                for run1 = 1 : CrnrNum-1
                    CPos_Dip1 = CrnrVal(1,run1);    
                    CPos_Dip2 = CrnrVal(1,run1+1);
                    RandOffs  = (rand(1)*2-1)*dHght;
                    NewDip    = (CPos_Dip1 + CPos_Dip2)/2;
                    
                    if (NewDip <= FaultDim(1,1))
           
                        RgtTrace(NewDip,1) = (RgtTrace(CPos_Dip1,1) + RgtTrace(CPos_Dip2,1) )/2 + RandOffs;
            end,end,end
            %--------------------------------------
            RoughSurf(1,:) = TopTrace(1,1:end);             RoughSurf(:,1)  = LftTrace(1:end,1);
            for (ii = FaultDim(1,1):size(RoughSurf,1)),     RoughSurf(ii,:) = BotTrace(1,1:end);              end
            for (ii = FaultDim(1,2):size(RoughSurf,2)),     RoughSurf(:,ii) = RgtTrace(1:end,1);              end
            %--------------------------------------
            %--------------------------------------   
            dHght = dHghtCopy;
            for run = 0 : ItNum-1
                if (run > 0),               dHght = dHght*2^(-RMDvals(1,2));                    end
                CrnrNum      = 2^run+1;
                CrnrVal      = linspace(1,SurfSize+1,CrnrNum);

                for run1 = 1: CrnrNum-1
                    CPos_Stk1 = CrnrVal(1,run1);
                    CPos_Stk2 = CrnrVal(1,run1+1);
                    NewStk    = (CPos_Stk1 +CPos_Stk2)/2;
                    
                    for run2 = 1 : CrnrNum-1
                        CPos_Dip1 = CrnrVal(1,run2);
                        CPos_Dip2 = CrnrVal(1,run2+1);
                        NewDip    = (CPos_Dip1 + CPos_Dip2)/2;
                        RandOffs  = (rand(1)*2-1)*dHght;

                        MeanElev  = (RoughSurf(CPos_Dip1, CPos_Stk1) +RoughSurf(CPos_Dip1,CPos_Stk2) +RoughSurf(CPos_Dip2, CPos_Stk1) +RoughSurf(CPos_Dip2,CPos_Stk2)) /4;
                        NewElev   = MeanElev + RandOffs;
                        if  (NewDip < FaultDim(1,1)) && (NewStk < FaultDim(1,2))
                            RoughSurf(NewDip,NewStk) = NewElev;% here I first calculate  the new value for the center of a square (think of it as the point where two diagonals crossing)
                        end
                end,end
                %..........................................................
                for run1 = 1 : CrnrNum-1
                    CPos_Stk1 =  CrnrVal(1,run1);
                    CPos_Stk2 =  CrnrVal(1,run1+1);
                    NewStk    = (CPos_Stk1+ CPos_Stk2)/2;
                    StkDist   = abs(CPos_Stk1- CPos_Stk2)/2;

                    for run2 = 1 : CrnrNum-1
                        CPos_Dip1 =  CrnrVal(1,run2);
                        CPos_Dip2 =  CrnrVal(1,run2+1);
                        NewDip    = (CPos_Dip1+ CPos_Dip2)/2;
                        DipDist   = abs(CPos_Dip1- CPos_Dip2)/2;
                        %..................................................
                        NewPoints = [NewStk,CPos_Dip1; NewStk,CPos_Dip2; CPos_Stk1,NewDip; CPos_Stk2,NewDip];

                        for run3 = 1 : 4 %here is the square step then...
                            StkCoord = NewPoints(run3,1);
                            DipCoord = NewPoints(run3,2);
                            RandOffs = (rand(1)*2-1)*dHght;
                            
                            %.........................
                            if ((DipCoord > 1) && (DipCoord < FaultDim(1,1)) && (StkCoord >= 1) && (StkCoord <= FaultDim(1,2)) )
                                if     (run3 == 1)
                                    ExtraPt = CPos_Dip1 -DipDist;
                                    if (ExtraPt < 1)
                                        RoughSurf(DipCoord,StkCoord)  = (RoughSurf(NewDip,NewStk) +RoughSurf(NewDip,NewStk)  +RoughSurf(CPos_Dip1,CPos_Stk1) +RoughSurf(CPos_Dip1,CPos_Stk2))/4 + RandOffs;
                                    else
                                        RoughSurf(DipCoord,StkCoord)  = (RoughSurf(NewDip,NewStk) +RoughSurf(ExtraPt,NewStk) +RoughSurf(CPos_Dip1,CPos_Stk1) +RoughSurf(CPos_Dip1,CPos_Stk2))/4 + RandOffs;
                                    end
                                %.........................  
                                elseif (run3 == 2)
                                    ExtraPt = CPos_Dip2 +DipDist;
                                    if (ExtraPt > FaultDim(1,1))
                                        RoughSurf(DipCoord,StkCoord)  = (RoughSurf(NewDip,NewStk) +RoughSurf(NewDip,NewStk) +RoughSurf(CPos_Dip2,CPos_Stk1) +RoughSurf(CPos_Dip2,CPos_Stk2))/4 + RandOffs;
                                    else
                                        RoughSurf(DipCoord,StkCoord)  = (RoughSurf(NewDip,NewStk) +RoughSurf(ExtraPt,NewStk)+RoughSurf(CPos_Dip2,CPos_Stk1) +RoughSurf(CPos_Dip2,CPos_Stk2))/4 + RandOffs;
                                    end
                                %.........................
                                elseif (run3 == 3)
                                    ExtraPt  = CPos_Stk1 -StkDist;
                                    if (ExtraPt < 1)
                                        RoughSurf(DipCoord,StkCoord) = (RoughSurf(NewDip,NewStk) +RoughSurf(NewDip,NewStk)  +RoughSurf(CPos_Dip1,CPos_Stk1) +RoughSurf(CPos_Dip2,CPos_Stk1))/4 + RandOffs;
                                    else
                                        RoughSurf(DipCoord,StkCoord) = (RoughSurf(NewDip,NewStk) +RoughSurf(NewDip,ExtraPt) +RoughSurf(CPos_Dip1,CPos_Stk1) +RoughSurf(CPos_Dip2,CPos_Stk1))/4 + RandOffs;
                                    end
                                %.........................
                                elseif (run3 == 4)
                                    ExtraPt  = CPos_Stk2 +StkDist;
                                    if (ExtraPt > FaultDim(1,2))
                                        RoughSurf(DipCoord,StkCoord) = (RoughSurf(NewDip,NewStk) +RoughSurf(NewDip,NewStk)  +RoughSurf(CPos_Dip1,CPos_Stk2) +RoughSurf(CPos_Dip2,CPos_Stk2))/4 + RandOffs;
                                    else
                                        RoughSurf(DipCoord,StkCoord) = (RoughSurf(NewDip,NewStk) +RoughSurf(NewDip,ExtraPt) +RoughSurf(CPos_Dip1,CPos_Stk2) +RoughSurf(CPos_Dip2,CPos_Stk2))/4 + RandOffs;
                                    end
                                end
                                %.........................
                            end
                        end
                    end
                end
            end
            %--------------------------------------------------------------
            %--------------------------------------------------------------
             if (app.Use_SurfTrace_CheckBox.Value == 1)    
                RoughSurf = RoughSurf(1:FaultDim(1,1),1:FaultDim(1,2));
                RoughSurf = flipud(RoughSurf);
                RoughSurf = RoughSurf - mean(RoughSurf(:));

                RoughSurf = RoughSurf./std(RoughSurf(end,:));		% standard deviation of unity
                RoughSurf = RoughSurf.*RMDvals(1,1);
             else
                app.SurfTraceVals(iii).TraceVals = RoughSurf(1,:);  

                RoughSurf = RoughSurf(1:FaultDim(1,1),1:FaultDim(1,2));
                RoughSurf = flipud(RoughSurf);
                RoughSurf = RoughSurf - mean(RoughSurf(:));

             %   RoughSurf = RoughSurf./std2(RoughSurf);		% standard deviation of unity
                RoughSurf = RoughSurf./std(RoughSurf(end,:));		% standard deviation of unity
                RoughSurf = RoughSurf.*RMDvals(1,1);  
            end
            %--------------------------------------------------------------
            %--------------------------------------------------------------
        end
        %---------------------------------------%---------------------------------------
        %---------------------------------------%---------------------------------------
        function ShowRoughnessSpectrum(app, ii)
            figure(1000+ii);  hold on; set(gca,'xscale','log','yscale','log');
            
            Nx        = size(app.RoughFault(ii).FltSurface,1);          Ny        = size(app.RoughFault(ii).FltSurface,2);
            Nx_mod    = Nx -mod(Nx,2);              Ny_mod    = Ny -mod(Ny,2);
            samp      = [1/size(app.RoughFault(ii).FltSurface,1) 1/size(app.RoughFault(ii).FltSurface,2)];
            %------------------------------
            next_freq = 0:1:Ny_mod/2;               CombPower = zeros(1,size(next_freq,2));
            for i = 1 : Nx_mod
                next_xdft          = fft(app.RoughFault(ii).FltSurface(i,1:Ny_mod)).*samp(2);
                next_xdft          = next_xdft(1:Ny_mod/2+1);
                next_psdx          = (1/(samp(2)*Ny_mod))*abs(next_xdft).^2;
                next_psdx(2:end-1) = 2*next_psdx(2:end-1);
                CombPower          = CombPower +next_psdx;
            end
            CombPower = CombPower./Nx_mod;
            plot(next_freq,CombPower,'LineWidth',1);hold on;
            %------------------------------
            next_freq = 0:1:Nx_mod/2;               CombPower = zeros(1,size(next_freq,2));
            for i = 1 : Ny_mod
                next_xdft          = fft(app.RoughFault(ii).FltSurface(1:Nx_mod,i))'.*samp(1);
                next_xdft          = next_xdft(1:Nx_mod/2+1);
                next_psdx          = (1/(samp(1)*Nx_mod))*abs(next_xdft).^2;
                next_psdx(2:end-1) = 2*next_psdx(2:end-1);
                CombPower          = CombPower +next_psdx;
            end
            CombPower = CombPower./Ny_mod;
            plot(next_freq,CombPower,'LineWidth',1);
            
            title('Periodogram Using FFT');
            xlabel('Wavenumber');
            ylabel('Power/Wavenumber');
        end
        %---------------------------------------%---------------------------------------
        %---------------------------------------%---------------------------------------
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RoughSeed_DropDown
        function RoughSeed_DropDownValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,1)  = str2double(app.RoughSeed_DropDown.Value);
            end
        end

        % Value changed function: RoughType_DropDown
        function RoughType_DropDownValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,2) = str2double(app.RoughType_DropDown.Value);
            end
            
            SelRoughType = str2double(app.RoughType_DropDown.Value);
            if     (SelRoughType== 1)
                app.RoughCorrLengthStrike_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughCorrLengthDip_EditField.BackgroundColor    = [0.8 1 0.8];
                app.RoughCorrLengthVari_EditField.BackgroundColor   = [0.8 1 0.8];
                app.RoughHurstStrike_EditField.BackgroundColor      = [0.8 1 0.8];
                app.RoughHurstDip_EditField.BackgroundColor         = [0.9 0.9 0.9];
                app.RoughHurstVari_EditField.BackgroundColor        = [0.8 1 0.8];
                app.RoughRMD_decay_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughFractalDimension_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughCornerWaveNum_EditField.BackgroundColor    = [0.9 0.9 0.9];
                app.RoughNonLinScalingExp_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughPreFact_EditField.BackgroundColor          = [0.8 1 0.8];
                app.RoughPreFactVari_EditField.BackgroundColor      = [0.8 1 0.8];
                app.RoughNormalizePreFact_EditField.BackgroundColor = [0.8 1 0.8];
            elseif (SelRoughType== 2)
                app.RoughCorrLengthStrike_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughCorrLengthDip_EditField.BackgroundColor    = [0.8 1 0.8];
                app.RoughCorrLengthVari_EditField.BackgroundColor   = [0.8 1 0.8];
                app.RoughHurstStrike_EditField.BackgroundColor      = [0.9 0.9 0.9];
                app.RoughHurstDip_EditField.BackgroundColor         = [0.9 0.9 0.9];
                app.RoughHurstVari_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughRMD_decay_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughFractalDimension_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughCornerWaveNum_EditField.BackgroundColor    = [0.9 0.9 0.9];
                app.RoughNonLinScalingExp_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughPreFact_EditField.BackgroundColor          = [0.8 1 0.8];
                app.RoughPreFactVari_EditField.BackgroundColor      = [0.8 1 0.8];
                app.RoughNormalizePreFact_EditField.BackgroundColor = [0.8 1 0.8];
            elseif (SelRoughType== 3)
                app.RoughCorrLengthStrike_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughCorrLengthDip_EditField.BackgroundColor    = [0.9 0.9 0.9];
                app.RoughCorrLengthVari_EditField.BackgroundColor   = [0.9 0.9 0.9];
                app.RoughHurstStrike_EditField.BackgroundColor      = [0.9 0.9 0.9];
                app.RoughHurstDip_EditField.BackgroundColor         = [0.9 0.9 0.9];
                app.RoughHurstVari_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughRMD_decay_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughFractalDimension_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughCornerWaveNum_EditField.BackgroundColor    = [0.8 1 0.8];
                app.RoughNonLinScalingExp_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughPreFact_EditField.BackgroundColor          = [0.8 1 0.8];
                app.RoughPreFactVari_EditField.BackgroundColor      = [0.8 1 0.8];
                app.RoughNormalizePreFact_EditField.BackgroundColor = [0.8 1 0.8];
            elseif (SelRoughType== 4)
                app.RoughCorrLengthStrike_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughCorrLengthDip_EditField.BackgroundColor    = [0.8 1 0.8];
                app.RoughCorrLengthVari_EditField.BackgroundColor   = [0.8 1 0.8];
                app.RoughHurstStrike_EditField.BackgroundColor      = [0.9 0.9 0.9];
                app.RoughHurstDip_EditField.BackgroundColor         = [0.9 0.9 0.9];
                app.RoughHurstVari_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughRMD_decay_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughFractalDimension_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughCornerWaveNum_EditField.BackgroundColor    = [0.9 0.9 0.9];
                app.RoughNonLinScalingExp_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughPreFact_EditField.BackgroundColor          = [0.8 1 0.8];
                app.RoughPreFactVari_EditField.BackgroundColor      = [0.8 1 0.8];
                app.RoughNormalizePreFact_EditField.BackgroundColor = [0.8 1 0.8];
            elseif (SelRoughType== 5)
                app.RoughCorrLengthStrike_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughCorrLengthDip_EditField.BackgroundColor    = [0.9 0.9 0.9];
                app.RoughCorrLengthVari_EditField.BackgroundColor   = [0.9 0.9 0.9];
                app.RoughHurstStrike_EditField.BackgroundColor      = [0.8 1 0.8];
                app.RoughHurstDip_EditField.BackgroundColor         = [0.8 1 0.8];
                app.RoughHurstVari_EditField.BackgroundColor        = [0.8 1 0.8];
                app.RoughRMD_decay_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughFractalDimension_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughCornerWaveNum_EditField.BackgroundColor    = [0.9 0.9 0.9];
                app.RoughNonLinScalingExp_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughPreFact_EditField.BackgroundColor          = [0.8 1 0.8];
                app.RoughPreFactVari_EditField.BackgroundColor      = [0.8 1 0.8];
                app.RoughNormalizePreFact_EditField.BackgroundColor = [0.8 1 0.8];
            elseif (SelRoughType== 6)
                app.RoughCorrLengthStrike_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughCorrLengthDip_EditField.BackgroundColor    = [0.9 0.9 0.9];
                app.RoughCorrLengthVari_EditField.BackgroundColor   = [0.9 0.9 0.9];
                app.RoughHurstStrike_EditField.BackgroundColor      = [0.9 0.9 0.9];
                app.RoughHurstDip_EditField.BackgroundColor         = [0.9 0.9 0.9];
                app.RoughHurstVari_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughRMD_decay_EditField.BackgroundColor        = [0.8 1 0.8];
                app.RoughFractalDimension_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughCornerWaveNum_EditField.BackgroundColor    = [0.9 0.9 0.9];
                app.RoughNonLinScalingExp_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughPreFact_EditField.BackgroundColor          = [0.8 1 0.8];
                app.RoughPreFactVari_EditField.BackgroundColor      = [0.8 1 0.8];
                app.RoughNormalizePreFact_EditField.BackgroundColor = [0.9 0.9 0.9];
            end
        end

        % Value changed function: RoughHurstVariType_DropDown
        function RoughHurstVariType_DropDownValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,3) = str2double(app.RoughHurstVariType_DropDown.Value);
            end
        end

        % Value changed function: RoughPreFactVariType_DropDown
        function RoughPreFactVariType_DropDownValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,4) = str2double(app.RoughPreFactVariType_DropDown.Value);
            end
        end

        % Value changed function: RoughCorrLengthVariType_DropDown
        function RoughCorrLengthVariType_DropDownValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,5) = str2double(app.RoughCorrLengthVariType_DropDown.Value);
            end
        end

        % Value changed function: RoughCorrLengthStrike_EditField
        function RoughCorrLengthStrike_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,6) = app.RoughCorrLengthStrike_EditField.Value;
            end
        end

        % Value changed function: RoughCorrLengthDip_EditField
        function RoughCorrLengthDip_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,7) = app.RoughCorrLengthDip_EditField.Value;
            end
        end

        % Value changed function: RoughCorrLengthVari_EditField
        function RoughCorrLengthVari_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,8) = app.RoughCorrLengthVari_EditField.Value;
            end
        end

        % Value changed function: RoughHurstStrike_EditField
        function RoughHurstStrike_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,9) = app.RoughHurstStrike_EditField.Value;
            end
        end

        % Value changed function: RoughHurstDip_EditField
        function RoughHurstDip_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,10) = app.RoughHurstDip_EditField.Value;
            end
        end

        % Value changed function: RoughHurstVari_EditField
        function RoughHurstVari_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,11) = app.RoughHurstVari_EditField.Value;
            end
        end

        % Value changed function: RoughFractalDimension_EditField
        function RoughFractalDimension_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,12) = app.RoughFractalDimension_EditField.Value;
            end
        end

        % Value changed function: RoughCornerWaveNum_EditField
        function RoughCornerWaveNum_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,13) = app.RoughCornerWaveNum_EditField.Value;
            end
        end

        % Value changed function: RoughRMD_decay_EditField
        function RoughRMD_decay_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,15) = app.RoughRMD_decay_EditField.Value;
            end
        end

        % Value changed function: RoughNonLinScalingExp_EditField
        function RoughNonLinScalingExp_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,16) = app.RoughNonLinScalingExp_EditField.Value;
            end
        end

        % Value changed function: RoughPreFact_EditField
        function RoughPreFact_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,14) = app.RoughPreFact_EditField.Value;
                app.RoughParasPerFault(SelFault,17) = app.RoughPreFact_EditField.Value;
            end
        end

        % Value changed function: RoughPreFactVari_EditField
        function RoughPreFactVari_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,18) = app.RoughPreFactVari_EditField.Value;
            end
        end

        % Value changed function: Use_SurfTrace_CheckBox
        function Use_SurfTrace_CheckBoxValueChanged(app, event)
            
            if (app.Use_SurfTrace_CheckBox.Value == 1)
                app.RoughCorrLengthStrike_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughCorrLengthDip_EditField.BackgroundColor    = [0.9 0.9 0.9];
                app.RoughCorrLengthVari_EditField.BackgroundColor   = [0.9 0.9 0.9];
                app.RoughHurstStrike_EditField.BackgroundColor      = [0.9 0.9 0.9];
                app.RoughHurstDip_EditField.BackgroundColor         = [0.9 0.9 0.9];
                app.RoughHurstVari_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughRMD_decay_EditField.BackgroundColor        = [0.8 1 0.8];
                app.RoughFractalDimension_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughCornerWaveNum_EditField.BackgroundColor    = [0.9 0.9 0.9];
                app.RoughNonLinScalingExp_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughPreFact_EditField.BackgroundColor          = [0.8 1 0.8];
                app.RoughPreFactVari_EditField.BackgroundColor      = [0.8 1 0.8];
                app.RoughNormalizePreFact_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughType_DropDown.Value = '6';
            end
            if (app.GridImported == 1)
                app.RoughParasPerFault(:,2)  = 6;
                app.RoughType_DropDown.Value = '6';
            end
        end

        % Value changed function: FricStatFric_EditField
        function FricStatFric_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,2) = app.FricStatFric_EditField.Value;
            end
        end

        % Value changed function: FricStatFricVari_EditField
        function FricStatFricVari_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,3) = app.FricStatFricVari_EditField.Value;
            end
        end

        % Value changed function: FricDynFric_EditField
        function FricDynFric_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,4) = app.FricDynFric_EditField.Value;
            end
        end

        % Value changed function: FricDynFricVari_EditField
        function FricDynFricVari_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,5) = app.FricDynFricVari_EditField.Value;
            end
        end

        % Value changed function: FricUseAmB_CheckBox
        function FricUseAmB_CheckBoxValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,8) = app.FricUseAmB_CheckBox.Value;
            end
        end

        % Value changed function: FricAmBType_DropDown
        function FricAmBType_DropDownValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,9) = str2double(app.FricAmBType_DropDown.Value);
            end
        end

        % Button pushed function: FricShowAmB_ShowButton
        function FricShowAmB_ShowButtonPushed(app, event)
            
            FigNum   = app.FigNum_EditField.Value*100+1;
            
            AmBShape = str2double(app.FricAmBType_DropDown.Value);
            Geotherm = app.FricGeoTherm_EditField.Value;
            ShiftTemp= app.FricGeoThermStartTemp_EditField.Value;
            
            minTemp   = 0;          maxTemp   = 500;            TempStps  = 200;
            TempVals  = linspace(minTemp,maxTemp,TempStps);
            DepVals   = TempVals./Geotherm;
            TempVals  = TempVals + ShiftTemp;
            AmB_Vals  = zeros(size(TempVals));
            %-----------------------------------
            if     (AmBShape == 1)
                AmB_Vals = app.AmBparabolic(TempVals);
            elseif (AmBShape == 2)
                for i = 1 : size(TempVals,2)
                    if     ((TempVals(1,i) >= app.AmB_p1_temp(1,1)) && (TempVals(1,i) <= app.AmB_p1_temp(1,2)))
                        AmB_Vals(1,i) = app.AmB_p1(TempVals(1,i));
                    elseif ((TempVals(1,i) >  app.AmB_p2_temp(1,1)) && (TempVals(1,i) <= app.AmB_p2_temp(1,2)))
                        AmB_Vals(1,i) = app.AmB_p2(TempVals(1,i));
                    elseif ((TempVals(1,i) >  app.AmB_p3_temp(1,1)) && (TempVals(1,i) <= app.AmB_p3_temp(1,2)))
                        AmB_Vals(1,i) = app.AmB_p3(TempVals(1,i));
                    elseif ((TempVals(1,i) >  app.AmB_p4_temp(1,1)) && (TempVals(1,i) <= app.AmB_p4_temp(1,2)))
                        AmB_Vals(1,i) = app.AmB_p4(TempVals(1,i));
                    end
                end
            end
            %-----------------------------------
            NegAmBvals  = AmB_Vals(AmB_Vals< 0);
            MeanSeisAmB = mean(NegAmBvals);
            %-----------------------------------
            figure(FigNum); hold on; xlabel('a-b value'); ylabel('depth (km)');
            plot(AmB_Vals,DepVals,'.-','linewidth',2);
            plot([0 0],[min(DepVals) max(DepVals)],'k');
            plot([MeanSeisAmB MeanSeisAmB],[min(DepVals) max(DepVals)],'-.r');
            set(gca,'ydir','reverse');
        end

        % Value changed function: FricGeoTherm_EditField
        function FricGeoTherm_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,10) = app.FricGeoTherm_EditField.Value;
            end
        end

        % Value changed function: FricDc_EditField
        function FricDc_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,6) = app.FricDc_EditField.Value;
            end
        end

        % Value changed function: FricDcVari_EditField
        function FricDcVari_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,7) = app.FricDcVari_EditField.Value;
            end
        end

        % Button pushed function: LoadFaults_Button
        function LoadFaults_ButtonPushed(app, event)
            
            [DataName,DataPath]  = uigetfile('*_FLTtrig.dat','Load gridded Fault Fault Model');
            if     (DataName ~=0),      DataName = extractBefore(DataName,"_FLTtrig.dat");        CompletePath = strcat(DataPath,DataName);
            elseif (DataName ==0),      return;
            end
            app.SaveName_EditField.Value = DataName;
            app.SavePath                 = DataPath;
            app.RMSoverLength            = [];
            %----------------------
            Name4ascii     = strcat(CompletePath,'_FLT.txt');
            Name4binary    = strcat(CompletePath,'_FLTtrig.dat');
            %----------------------------------------------------------------
            fidascii    = fopen(Name4ascii ,'r');
            if (fidascii == -1), errordlg('The entered filename has not been found. 1');    return;   end
            %----------------------------------
            nextline        = fgetl(fidascii);              SectionIDNum          = sscanf(nextline,'%*s %d');
            app.FaultValues = nan(SectionIDNum,23);         app.FaultNames        = cell(1,SectionIDNum);
            %-----------------------%-----------------------%-----------------------
            nextline                        = fgetl(fidascii);        nextvals                      = sscanf(nextline,'%*s %d %d');
            app.GriddedFault.SizeTrigList   = nextvals(1,1);          app.GriddedFault.SizeVertList = nextvals(2,1);
            nextline                        = fgetl(fidascii);        nextvals                      = sscanf(nextline,'%*s %e %e');
            app.GriddedFault.meanLegLengths = nextvals(1,1);
            %----------------------
            nextline                = fgetl(fidascii);
            %-----------------------%-----------------------%-----------------------
            for i = 1 : SectionIDNum
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,20)    = sscanf(nextline,'%*s %d');
                FltSecName          = extractAfter(fgetl(fidascii)," ");                     app.FaultNames{1,i}      = FltSecName;
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,1)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,2)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,3)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,4)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,5)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,6)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,7)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,21)    = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,22)    = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,23)    = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,8:10)  = sscanf(nextline,'%*s %e %e %e')';
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,11:13) = sscanf(nextline,'%*s %e %e %e')';
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,14:16) = sscanf(nextline,'%*s %e %e %e')';
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,17:19) = sscanf(nextline,'%*s %e %e %e')';
                    
                nextline   = fgetl(fidascii);       nextline   = fgetl(fidascii);           nextline   = fgetl(fidascii);
            end
            fclose(fidascii);
            %-----------------------%-----%-----------------------%-----
            FltIDUniq   = unique(app.FaultValues(:,20));
            FaultIDnum  = size(FltIDUniq,1);
            PlaneNormal = zeros(FaultIDnum,3);%x,y,z of normal vector
            %-----------------------%-----%-----------------------%-----
            app.RoughParasPerFault     = zeros(FaultIDnum,19);
            app.StrgthParasPerSectn    = zeros(SectionIDNum,17);
            %-----------------------%-----------------------%-----------------------
            app.GriddedFault.LocalPosVals    = zeros(app.GriddedFault.SizeVertList,3);
            app.GriddedFault.VertexList      = zeros(app.GriddedFault.SizeVertList,2);
            app.GriddedFault.GlobalVertices  = zeros(app.GriddedFault.SizeVertList,3);
            app.GriddedFault.RoughPosVals    = zeros(app.GriddedFault.SizeVertList,4);
            %-----------------------%-----%-----------------------%-----
            app.GriddedFault.TriangleList    = zeros(app.GriddedFault.SizeTrigList,9);
            app.GriddedFault.GlobTrigCentr   = zeros(app.GriddedFault.SizeTrigList,3);
            app.GriddedFault.LocalCentVals   = zeros(app.GriddedFault.SizeTrigList,2);
            app.GriddedFault.RoughCentVals   = zeros(app.GriddedFault.SizeTrigList,3);
            app.GriddedFault.StrikeDipVals   = zeros(app.GriddedFault.SizeTrigList,2);
            app.GriddedFault.FaultCentCoord  = zeros(SectionIDNum,3);
            app.GriddedFault.RotMat          = zeros(SectionIDNum,9);
            app.GriddedFault.RotMatT         = zeros(SectionIDNum,9);
            app.GriddedFault.FricVals        = zeros(app.GriddedFault.SizeTrigList,12);
            %-----------------------
            fidbin    = fopen(Name4binary ,'rb');
            if (fidbin == -1), errordlg('The entered filename has not been found. 2');    return;   end
            %-----------------------
            PatchNum = fread(fidbin,1,'int');
            VertNum  = fread(fidbin,1,'int');
            app.GriddedFault.meanLegLengths        = fread(fidbin,1,'single');
            app.GriddedFault.meanPatchAreas        = fread(fidbin,1,'single');

            if ((PatchNum ~= app.GriddedFault.SizeTrigList) || (VertNum ~= app.GriddedFault.SizeVertList))
                disp('patch and/or vertex numbers are not consistent');
                return;
            end
            app.GriddedFault.TriangleList(:,1)     = fread(fidbin,PatchNum,'int32');
            app.GriddedFault.TriangleList(:,2)     = fread(fidbin,PatchNum,'int32');
            app.GriddedFault.TriangleList(:,3)     = fread(fidbin,PatchNum,'int32');
            app.GriddedFault.TriangleList(:,4)     = fread(fidbin,PatchNum,'int32');
            app.GriddedFault.TriangleList(:,5)     = fread(fidbin,PatchNum,'int32');
            app.GriddedFault.TriangleList(:,6)     = 1:PatchNum;
            app.GriddedFault.TriangleList(:,7)     = fread(fidbin,PatchNum,'single');
            app.GriddedFault.TriangleList(:,8)     = fread(fidbin,PatchNum,'single');
            app.GriddedFault.TriangleList(:,9)     = fread(fidbin,PatchNum,'single');
            app.GriddedFault.VertexList(:,1)       = fread(fidbin,VertNum,'single');
            app.GriddedFault.VertexList(:,2)       = fread(fidbin,VertNum,'single');
            app.GriddedFault.GlobalVertices(:,1)   = fread(fidbin,VertNum,'single');
            app.GriddedFault.GlobalVertices(:,2)   = fread(fidbin,VertNum,'single');
            app.GriddedFault.GlobalVertices(:,3)   = fread(fidbin,VertNum,'single');
            app.GriddedFault.RoughPosVals(:,4)     = fread(fidbin,VertNum,'single');
            app.GriddedFault.GlobTrigCentr(:,1)    = fread(fidbin,PatchNum,'single');
            app.GriddedFault.GlobTrigCentr(:,2)    = fread(fidbin,PatchNum,'single');
            app.GriddedFault.GlobTrigCentr(:,3)    = fread(fidbin,PatchNum,'single');
            %-----------------------%--------------
            fclose(fidbin);
            %-----------------------%--------------
            app.GriddedFault.RoughCentVals         = app.GriddedFault.GlobTrigCentr;
            app.GriddedFault.RoughPosVals(:,1:3)   = app.GriddedFault.GlobalVertices;
            %-----------------------%-----------------------
            PatchAreas    = zeros(app.GriddedFault.SizeTrigList,1);
            %-----------------------------------------
            for i = 1 : app.GriddedFault.SizeTrigList
                v1    = app.GriddedFault.TriangleList(i,1);
                v2    = app.GriddedFault.TriangleList(i,2);
                v3    = app.GriddedFault.TriangleList(i,3);
                %---------------------------
                PatchAreas(i,1) = 1/2*norm(cross( (app.GriddedFault.GlobalVertices(v2,:)-app.GriddedFault.GlobalVertices(v1,:))',(app.GriddedFault.GlobalVertices(v3,:)-app.GriddedFault.GlobalVertices(v1,:))'));
                %---------------------------
            end
            meanPatchAreas   = nanmean(PatchAreas(:));
            app.GriddedFault.MeanPatchSize   = meanPatchAreas;
            %-----------------------------------------
            for iii = 1 : FaultIDnum
                SelFltID      = FltIDUniq(iii);
                SelectedTrigs = app.GriddedFault.TriangleList(app.GriddedFault.TriangleList(:,5) == SelFltID, :);
                
                for iiii = 1 : size(SelectedTrigs,1)
                    v1    = SelectedTrigs(iiii,1);              P1    = app.GriddedFault.GlobalVertices(v1,:);
                    v2    = SelectedTrigs(iiii,2);              P2    = app.GriddedFault.GlobalVertices(v2,:);
                    v3    = SelectedTrigs(iiii,3);              P3    = app.GriddedFault.GlobalVertices(v3,:);
                    %get the fault normal for this,
                    P1P2 = P2 -P1;                              P1P3  = P3 -P1;
                    FltNorm = cross(P1P2,P1P3);                 FltNorm = FltNorm./norm(FltNorm);
                    
                    if (iiii == 1)
                        PlaneNormal(iii,:) = FltNorm;
                    else
                        %get angle between the vectors
                        vect1 = PlaneNormal(iii,:)./norm(PlaneNormal(iii,:));
                        vect2 = FltNorm;
                        
                        if (acosd(dot(vect1,vect2)) > 90),                        FltNorm = -FltNorm;             end
                        
                        PlaneNormal(iii,:) = PlaneNormal(iii,:) + FltNorm;
                    end
                end
                PlaneNormal(iii,:) = PlaneNormal(iii,:)./norm(PlaneNormal(iii,:));
            end %goal here is to get average orientation of the fault elements, is done to get normal to overall fault surface across multiple sections
            %-----------------------%-----------------------
            app.ElementEditField.Value         = app.GriddedFault.SizeTrigList;          
            app.FaultList_ListBox.Items        = app.FaultNames;
            app.FaultList_ListBox.ItemsData    = 1:size(app.FaultNames,2);
            app.FaultList_ListBox.Value        = 1;
            %-----------------------%-----------------------%-----------------------
            %-----------------------%-----------------------%------------------- ----
            RSeed_OldNew = str2double(app.RoughSeed_DropDown.Value);
            RType        = str2double(app.RoughType_DropDown.Value);
            RHexpVarType = str2double(app.RoughHurstVariType_DropDown.Value);
            RPreFVarType = str2double(app.RoughPreFactVariType_DropDown.Value);
            RCorrVarType = str2double(app.RoughCorrLengthVariType_DropDown.Value);
            
            RCorrInStk   = app.RoughCorrLengthStrike_EditField.Value;
            RCorrInDip   = app.RoughCorrLengthDip_EditField.Value;
            RCorrVar     = app.RoughCorrLengthVari_EditField.Value;
            RHexpStk     = app.RoughHurstStrike_EditField.Value;
            RHexpDip     = app.RoughHurstDip_EditField.Value;
            RHexpVar     = app.RoughHurstVari_EditField.Value;
            RFractDim    = app.RoughFractalDimension_EditField.Value;
            RCornFreq    = app.RoughCornerWaveNum_EditField.Value;
            RrmdDcy      = app.RoughRMD_decay_EditField.Value;
            
            RNonLinScl   = app.RoughNonLinScalingExp_EditField.Value;
            RPreFact     = app.RoughPreFact_EditField.Value;
            RPFactVar    = app.RoughPreFactVari_EditField.Value;
            
            SStatFric    = app.FricStatFric_EditField.Value;
            SStatFricVari= app.FricStatFricVari_EditField.Value;
            SDynFric     = app.FricDynFric_EditField.Value;
            SDynFricVari = app.FricDynFricVari_EditField.Value;
            SCritSlip    = app.FricDc_EditField.Value;
            SCritSlipVari= app.FricDcVari_EditField.Value;
            SUseAmBprof  = app.FricUseAmB_CheckBox.Value;
            SAmBShpe     = str2double(app.FricAmBType_DropDown.Value);
            SGeothermGrd = app.FricGeoTherm_EditField.Value;
       
            SFractDist   = app.StrengthUseFractalDistrib_CheckBox.Value;
            SSeed_OldNew = str2double(app.StrengthSeed_DropDown.Value);
            SCorrInStk   = app.StrengthCorrLengthStrike_EditField.Value;
            SCorrInDip   = app.StrengthCorrLengthDip_EditField.Value;
            SHexp        = app.StrengthHurstExp_EditField.Value;
            SWatrLevl    = app.StrengthWaterLevel_EditField.Value;
            %-----------------------%-----%-----------------------%-----
            for jj = 1 : FaultIDnum
                
                app.RoughParasPerFault(jj,1)  = RSeed_OldNew;
                app.RoughParasPerFault(jj,2)  = RType;
                app.RoughParasPerFault(jj,3)  = RHexpVarType;
                app.RoughParasPerFault(jj,4)  = RPreFVarType;
                app.RoughParasPerFault(jj,5)  = RCorrVarType;
                app.RoughParasPerFault(jj,6)  = RCorrInStk;
                app.RoughParasPerFault(jj,7)  = RCorrInDip;
                app.RoughParasPerFault(jj,8)  = RCorrVar;
                app.RoughParasPerFault(jj,9)  = RHexpStk;
                app.RoughParasPerFault(jj,10) = RHexpDip;
                app.RoughParasPerFault(jj,11) = RHexpVar;
                app.RoughParasPerFault(jj,12) = RFractDim;
                app.RoughParasPerFault(jj,13) = RCornFreq;
                app.RoughParasPerFault(jj,14) = RPreFact;
                app.RoughParasPerFault(jj,15) = RrmdDcy;
                app.RoughParasPerFault(jj,16) = RNonLinScl;
                app.RoughParasPerFault(jj,17) = RPreFact;
                app.RoughParasPerFault(jj,18) = RPFactVar;
                app.RoughParasPerFault(jj,19) = FltIDUniq(jj,1); %the fault ID number...
                
                app.RMSoverLength(jj)         = 0;
            end
            %-----------------------%-----
            for jj = 1 : SectionIDNum
                app.StrgthParasPerSectn(jj,1)  = 0; %not used anymore
                app.StrgthParasPerSectn(jj,2)  = SStatFric;
                app.StrgthParasPerSectn(jj,3)  = SStatFricVari;
                app.StrgthParasPerSectn(jj,4)  = SDynFric;
                app.StrgthParasPerSectn(jj,5)  = SDynFricVari;
                app.StrgthParasPerSectn(jj,6)  = SCritSlip;
                app.StrgthParasPerSectn(jj,7)  = SCritSlipVari;
                
                app.StrgthParasPerSectn(jj,8)  = SUseAmBprof;
                app.StrgthParasPerSectn(jj,9)  = SAmBShpe;
                app.StrgthParasPerSectn(jj,10) = SGeothermGrd;
                
                app.StrgthParasPerSectn(jj,11) = SFractDist;
                app.StrgthParasPerSectn(jj,12) = SSeed_OldNew;
                app.StrgthParasPerSectn(jj,13) = SCorrInStk;
                app.StrgthParasPerSectn(jj,14) = SCorrInDip;
                app.StrgthParasPerSectn(jj,15) = SHexp;
                app.StrgthParasPerSectn(jj,16) = SWatrLevl;
                
                app.StrgthParasPerSectn(jj,17) = app.FaultValues(jj,20); %the fault ID number...
            end
            %-----------------------%-----%-----------------------%-----
            for (jj = 1 : FaultIDnum)
                SelectedPatchPos = find(app.GriddedFault.TriangleList(:,5) == FltIDUniq(jj,1));
                SelectedPatches  = app.GriddedFault.TriangleList(SelectedPatchPos,1:3);
                %now I know the ID of the vertices => need to get their locations (X/Y/Z coordinates) next; make SelectedPatches a list
                SelVerticesList  = unique(SelectedPatches(:));
                SelCoordinates   = app.GriddedFault.GlobalVertices(SelVerticesList,1:3);
                
                app.GriddedFault.FaultCentCoord(jj,1:3) = [mean(SelCoordinates(:,1)), mean(SelCoordinates(:,2)), mean(SelCoordinates(:,3))];
                SelCoordinates(:,1)   = SelCoordinates(:,1) - app.GriddedFault.FaultCentCoord(jj,1);
                SelCoordinates(:,2)   = SelCoordinates(:,2) - app.GriddedFault.FaultCentCoord(jj,2);
                SelCoordinates(:,3)   = SelCoordinates(:,3) - app.GriddedFault.FaultCentCoord(jj,3);
                %----------------------------------------
                PlaneNorm = PlaneNormal(jj,:)';
                
                if (PlaneNorm(3,1) < 0),                                        PlaneNorm = PlaneNorm.*-1;          end
                if (PlaneNorm(3,1) > 0)
                    TempVect = [PlaneNorm(1,1);PlaneNorm(2,1); 0];              TempVect    = TempVect./norm(TempVect);
                    StkVect  = cross(PlaneNorm,TempVect);                       StkVect     = StkVect./norm(StkVect);
                else
                    TempVect = [0; 0; 1];                                       StkVect     = cross(TempVect,PlaneNorm);
                    StkVect  = StkVect./norm(StkVect);
                end
                DipVect = cross(PlaneNorm,StkVect);                           DipVect     = DipVect./norm(DipVect);
                
                app.GriddedFault.RotMat(jj,1:9)  = [PlaneNorm(1,1), PlaneNorm(2,1), PlaneNorm(3,1), StkVect(1,1),   StkVect(2,1), StkVect(3,1), DipVect(1,1),   DipVect(2,1), DipVect(3,1)];
                app.GriddedFault.RotMatT(jj,1:9) = [PlaneNorm(1,1), StkVect(1,1),   DipVect(1,1),   PlaneNorm(2,1), StkVect(2,1), DipVect(2,1), PlaneNorm(3,1), StkVect(3,1), DipVect(3,1)];
                %----------------------------------------
                if (sign(app.GriddedFault.RotMatT(jj,1)) ~= sign(app.GriddedFault(1).RotMatT(jj,1)))
                    app.GriddedFault.RotMat(jj,1)  = -1*app.GriddedFault.RotMat(jj,1);            app.GriddedFault.RotMat(jj,4) = -1*app.GriddedFault.RotMat(jj,4);        app.GriddedFault.RotMat(jj,7) = -1*app.GriddedFault.RotMat(jj,7);
                    app.GriddedFault.RotMat(jj,2)  = -1*app.GriddedFault.RotMat(jj,2);            app.GriddedFault.RotMat(jj,5) = -1*app.GriddedFault.RotMat(jj,5);        app.GriddedFault.RotMat(jj,8) = -1*app.GriddedFault.RotMat(jj,8);
                    
                    app.GriddedFault.RotMatT(jj,1) = -1*app.GriddedFault.RotMatT(jj,1);           app.GriddedFault.RotMatT(jj,2)= -1*app.GriddedFault.RotMatT(jj,2);       app.GriddedFault.RotMatT(jj,3)= -1*app.GriddedFault.RotMatT(jj,3);
                    app.GriddedFault.RotMatT(jj,4) = -1*app.GriddedFault.RotMatT(jj,4);           app.GriddedFault.RotMatT(jj,5)= -1*app.GriddedFault.RotMatT(jj,5);       app.GriddedFault.RotMatT(jj,6)= -1*app.GriddedFault.RotMatT(jj,6);
                end
                
                %----------------------------------------
                RotMatT                                          = reshape(app.GriddedFault.RotMatT(jj,:),3,3);
                app.GriddedFault.LocalPosVals(SelVerticesList,:) = (RotMatT*SelCoordinates')';
                %----------------------------------------
                for kk = 1 : size(SelectedPatchPos,1)
                    NextPatch = SelectedPatchPos(kk,1);
                    v1        = app.GriddedFault.TriangleList(NextPatch,1);
                    v2        = app.GriddedFault.TriangleList(NextPatch,2);
                    v3        = app.GriddedFault.TriangleList(NextPatch,3);
                    MeanStkPos= (app.GriddedFault.LocalPosVals(v1,2) +app.GriddedFault.LocalPosVals(v2,2) +app.GriddedFault.LocalPosVals(v3,2))/3;
                    MeanDipPos= (app.GriddedFault.LocalPosVals(v1,3) +app.GriddedFault.LocalPosVals(v2,3) +app.GriddedFault.LocalPosVals(v3,3))/3;
                    
                    app.GriddedFault.LocalCentVals(NextPatch,1) = MeanStkPos;
                    app.GriddedFault.LocalCentVals(NextPatch,2) = MeanDipPos;
                end
            end
            %-----------------------%-----%-----------------------%-----
            app.GridImported = 1;
        end

        % Button pushed function: Plotfaults_Button
        function Plotfaults_ButtonPushed(app, event)
            
            if (app.GridImported == 0),             return;                             end
            
            FigNum          = app.FigNum_EditField.Value;
            ViewAngle       = app.ViewAngle_EditField.Value;        ViewAngle = sscanf(ViewAngle,'%f');
            %-----------------------------------------------
            figure(FigNum); clf; hold on; axis equal;
            xlabel('easting');ylabel('northing'); zlabel('depth');
            %-----------------------------------------------
            AllV1s          = zeros(app.GriddedFault.SizeTrigList,3);
            AllV2s          = zeros(app.GriddedFault.SizeTrigList,3);
            AllV3s          = zeros(app.GriddedFault.SizeTrigList,3);
            PlottedComp     = zeros(app.GriddedFault.SizeTrigList,1);
            Cntr            = 1;
            %-----------------------------------------------
            for j = 1 : app.GriddedFault.SizeTrigList
                
                AllV1s(Cntr,1) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,1),1);
                AllV1s(Cntr,2) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,2),1);
                AllV1s(Cntr,3) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,3),1);
                
                AllV2s(Cntr,1) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,1),2);
                AllV2s(Cntr,2) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,2),2);
                AllV2s(Cntr,3) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,3),2);
                
                AllV3s(Cntr,1) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,1),3);
                AllV3s(Cntr,2) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,2),3);
                AllV3s(Cntr,3) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,3),3);
                %-----------------------------
                PlottedComp(Cntr,1)   = app.GriddedFault.TriangleList(j,5);%rand(1);
                Cntr                  = Cntr +1;
                %-----------------------------
            end
            patch(AllV1s',AllV2s',AllV3s',PlottedComp');
            view(ViewAngle');
            axis tight;
        end

        % Value changed function: FaultList_ListBox
        function FaultList_ListBoxValueChanged(app, event)
            
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            FltIDUniq     = unique(app.FaultValues(:,20));
            SelFaultIDval = app.FaultValues(SelSection,20);
            SelFaultID    = find(FltIDUniq == SelFaultIDval,1);

            app.RoughSeed_DropDown.Value                 = num2str(app.RoughParasPerFault(SelFaultID,1));
            app.RoughType_DropDown.Value                 = num2str(app.RoughParasPerFault(SelFaultID,2));
            app.RoughHurstVariType_DropDown.Value        = num2str(app.RoughParasPerFault(SelFaultID,3));
            app.RoughPreFactVariType_DropDown.Value      = num2str(app.RoughParasPerFault(SelFaultID,4));
            app.RoughCorrLengthVariType_DropDown.Value   = num2str(app.RoughParasPerFault(SelFaultID,5));
            app.RoughCorrLengthStrike_EditField.Value    = app.RoughParasPerFault(SelFaultID,6);
            app.RoughCorrLengthDip_EditField.Value       = app.RoughParasPerFault(SelFaultID,7);
            app.RoughCorrLengthVari_EditField.Value      = app.RoughParasPerFault(SelFaultID,8);
            app.RoughHurstStrike_EditField.Value         = app.RoughParasPerFault(SelFaultID,9);
            app.RoughHurstDip_EditField.Value            = app.RoughParasPerFault(SelFaultID,10);
            app.RoughHurstVari_EditField.Value           = app.RoughParasPerFault(SelFaultID,11);
            app.RoughFractalDimension_EditField.Value    = app.RoughParasPerFault(SelFaultID,12);
            app.RoughCornerWaveNum_EditField.Value       = app.RoughParasPerFault(SelFaultID,13);
            app.RoughRMD_decay_EditField.Value           = app.RoughParasPerFault(SelFaultID,15);
            app.RoughNonLinScalingExp_EditField.Value    = app.RoughParasPerFault(SelFaultID,16);
            app.RoughPreFact_EditField.Value             = app.RoughParasPerFault(SelFaultID,17);
            app.RoughPreFactVari_EditField.Value         = app.RoughParasPerFault(SelFaultID,18);
            
            app.FricStatFric_EditField.Value             = app.StrgthParasPerSectn(SelSection,2);
            app.FricStatFricVari_EditField.Value         = app.StrgthParasPerSectn(SelSection,3);
            app.FricDynFric_EditField.Value              = app.StrgthParasPerSectn(SelSection,4);
            app.FricDynFricVari_EditField.Value          = app.StrgthParasPerSectn(SelSection,5);
            app.FricDc_EditField.Value                   = app.StrgthParasPerSectn(SelSection,6);
            app.FricDcVari_EditField.Value               = app.StrgthParasPerSectn(SelSection,7);
            app.FricUseAmB_CheckBox.Value                = app.StrgthParasPerSectn(SelSection,8);
            app.FricAmBType_DropDown.Value               = num2str(app.StrgthParasPerSectn(SelSection,9));
            app.FricGeoTherm_EditField.Value             = app.StrgthParasPerSectn(SelSection,10);
            app.StrengthUseFractalDistrib_CheckBox.Value = app.StrgthParasPerSectn(SelSection,11);
            app.StrengthSeed_DropDown.Value              = num2str(app.StrgthParasPerSectn(SelSection,12));
            app.StrengthCorrLengthStrike_EditField.Value = app.StrgthParasPerSectn(SelSection,13);
            app.StrengthCorrLengthDip_EditField.Value    = app.StrgthParasPerSectn(SelSection,14);
            app.StrengthHurstExp_EditField.Value         = app.StrgthParasPerSectn(SelSection,15);
            app.StrengthWaterLevel_EditField.Value       = app.StrgthParasPerSectn(SelSection,16);
            
            app.RMSL_EditField.Value                     = app.RMSoverLength(SelFaultID);
        end

        % Button pushed function: GenerateRoughness_Button
        function GenerateRoughness_ButtonPushed(app, event)
            
            if (app.GridImported == 0),             return;                             end
            
            ShowStats       = app.ShowRoughnessStats_CheckBox.Value;
            PlotRough       = app.PlotRoughness_CheckBox.Value;
            FigNum          = app.FigNum_EditField.Value;
            FaultNumber     = size(unique(app.FaultValues(:,20)),1);
            LgthScale       = app.RoughLengthScale_EditField.Value;

            Length4Roughness = app.RoughNormalizePreFact_EditField.Value;
            rng('shuffle');
            VariationRandomNum    = rand(FaultNumber,5);
            VariationRandomNumN   = randn(FaultNumber,5);
            %-----------------------------
            for ii = 1 : FaultNumber
                %gathergathergathergathergathergathergathergathergathergathergathergather
                UsePrevSeed = app.RoughParasPerFault(ii,1); % 1 == new; 2 == previous
                
                if     (app.RoughParasPerFault(ii,2) == 1),        acf = 'AK';
                elseif (app.RoughParasPerFault(ii,2) == 2),        acf = 'EX';
                elseif (app.RoughParasPerFault(ii,2) == 3),        acf = 'FR';
                elseif (app.RoughParasPerFault(ii,2) == 4),        acf = 'GS';
                elseif (app.RoughParasPerFault(ii,2) == 5),        acf = 'CN';    %for Candela et al. 2009
                elseif (app.RoughParasPerFault(ii,2) == 6),        acf = 'RM';    %random midpoint displacement
                end
                
                HurstExp_vartype= app.RoughParasPerFault(ii,3);                     PreFact_vartype = app.RoughParasPerFault(ii,4);
                CorrLgth_vartype= app.RoughParasPerFault(ii,5);                     CorrLgth_StkAll = app.RoughParasPerFault(ii,6);
                CorrLgth_Dip    = app.RoughParasPerFault(ii,7);                     CorrLgth_vari   = app.RoughParasPerFault(ii,8);
                HurstExp_StkAll = app.RoughParasPerFault(ii,9);                     HurstExp_Dip    = app.RoughParasPerFault(ii,10);
                HurstExp_vari   = app.RoughParasPerFault(ii,11);                    FractDim        = app.RoughParasPerFault(ii,12);
                CnrWaveNum      = app.RoughParasPerFault(ii,13);                    
                RMDDecy         = app.RoughParasPerFault(ii,15);                    NonLinSclExp    = app.RoughParasPerFault(ii,16);
                PreFactor       = app.RoughParasPerFault(ii,17);                    PreFact_vari    = app.RoughParasPerFault(ii,18);
                SelFaultID      = app.RoughParasPerFault(ii,19);
                %gathergathergathergathergathergathergathergathergathergathergathergather
                %prepareprepareprepareprepareprepareprepareprepareprepareprepareprepare
                %------------------------------
                
                %get all patches/vertices that belong to a given fault;
                %determine extend of those; important is to know
                SelectedPatches = app.GriddedFault(1).TriangleList( (app.GriddedFault(1).TriangleList(:,5) == SelFaultID) ,1:3);
                SelVerticesList = unique(SelectedPatches(:));
                SeletedVerts    = app.GriddedFault(1).LocalPosVals(SelVerticesList,:); %this is in local coordinate system that is best fitting plane to all fault sections of a given fault
                
                MinStkLngthVal  = min(SeletedVerts(:,2));                                MinDipLngthVal = min(SeletedVerts(:,3));
                MaxStkLngthVal  = max(SeletedVerts(:,2));                                MaxDipLngthVal = max(SeletedVerts(:,3));
                FaultLength     = (MaxStkLngthVal-MinStkLngthVal);                       FaultWidth      = (MaxDipLngthVal-MinDipLngthVal);
                PtchNumInStk    = ceil(FaultLength/LgthScale);                           PtchNumInDip   = ceil(FaultWidth/LgthScale);
                %------------------------------
                if (HurstExp_vartype == 1),     HurstExp_StkAll = HurstExp_StkAll   + HurstExp_vari *VariationRandomNumN(ii,1);            HurstExp_Dip    = HurstExp_Dip  + HurstExp_vari*VariationRandomNumN(ii,2);
                else,                           HurstExp_StkAll = HurstExp_StkAll   + HurstExp_vari *(VariationRandomNum(ii,1)*2 -1);      HurstExp_Dip    = HurstExp_Dip  + HurstExp_vari*(VariationRandomNum(ii,2)*2 -1);
                end
                if (CorrLgth_vartype== 1),      CorrLgth_StkAll = CorrLgth_StkAll   + CorrLgth_vari *VariationRandomNumN(ii,3);            CorrLgth_Dip    = CorrLgth_Dip  + CorrLgth_vari *VariationRandomNumN(ii,4);
                else,                           CorrLgth_StkAll = CorrLgth_StkAll   + CorrLgth_vari *(VariationRandomNum(ii,3)*2 -1);      CorrLgth_Dip    = CorrLgth_Dip  + CorrLgth_vari *(VariationRandomNum(ii,4)*2 -1);
                end
                if (PreFact_vartype == 1),      PreFactorUsed   = abs(PreFactor     + PreFact_vari  *VariationRandomNumN(ii,5))    * Length4Roughness; %latter part is normal-distributed value => and std == entered value
                else,                           PreFactorUsed   = abs(PreFactor     + PreFact_vari*(VariationRandomNum(ii,5)*2 -1))* Length4Roughness; %latter part is value between -1 and +1 ==> then multiplied with range entry
                end
                %------------------------------
                if ((UsePrevSeed == 2) && (isfield(app.RandParasRough,'Vals')))
                    %don't do/change anything then...
                else
                    app.RandParasRough(ii).Vals =  rng('shuffle');
                end
                %------------------------------
                sampsize = [FaultWidth/PtchNumInDip, FaultLength/PtchNumInStk];
                %---------------------------------------
                if     (app.RoughParasPerFault(ii,2) == 1),                                             corr = [CorrLgth_Dip,    CorrLgth_StkAll, HurstExp_StkAll];%default == 1;        ak (van Karman)
                elseif (app.RoughParasPerFault(ii,2) == 2)||(app.RoughParasPerFault(ii,2) == 4),        corr = [CorrLgth_Dip,    CorrLgth_StkAll                 ];%exponential              gaussian
                elseif (app.RoughParasPerFault(ii,2) == 3),                                             corr = [FractDim,        CnrWaveNum                      ];%fractal
                    %kc   = 2*pi/(sqrt(FaultLength*FaultWidth)); %disp(strcat('computed corner wavenumber is : ',num2str(kc)));
                elseif (app.RoughParasPerFault(ii,2) == 5),                                             corr = [HurstExp_StkAll, HurstExp_Dip                    ];
                elseif (app.RoughParasPerFault(ii,2) == 6),                                             corr = [PreFactorUsed,     RMDDecy                       ];
                end
                %prepareprepareprepareprepareprepareprepareprepareprepareprepareprepare
                % --------------------------------------
                % GENERATE THE SPATIAL RANDOM FIELDS
                %---------------------------------------
                if (app.RoughParasPerFault(ii,2) < 5)
                    app.RoughFault(ii).FltSurface  = SpecSyn2(app, [FaultWidth FaultLength],sampsize,corr,acf,NonLinSclExp,PreFactorUsed,app.RandParasRough(ii).Vals, ii);
                    
                elseif (app.RoughParasPerFault(ii,2) == 5)
                    MaxPntNum                      = max([PtchNumInStk PtchNumInDip]);
                    N                              = nextpow2(MaxPntNum);		% new grid-size
                    app.RoughFault(ii).FltSurface  = Synth2DFault_Candela2009(app, N, HurstExp_StkAll, HurstExp_Dip,NonLinSclExp,PreFactorUsed,app.RandParasRough(ii).Vals,ii);
                    CentPos                        = round(size(app.RoughFault(ii).FltSurface)/2);
                    app.RoughFault(ii).FltSurface  = app.RoughFault(ii).FltSurface(CentPos-floor(PtchNumInDip/2) : CentPos+floor(PtchNumInDip/2), CentPos-floor(PtchNumInStk/2) : CentPos+floor(PtchNumInStk/2));
                    
                elseif (app.RoughParasPerFault(ii,2) == 6)
                    MaxPntNum         = max([PtchNumInStk PtchNumInDip]);
                    N                 = nextpow2(MaxPntNum);		% new grid-size
                    if ((app.Use_SurfTrace_CheckBox.Value == 1) && (app.SurfTraceImported == 0)),           return;             end
                    if  (app.Use_SurfTrace_CheckBox.Value == 1)
                        NextFaultTrace = app.SurfTraceVals(ii).TraceVals;
                    else
                        NextFaultTrace = [];
                    end
                    app.RoughFault(ii).FltSurface  = RandomMidPointDisplacement(app, N, [PreFactorUsed, RMDDecy], [PtchNumInDip PtchNumInStk], app.RandParasRough(ii).Vals, sampsize, NextFaultTrace,ii);

                end
                %---------------------------------------
                if (ShowStats == 1),            ShowRoughnessSpectrum(app,ii);             end
                %---------------------------------------
                %for RMS -calculation:
                PatchNum                = length(app.RoughFault(ii).FltSurface(:));
                FaultArea               = FaultLength*FaultWidth;
                PatchArea               = FaultArea/PatchNum;
                app.RMSoverLength(ii,1) = sqrt(1/FaultArea *sum(app.RoughFault(ii).FltSurface(:).^2 *PatchArea) )/Length4Roughness;
                app.RMSL_EditField.Value = app.RMSoverLength(ii,1);
                disp(strcat('Fault ',num2str(ii),' with RMS value:_',num2str(app.RMSoverLength(ii,1))));
                %---------------------------------------%---------------------------------------
                if (PlotRough == 1)
                    VectAlongStk = linspace(MinStkLngthVal,MaxStkLngthVal,size(app.RoughFault(ii).FltSurface,2));
                    VectAlongDip = linspace(MinDipLngthVal,MaxDipLngthVal,size(app.RoughFault(ii).FltSurface,1));
                    
                    figure(FigNum*100+ii); clf; hold on; axis equal;
                    imagesc(VectAlongStk,VectAlongDip,app.RoughFault(ii).FltSurface);
                    view(0,90);
                    axis image;
                    xlabel('Distance along fault strike (km)');
                    ylabel('Distance along fault dip (km)');
                    colorbar;
                    %maxval = max(abs(app.RoughFault(ii).FltSurface(:)));
                    %caxis([-maxval maxval]);
                end
                %---------------------------------------
            end
            
            app.RoughnessGenerated   = 1;


app.Manual_Strength.FaultLength = FaultLength ;

app.Manual_Strength.FaultWidth = FaultWidth ;

        end

        % Button pushed function: CombinewGrid_Button
        function CombinewGrid_ButtonPushed(app, event)
            
            if (app.GridImported       == 0),               return;                             end
            if (app.RoughnessGenerated == 0),               return;                             end
            %-----------------------------
            PlotRough       = app.PlotRoughness_CheckBox.Value;
            FigNum          = app.FigNum_EditField.Value;
            ViewAngle       = str2num(app.ViewAngle_EditField.Value);
            FaultNumber     = size(unique(app.FaultValues(:,20)),1);
            %-----------------------------
            Length4Roughness= app.RoughNormalizePreFact_EditField.Value;
            %-------------------------------%-------------------------------
            for ii = 1 : FaultNumber
                
                SelFaultID       = app.RoughParasPerFault(ii,19);
                SelectedPatchPos = find(app.GriddedFault.TriangleList(:,5) == SelFaultID);
                SelectedPatches  = app.GriddedFault.TriangleList(SelectedPatchPos,1:3);
                SelVerticesList  = unique(SelectedPatches(:));
                
                SeletedVerts     = app.GriddedFault.LocalPosVals(SelVerticesList,1:3);
                
                MinStkLngthVal   = min(SeletedVerts(:,2));                                MaxStkLngthVal = max(SeletedVerts(:,2));
                MinDipLngthVal   = min(SeletedVerts(:,3));                                MaxDipLngthVal = max(SeletedVerts(:,3));
                FaultLength      = (MaxStkLngthVal-MinStkLngthVal);                       FaultWidth     = (MaxDipLngthVal-MinDipLngthVal);
                %-----------------------------
                ApproxSquareNum     = (FaultLength*FaultWidth)/app.GriddedFault.MeanPatchSize;
                AspectRatio         = FaultLength/FaultWidth;
                PtchNumInDipregrid  = round(sqrt((ApproxSquareNum)/AspectRatio));
                PtchNumInStkregrid  = round((ApproxSquareNum)/PtchNumInDipregrid);
                %-----------------------------
                VectAlongStkregrid  = linspace(MinStkLngthVal,MaxStkLngthVal,PtchNumInStkregrid);
                VectAlongDipregrid  = linspace(MinDipLngthVal,MaxDipLngthVal,PtchNumInDipregrid);
                Regridded           = imresize(app.RoughFault(ii).FltSurface,[PtchNumInDipregrid,PtchNumInStkregrid],'bicubic');
                %interpolatinginterpolatinginterpolatinginterpolatinginterpolatinginterpolating
                [X,Y]               = meshgrid(VectAlongStkregrid,VectAlongDipregrid);
                VertexHeight        = interp2(X,Y, Regridded ,SeletedVerts(:,2),SeletedVerts(:,3),'spline'); %this is important for potentially required extrapolation
                %-----------------------------
                %-----------------------------
                FaultArea = 0;
                for i = 1 : size(SelectedPatches,1)
                    v1    = SelectedPatches(i,1);           v2    = SelectedPatches(i,2);           v3    = SelectedPatches(i,3);
                    %---------------------------
                    %https://www.mathworks.com/matlabcentral/answers/271797-surface-area-of-a-3d-surface
                    FaultArea = FaultArea + 1/2*norm(cross((app.GriddedFault.LocalPosVals(v2,:)-app.GriddedFault.LocalPosVals(v1,:)),(app.GriddedFault.LocalPosVals(v3,:)-app.GriddedFault.LocalPosVals(v1,:))));
                end
                PatchArea = FaultArea/size(SelectedPatches,1);
                app.RMSoverLength(ii,1) = sqrt(1/FaultArea *sum(VertexHeight.^2 *PatchArea) )/Length4Roughness;
                %-----------------------------
                %-----------------------------
                SeletedVerts(:,1)   = SeletedVerts(:,1) +VertexHeight;
                %interpolatinginterpolatinginterpolatinginterpolatinginterpolatinginterpolating
                RotMat              = reshape(app.GriddedFault.RotMat(ii,:),3,3);
                
                app.GriddedFault.RoughPosVals(SelVerticesList,1:3) = (RotMat*SeletedVerts')';
                app.GriddedFault.RoughPosVals(SelVerticesList,1)   = app.GriddedFault.RoughPosVals(SelVerticesList,1) +app.GriddedFault.FaultCentCoord(ii,1);
                app.GriddedFault.RoughPosVals(SelVerticesList,2)   = app.GriddedFault.RoughPosVals(SelVerticesList,2) +app.GriddedFault.FaultCentCoord(ii,2);
                app.GriddedFault.RoughPosVals(SelVerticesList,3)   = app.GriddedFault.RoughPosVals(SelVerticesList,3) +app.GriddedFault.FaultCentCoord(ii,3);
                app.GriddedFault.RoughPosVals(SelVerticesList,4)   = VertexHeight;
            end
            %-----------------------------%-----------------------------
            MinDepth = max(app.GriddedFault.RoughPosVals(:,3));
            if (MinDepth > 0)
                app.GriddedFault.RoughPosVals(:,3) = app.GriddedFault.RoughPosVals(:,3)-MinDepth -0.001;
            end
            %-----------------------------%-----------------------------
            for i = 1 : size(app.GriddedFault.TriangleList,1)
                v1   = app.GriddedFault.TriangleList(i,1);
                v2   = app.GriddedFault.TriangleList(i,2);
                v3   = app.GriddedFault.TriangleList(i,3);
                
                Vert1 = app.GriddedFault.RoughPosVals(v1,:);
                Vert2 = app.GriddedFault.RoughPosVals(v2,:);
                Vert3 = app.GriddedFault.RoughPosVals(v3,:);
                
                app.GriddedFault.RoughCentVals(i,1) = (Vert1(1,1)+Vert2(1,1)+Vert3(1,1))/3;
                app.GriddedFault.RoughCentVals(i,2) = (Vert1(1,2)+Vert2(1,2)+Vert3(1,2))/3;
                app.GriddedFault.RoughCentVals(i,3) = (Vert1(1,3)+Vert2(1,3)+Vert3(1,3))/3;
                %-----------------------------
            end
            %-----------------------------%-----------------------------
            if (PlotRough == 1)
                figure(FigNum*10); clf; hold on; axis equal;
                view(ViewAngle);
                SectionNumber = max(app.GriddedFault.TriangleList(:,4));
                for ii = 1 : SectionNumber
                    NextSection = find(app.GriddedFault.TriangleList(:,4) == ii);
                    trisurf(app.GriddedFault.TriangleList(NextSection,1:3),app.GriddedFault.RoughPosVals(:,1),app.GriddedFault.RoughPosVals(:,2),app.GriddedFault.RoughPosVals(:,3),app.GriddedFault.RoughPosVals(:,4));%,'linestyle','none');
                end
                axis tight;
            end
            %-----------------------------%-----------------------------
        end

        % Button pushed function: GenerateStrength_Button
        function GenerateStrength_ButtonPushed(app, event)
            if (app.GridImported       == 0),               return;                             end
            PlotStuff    = app.PlotStrength_CheckBox.Value;
            Density      = app.HalfspaceDensity_EditField.Value;
            AddSigN      = app.HalfspaceOverburden_EditField.Value;
            LgthScale    = app.RoughLengthScale_EditField.Value;
            %-----------------------------%-----------------------------
            MeanAmBseis     = zeros(size(app.StrgthParasPerSectn,1),1);
            for ii = 1 : size(app.StrgthParasPerSectn,1)
                AmBshape  = app.StrgthParasPerSectn(ii,9);
                minTemp   = 0;
                maxTemp   = 600;
                TempStps  = 300;
                TempVals  = linspace(minTemp,maxTemp,TempStps) +app.FricGeoThermStartTemp_EditField.Value;
                AmB_Vals  = zeros(size(TempVals));
                %-----------------------------------
                if     (AmBshape == 1)
                    AmB_Vals = app.AmBparabolic(TempVals);
                elseif (AmBshape == 2)
                    for i = 1 : size(TempVals,2)
                        if     ((TempVals(1,i) >= app.AmB_p1_temp(1,1)) && (TempVals(1,i) <= app.AmB_p1_temp(1,2)))
                            AmB_Vals(1,i) = app.AmB_p1(TempVals(1,i));
                        elseif ((TempVals(1,i) >  app.AmB_p2_temp(1,1)) && (TempVals(1,i) <= app.AmB_p2_temp(1,2)))
                            AmB_Vals(1,i) = app.AmB_p2(TempVals(1,i));
                        elseif ((TempVals(1,i) >  app.AmB_p3_temp(1,1)) && (TempVals(1,i) <= app.AmB_p3_temp(1,2)))
                            AmB_Vals(1,i) = app.AmB_p3(TempVals(1,i));
                        elseif ((TempVals(1,i) >  app.AmB_p4_temp(1,1)) && (TempVals(1,i) <= app.AmB_p4_temp(1,2)))
                            AmB_Vals(1,i) = app.AmB_p4(TempVals(1,i));
                        end
                    end
                end
                %-----------------------------------
                NegAmBvals        = AmB_Vals(AmB_Vals< 0);
                MeanAmBseis(ii,1) = mean(NegAmBvals);
            end
            %-----------------------------%-----------------------------

            FltIDUniq   = unique(app.FaultValues(:,20));
            FaultIDnum  = size(FltIDUniq,1);
            %------------------------------------
            for ii = 1 : FaultIDnum
                if ((app.StrgthParasPerSectn(ii,12) == 1) ||(~isfield(app.RandParasStrgt,'Vals'))) 
                    app.RandParasStrgt(ii).Vals =  rng('shuffle');
                end
            end         
            %-----------------------------%-----------------------------
            app.GriddedFault.FricVals(:,12) = 1;  %starting with assumption that all patches/cells are in seismogenic zone
            for ii = 1 : FaultIDnum
                SelSections     = find(app.StrgthParasPerSectn(:,17) == FltIDUniq(ii,1));
                SelStrgthValues = app.StrgthParasPerSectn(SelSections(1,1),:);
                if (SelStrgthValues(1,11) == 1) %use fractal strength distribution

                    CorrInStk        = SelStrgthValues(1,13);
                    CorrInDip        = SelStrgthValues(1,14);
                    HurstExp         = SelStrgthValues(1,15);
                    WaterLevel       = SelStrgthValues(1,16);
                    
                    SelectedPatchPos = find(app.GriddedFault.TriangleList(:,5) == FltIDUniq(ii,1));
                    SelectedVertIndx = app.GriddedFault.TriangleList(SelectedPatchPos,1:3);
                    SelVerticesList  = unique(SelectedVertIndx(:));
                    SeletedVerts     = app.GriddedFault.LocalPosVals(SelVerticesList,:);

                    MinStkLngthVal   = min(SeletedVerts(:,2));                                MaxStkLngthVal = max(SeletedVerts(:,2));
                    MinDipLngthVal   = min(SeletedVerts(:,3));                                MaxDipLngthVal = max(SeletedVerts(:,3));
                    FaultLength      = (MaxStkLngthVal-MinStkLngthVal);                       FaultWidth     = (MaxDipLngthVal-MinDipLngthVal);
                    PtchNumInStk     = ceil(FaultLength/LgthScale);                           PtchNumInDip   = ceil(FaultWidth/LgthScale);
                    %------------------------------
                    sampsize   = [FaultWidth/PtchNumInDip, FaultLength/PtchNumInStk];
                    %--------------------------------------%--------------------------------------
                    %--------------------------------------%--------------------------------------
                    % GENERATE THE SPATIAL RANDOM FIELD
                    %---------------------------------- % set size for spectral synthesis tool that generates fields of size (2*rmz+1, 2*rmx+1), i.e. the method requires an ODD number of points in each direction
                    if     mod(PtchNumInStk,2) == 0,       rmx = PtchNumInStk/2;
                    elseif mod(PtchNumInStk,2) == 1,       rmx = (PtchNumInStk-1)/2;
                    end
                    if     mod(PtchNumInDip,2) == 0,       rmz = PtchNumInDip/2;
                    elseif mod(PtchNumInDip,2) == 1,       rmz = (PtchNumInDip-1)/2;
                    end
                    %---------------------------------- % compose power spectrum for two of the four quadrant wavenumber vector in [-pi,pi]
                    kx = (rmx./((2*rmx+1)*sampsize(1,2)))*linspace(-2*pi,2*pi,2*rmx+1);
                    kz = (rmz./((2*rmz+1)*sampsize(1,1)))*linspace(-2*pi,2*pi,2*rmz+1);
                    kr = zeros(rmz+1,2*rmx+1);
                    %----------------------------------
                    for j = 1:(2*rmx+1)
                        for i = 1:(rmz+1)
                            kr(i,j) = sqrt((CorrInDip^2)*(kz(i)^2) + (CorrInStk^2)*(kx(j)^2));
                        end
                    end
                    k    = kr(:); k = k(k>0);        
                    coef = 4*pi*HurstExp*CorrInStk*CorrInDip./besselk(HurstExp,min(k));      
                    PS   = coef./(1 + (kr.^2)).^(HurstExp+1);
                    %----------------------------------          
                    % the IFFT needs the spectrum normalized to max.amp unity
                    PS = PS./max(PS(:));                            AM = sqrt(PS);
                    % compose the random phase initialize random number generator
                    rng(app.RandParasStrgt(ii).Vals);
                    % random phase in [0,pi]
                    PH = 2*pi*rand(size(kr));
                    % assemble the random field in FT-domain add small random high-wavenumber components
                    %x = (1 + 0.5*randn(size(kr)));
                    x = 1;
                    RAD = AM.*x;
                    % set DC-component to different value, if desired NOTE that this only changes the overall 'level' of
                    % the field, not its appearance, but has significant impact on the Fourier transform which reflects the
                    % DC-value at the smallest wavenumber ("Nugget Effect")
                    Neff             = 0;               % "Nugget" value
                    RAD(rmz+1,rmx+1) = Neff;		% "Nugget" effect, zero-mean field
                    AM(rmz+1,rmx+1)  = RAD(rmz+1,rmx+1);

                    RoughFric = RAD.*cos(PH) + sqrt(-1)*RAD.*sin(PH);
                    
                    % the following takes care of the conjugate symmetry condition in order to ensure the final random field is purely real
                    U = zeros(2*rmz+1,2*rmx+1);	        %% will be conj. sym. field
                    RoughFric = [RoughFric ; conj(fliplr(flipud(RoughFric(1:rmz,:))))];
                    %----------------------------------
                    for i = 1:rmx
                        RoughFric(rmz+1,-i+2*rmx+2) = conj(RoughFric(rmz+1,i));
                    end
                    for i = 1:rmz+1
                        for j = 1:rmx+1
                            U(i,j) = RoughFric(i+rmz,j+rmx);
                        end
                    end
                    for i = rmz+2:2*rmz+1
                        for j = rmx+2:2*rmx+1
                            U(i,j) = RoughFric(i-rmz-1,j-rmx-1);
                        end
                    end
                    for i = 1:rmz+1
                        for j = rmx+2:2*rmx+1
                            U(i,j) = RoughFric(i+rmz,j-rmx-1);
                        end
                    end
                    for i = rmz+2:2*rmz+1
                        for j = 1:rmx+1
                            U(i,j) = RoughFric(i-1-rmz,j+rmx);
                        end
                    end
                    %----------------------------------
                    % take 2D-inverse FFT to obtain spatial field; imaginary parts
                    % of order 1e-13 due to machine precision are removed;
                    % also, remove mean and scale to unit variance
                    RoughFric = real(ifft2(U));
                    
                    %should detrend the surface
                    A         = 1: size(RoughFric,2);
                    B         = 1: size(RoughFric,1);
                    [BB,AA]   = meshgrid(A,B);
                    SurfPCA   = pca([AA(:), BB(:), RoughFric(:)]);
                    PlaneNorm = SurfPCA(:,3)./norm(SurfPCA(:,3));
                    RoughFric = (AA.*PlaneNorm(1,1) + BB.*PlaneNorm(2,1) + RoughFric.*PlaneNorm(3,1));
                    %---------------------------------------

                    RoughFric = RoughFric -mean(RoughFric(:));
                    RoughFric = RoughFric./std2(RoughFric);		% standard deviation of unity
                    %--------------------------------------%--------------------------------------
                    %--------------------------------------%--------------------------------------

                    PatchPosSelGrid     = find(app.GriddedFault.TriangleList(:,5) == FltIDUniq(ii,1));
                    LocalPosSelGrid     = app.GriddedFault.LocalCentVals(PatchPosSelGrid,:);
                    MeanPatchSize       = app.GriddedFault.MeanPatchSize;
                    
                    ApproxSquareNum     = (FaultLength*FaultWidth)/MeanPatchSize;
                    AspectRatio         = FaultLength/FaultWidth;
                    PtchNumInDipregrid  = round(sqrt((ApproxSquareNum)/AspectRatio));
                    PtchNumInStkregrid  = round((ApproxSquareNum)/PtchNumInDipregrid);
                    %-----------------------------
                    VectAlongStkregrid  = linspace(MinStkLngthVal,MaxStkLngthVal,PtchNumInStkregrid);
                    VectAlongDipregrid  = linspace(MinDipLngthVal,MaxDipLngthVal,PtchNumInDipregrid);
                    %regridding....regridding....regridding....regridding....
                    RegriddedRoughFric  = imresize(RoughFric,[PtchNumInDipregrid,PtchNumInStkregrid],'bicubic');
                    %regridding....regridding....regridding....regridding....
                    RegriddedRoughFric  = RegriddedRoughFric - min(RegriddedRoughFric(:));
                    %interpolatinginterpolatinginterpolatinginterpolatinginterpolatinginterpolating
                    [X,Y]               = meshgrid(VectAlongStkregrid, VectAlongDipregrid);
                    RoughFricChng       = interp2(X,Y, RegriddedRoughFric ,LocalPosSelGrid(:,1),LocalPosSelGrid(:,2),'spline');
                    %interpolatinginterpolatinginterpolatinginterpolatinginterpolatinginterpolating
                    %-----------------------------
                    IsInSeis          = app.GriddedFault.FricVals(PatchPosSelGrid, 2)  > 0; %those with an actual stress drop
                    RghFricInSeis     = RoughFricChng(IsInSeis);
                    CutoffValue       = prctile(RghFricInSeis,WaterLevel);
                    RghFricInSeis(RghFricInSeis<CutoffValue) = CutoffValue;
                    RghFricInSeis     = RghFricInSeis - CutoffValue;

                    RoughFricChng     = RoughFricChng./mean(RghFricInSeis(:));
                    %-----------------------------
                    app.GriddedFault.FricVals(SelectedPatchPos, 12) =  RoughFricChng ;%factor has now an average of 1 when considering the seimogenic zone...
                    %-----------------------------
                end
            end
            %-----------------------------%-----------------------------      
            for jj = 1 : app.GriddedFault.SizeTrigList
                Depth       = app.GriddedFault.RoughCentVals(jj,3); %in km, and negtive b/c below surface
                SectionID   = app.GriddedFault.TriangleList(jj,4);
                FractalFact = app.GriddedFault.FricVals(jj,12);

                StatFric    = app.StrgthParasPerSectn(SectionID,2);         StatFricVari= app.StrgthParasPerSectn(SectionID,3);
                DynFric     = app.StrgthParasPerSectn(SectionID,4);         DynFricVari = app.StrgthParasPerSectn(SectionID,5);
                D_cValue    = app.StrgthParasPerSectn(SectionID,6);         D_cValVari  = app.StrgthParasPerSectn(SectionID,7);
                
                if (app.CallStrengthFromSave == 1)
                    StatFricVari= 0;                    DynFricVari  = 0;        D_cValVari   = 0;
                end                
                UseAmB      = app.StrgthParasPerSectn(SectionID,8);         AmBshape    = app.StrgthParasPerSectn(SectionID,9);
                Geotherm    = app.StrgthParasPerSectn(SectionID,10);        TempAtDepth = -Geotherm*Depth +app.FricGeoThermStartTemp_EditField.Value;
                %------------------------------------
                if (UseAmB == 1)
                    if     (AmBshape == 1)
                        AmB_Val = app.AmBparabolic(TempAtDepth);
                        AmB_fact= AmB_Val/MeanAmBseis(SectionID,1);
                    elseif (AmBshape == 2)
                        if     ((TempAtDepth >= app.AmB_p1_temp(1,1)) && (TempAtDepth <= app.AmB_p1_temp(1,2))),            AmB_Val = app.AmB_p1(TempAtDepth);
                        elseif ((TempAtDepth >  app.AmB_p2_temp(1,1)) && (TempAtDepth <= app.AmB_p2_temp(1,2))),            AmB_Val = app.AmB_p2(TempAtDepth);
                        elseif ((TempAtDepth >  app.AmB_p3_temp(1,1)) && (TempAtDepth <= app.AmB_p3_temp(1,2))),            AmB_Val = app.AmB_p3(TempAtDepth);
                        elseif ((TempAtDepth >  app.AmB_p4_temp(1,1)) && (TempAtDepth <= app.AmB_p4_temp(1,2))),            AmB_Val = app.AmB_p4(TempAtDepth);
                        end
                        AmB_fact= AmB_Val/MeanAmBseis(SectionID,1);
                    end
                else
                    AmB_fact = 1;
                end
                %------------------------------------
                app.GriddedFault.FricVals(jj,1) = StatFric*(1 - (rand(1)*2-1)*StatFricVari/100); %this is the used value for static friction coefficient for given cell
                
                dynFricPart1 = StatFric - (StatFric-DynFric)*AmB_fact*FractalFact;
                dynFricPart2 = DynFric*(rand(1)*2-1)*DynFricVari/100;

                app.GriddedFault.FricVals(jj,2) = dynFricPart1 + dynFricPart2; 

                app.GriddedFault.FricVals(jj,3) = (Density*app.Gravity*Depth*1000)/1E+6 - AddSigN;
                app.GriddedFault.FricVals(jj,4) = D_cValue*(1-(rand(1)*2-1)*D_cValVari/100)*app.GriddedFault.meanLegLengths*1000;
                app.GriddedFault.FricVals(jj,5) = app.StrgthParasPerSectn(SectionID,3);
                app.GriddedFault.FricVals(jj,6) = app.StrgthParasPerSectn(SectionID,5);
                app.GriddedFault.FricVals(jj,7) = app.StrgthParasPerSectn(SectionID,7);
                %------------------------------------
            end
            app.GriddedFault.FricVals(app.GriddedFault.FricVals(:,2) < 0, 2) = 0;
            %------------------------------------------------------------
            PosStrength  = (app.GriddedFault.FricVals(:,1)-app.GriddedFault.FricVals(:,2)).*((Density.*app.Gravity.*-app.GriddedFault.RoughCentVals(:,3).*1000)./1E+6 +AddSigN);
            
            PosStrength  = PosStrength(PosStrength  >= 0);
            MeanStrength = mean(PosStrength);
            app.AverageStrength_EditField.Value = MeanStrength;
            app.StrengthGenerated  = 1;
            %-----------------------------%-----------------------------
            if (PlotStuff == 1)
                PlotStrength_ButtonPushed(app, event);
            end
            
        end

        % Button pushed function: PlotStrength_Button
        function PlotStrength_ButtonPushed(app, event)
   
            if (app.GridImported       == 0),         app.dlga = warndlg('No Fault model has been imported' , 'ATTENTION')        ;  set(app.dlga,'Units','normalized','Position', [ .46 .46 .06 .06 ] )     ,      return;                             end
            if (app.StrengthGenerated  == 0),         app.dlga = warndlg('Random Strength field model has not been imported' , 'ATTENTION')        ;  set(app.dlga,'Units','normalized','Position', [ .46 .46 .06 .06 ] )    ,    return;                             end
          
            FigNum1         = app.FigNum_EditField.Value*10+4;
            ViewAngle       = app.ViewAngle_EditField.Value;       ViewAngle = sscanf(ViewAngle,'%f');
            PlotWRough      = app.PlotWithRoughness_CheckBox.Value;
            Density         = app.HalfspaceDensity_EditField.Value;
            AddSigN         = app.HalfspaceOverburden_EditField.Value;

            AllV1s          = zeros(app.GriddedFault.SizeTrigList,3);
            AllV2s          = zeros(app.GriddedFault.SizeTrigList,3);
            AllV3s          = zeros(app.GriddedFault.SizeTrigList,3);
            PlottedComp     = zeros(app.GriddedFault.SizeTrigList,3);
            Cntr            = 1;
            %------------------------
            CircRadius      = sqrt(app.GriddedFault.MeanPatchSize/pi) *1000;
            K_approx        = (7*pi*app.HalfspaceShearMod_EditField.Value*1E+9)/(24*CircRadius);
            %------------------------
            PlottedComp(:,1)   = ((app.GriddedFault.FricVals(:,1)+app.GriddedFault.FricVals(:,8)) -(app.GriddedFault.FricVals(:,2)+app.GriddedFault.FricVals(:,9))) .*((Density.*app.Gravity.*-app.GriddedFault.RoughCentVals(:,3).*1000)./1E+6 +AddSigN +app.GriddedFault.FricVals(:,10));
            PlottedComp(:,2)   = app.GriddedFault.FricVals(:,4) + app.GriddedFault.FricVals(:,11);
            
            PlottedComp(:,3)                                                     = 2;    
            PlottedComp(PlottedComp(:,1) < 0,3)                                  = 3;
            PlottedComp(PlottedComp(:,1) > (K_approx./1E+6.*PlottedComp(:,2)),3) = 1;
            %------------------------
            if ((PlotWRough == 1) && (sum(app.GriddedFault.RoughPosVals(:))~=0))
                for j = 1 : app.GriddedFault.SizeTrigList
                    AllV1s(Cntr,1) = app.GriddedFault.RoughPosVals(app.GriddedFault.TriangleList(j,1),1);
                    AllV1s(Cntr,2) = app.GriddedFault.RoughPosVals(app.GriddedFault.TriangleList(j,2),1);
                    AllV1s(Cntr,3) = app.GriddedFault.RoughPosVals(app.GriddedFault.TriangleList(j,3),1);
                    
                    AllV2s(Cntr,1) = app.GriddedFault.RoughPosVals(app.GriddedFault.TriangleList(j,1),2);
                    AllV2s(Cntr,2) = app.GriddedFault.RoughPosVals(app.GriddedFault.TriangleList(j,2),2);
                    AllV2s(Cntr,3) = app.GriddedFault.RoughPosVals(app.GriddedFault.TriangleList(j,3),2);
                    
                    AllV3s(Cntr,1) = app.GriddedFault.RoughPosVals(app.GriddedFault.TriangleList(j,1),3);
                    AllV3s(Cntr,2) = app.GriddedFault.RoughPosVals(app.GriddedFault.TriangleList(j,2),3);
                    AllV3s(Cntr,3) = app.GriddedFault.RoughPosVals(app.GriddedFault.TriangleList(j,3),3);
                    %-----------------------------
                    Cntr                  = Cntr +1;
                    %-----------------------------
                end
                DepthVals      = app.GriddedFault.RoughCentVals(:,3);
            else
                for j = 1 : app.GriddedFault.SizeTrigList
                    AllV1s(Cntr,1) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,1),1);
                    AllV1s(Cntr,2) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,2),1);
                    AllV1s(Cntr,3) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,3),1);
                    
                    AllV2s(Cntr,1) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,1),2);
                    AllV2s(Cntr,2) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,2),2);
                    AllV2s(Cntr,3) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,3),2);
                    
                    AllV3s(Cntr,1) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,1),3);
                    AllV3s(Cntr,2) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,2),3);
                    AllV3s(Cntr,3) = app.GriddedFault.GlobalVertices(app.GriddedFault.TriangleList(j,3),3);
                    %-----------------------------
                    Cntr                  = Cntr +1;
                    %-----------------------------
                end
                DepthVals      = app.GriddedFault.RoughCentVals(:,3);
            end
            %-----------------------------
            figure(FigNum1); clf; hold on; axis equal;
            subplot(2,2,1);axis equal;
            xlabel('easting');ylabel('northing'); zlabel('depth');
            title('Coseismic stress change/strength in MPa');
            patch(AllV1s',AllV2s',AllV3s',PlottedComp(:,1)');
            view(ViewAngle'); axis tight;   
            caxis([-max(PlottedComp(:,1)) max(PlottedComp(:,1))]);
            set(gca,"Colormap",app.Color_Blue2Red);
            colorbar;
            %-----------------------------
            subplot(2,2,2);axis equal;
            xlabel('easting');ylabel('northing'); zlabel('depth');
            title('D_c distribution and variability');
            patch(AllV1s',AllV2s',AllV3s',PlottedComp(:,2)');
            view(ViewAngle'); axis tight;
            set(gca,"Colormap",hot);
            colorbar;
            %-----------------------------
            subplot(2,2,3);axis equal;
            xlabel('easting');ylabel('northing'); zlabel('depth');
            title('Stability regime');
            patch(AllV1s',AllV2s',AllV3s',PlottedComp(:,3)');
            view(ViewAngle'); axis tight;
            set(gca,"Colormap",parula);
            colorbar;
            %-----------------------------
            FricVals1 = app.GriddedFault.FricVals(:,1)+app.GriddedFault.FricVals(:,8);
            FricVals2 = app.GriddedFault.FricVals(:,2)+app.GriddedFault.FricVals(:,9);
            subplot(2,2,4); hold on;
            xlabel('Stat. friction coeff (in blue) and coseismic change of coeff. (in red)'); ylabel('Depth (km)');
            plot(FricVals1,DepthVals,'ob','markersize',8);
            plot((FricVals2),DepthVals,'xr','markersize',8);
            plot([0 0],[min(DepthVals) max(DepthVals)],'k');
      %      xlim([min(FricVals2) max(FricVals2)]);
        end

        % Button pushed function: SaveRoughStrenth_Button
        function SaveRoughStrenth_ButtonPushed(app, event)
            
            CompletePath   = strcat(app.SavePath,app.SaveName_EditField.Value);
            %-----------------------
            Name4ascii     = strcat(CompletePath,'_Summary_RoughStrength.txt');
            %-----------------------
            NumOfMods2Save = app.SaveModels2Generate_EditField.Value;
            %-------------------------------
            if (NumOfMods2Save > 1)
                app.PlotRoughness_CheckBox.Value     = 0;
                app.PlotStrength_CheckBox.Value      = 0;
                app.PlotWithRoughness_CheckBox.Value = 0;
                drawnow;
            end
            %-------------------------------
            Density   = app.HalfspaceDensity_EditField.Value;
            AddSigN   = app.HalfspaceOverburden_EditField.Value;
            ShearMod  = app.HalfspaceShearMod_EditField.Value;
            Poisson   = app.HalfspacePoisson_EditField.Value;
            
            RandmzeFrc= app.FricAllowReset_CheckBox.Value;
            %-------------------------------%-------------------------------
            FltNum    = size(app.FaultNames,2);
            %--------------------------------------%-----------------------------------
            fidascii    = fopen(Name4ascii ,'w');
            if (fidascii == -1), errordlg('The entered filename has not been found.');    return;   end
            
            fprintf(fidascii,'Half-space_medium_density(kg/m^3):   %10.4e\n', Density);
            fprintf(fidascii,'Half-space_added_NormalStress(MPa):  %10.4e\n', AddSigN);
            fprintf(fidascii,'Half-space_shear_modulus(GPa):       %10.4e\n', ShearMod);
            fprintf(fidascii,'Half-space_poisson_ratio:            %10.4e\n', Poisson);
            
            fprintf(fidascii,'Randomize_FricStrengthBetweenEQS: %d\n',RandmzeFrc);
            FltIDUniq   = unique(app.FaultValues(:,20));
            
            for i = 1 : FltNum
                FaultIDpos = find(FltIDUniq == app.FaultValues(i,20),1);
                
                fprintf(fidascii,'Fault_ID_number:       %d\n',    app.FaultValues(i,20));
                fprintf(fidascii,'Fault_section_name:    %s\n',    app.FaultNames{1,i});
                fprintf(fidascii,'Roughness Parameters:    \n');
                fprintf(fidascii,'Roughness_Type:        %d\n',    app.RoughParasPerFault(FaultIDpos,2));
                fprintf(fidascii,'HurstExp_vari_Type:    %d\n',    app.RoughParasPerFault(FaultIDpos,3));
                fprintf(fidascii,'PreFact_vari_Type:     %d\n',    app.RoughParasPerFault(FaultIDpos,4));
                fprintf(fidascii,'CorrLgth_vari_Type:    %d\n',    app.RoughParasPerFault(FaultIDpos,5));
                fprintf(fidascii,'CorrLgth_in_Strike:    %10.4e\n',app.RoughParasPerFault(FaultIDpos,6));
                fprintf(fidascii,'CorrLgth_in_Dip:       %10.4e\n',app.RoughParasPerFault(FaultIDpos,7));
                fprintf(fidascii,'CorrLgth_vari_Amount:  %10.4e\n',app.RoughParasPerFault(FaultIDpos,8));
                fprintf(fidascii,'HurstExponent_Strike:  %10.4e\n',app.RoughParasPerFault(FaultIDpos,9));
                fprintf(fidascii,'HurstExponent_Dip:     %10.4e\n',app.RoughParasPerFault(FaultIDpos,10));
                fprintf(fidascii,'HurstExp_vari_Amount:  %10.4e\n',app.RoughParasPerFault(FaultIDpos,11));
                fprintf(fidascii,'Fractal_Dimension:     %10.4e\n',app.RoughParasPerFault(FaultIDpos,12));
                fprintf(fidascii,'Corner_Frequency:      %10.4e\n',app.RoughParasPerFault(FaultIDpos,13));
                fprintf(fidascii,'RandMidDisp_Ampli:     %10.4e\n',app.RoughParasPerFault(FaultIDpos,17));
                fprintf(fidascii,'RandMidDisp_Decay:     %10.4e\n',app.RoughParasPerFault(FaultIDpos,15));
                fprintf(fidascii,'NonLin_ScaleFactor:    %10.4e\n',app.RoughParasPerFault(FaultIDpos,16));
                fprintf(fidascii,'PreFactor:             %10.4e\n',app.RoughParasPerFault(FaultIDpos,17));
                fprintf(fidascii,'PreFator_vari_Amount:  %10.4e\n',app.RoughParasPerFault(FaultIDpos,18));
                
                fprintf(fidascii,'Strength/Friction Parameters:    \n');
                fprintf(fidascii,'Used_FrictionType:      linear slip weakening\n');
                fprintf(fidascii,'StaticFric_value:       %10.4e\n',app.StrgthParasPerSectn(i,2));
                fprintf(fidascii,'StaticFric_vari_value:  %10.4e\n',app.StrgthParasPerSectn(i,3));
                fprintf(fidascii,'DynamicFric_value:      %10.4e\n',app.StrgthParasPerSectn(i,4));
                fprintf(fidascii,'DynFric_valchg_vari:    %10.4e\n',app.StrgthParasPerSectn(i,5));
                fprintf(fidascii,'Critical_SlipDistance:  %10.4e\n',app.StrgthParasPerSectn(i,6));
                fprintf(fidascii,'Critical_SlipDistVari:  %10.4e\n',app.StrgthParasPerSectn(i,7));
                fprintf(fidascii,'Use_(a-b)_Profile:      %d\n',    app.StrgthParasPerSectn(i,8));
                fprintf(fidascii,'(a-b)_shape:            %d\n',    app.StrgthParasPerSectn(i,9));
                fprintf(fidascii,'Geothermal_Gradient:    %10.4e\n',app.StrgthParasPerSectn(i,10));
                
                fprintf(fidascii,'Use_FractStrgth:        %d\n',    app.StrgthParasPerSectn(i,11));
                fprintf(fidascii,'CorrLgth_in_Strike:     %10.4e\n',app.StrgthParasPerSectn(i,13));
                fprintf(fidascii,'CorrLgth_in_Dip:        %10.4e\n',app.StrgthParasPerSectn(i,14));
                fprintf(fidascii,'HurstExponent:          %10.4e\n',app.StrgthParasPerSectn(i,15));
                fprintf(fidascii,'Strength_WaterLevel:    %10.4e\n',app.StrgthParasPerSectn(i,16));
                fprintf(fidascii,'\n');
            end
            fclose(fidascii);
            
            app.CallStrengthFromSave = 1;
            %--------------------------------------%-----------------------------------
            for i = 1 : NumOfMods2Save
                app.SaveCurrentModelNum_EditField.Value = i;
                drawnow;
                %------------------------------%------------------------------
                
                GenerateRoughness_ButtonPushed(app, event);
                CombinewGrid_ButtonPushed(app,event);
                GenerateStrength_ButtonPushed(app, event);
               
                %------------------------------%---------------
                Name4binary_R  = strcat(CompletePath,'_',num2str(i),'_Roughn.dat');
                Name4binary_S  = strcat(CompletePath,'_',num2str(i),'_Strgth.dat');
                fidbinR        = fopen(Name4binary_R,'wb');
                fidbinS        = fopen(Name4binary_S,'wb');
                if (fidbinR == -1), errordlg('The entered filename has not been found.');    return;   end
                if (fidbinS == -1), errordlg('The entered filename has not been found.');    return;   end
                %------------------------------%---------------
                fwrite(fidbinR,size(app.GriddedFault.TriangleList,1),'int32');
                fwrite(fidbinR,size(app.GriddedFault.VertexList,1),  'int32');
                fwrite(fidbinR,app.GriddedFault.meanLegLengths,      'single');
                fwrite(fidbinR,app.GriddedFault.meanPatchAreas,      'single');
                fwrite(fidbinR,app.GriddedFault.TriangleList(:,1),   'int32');%patch
                fwrite(fidbinR,app.GriddedFault.TriangleList(:,2),   'int32');%patch
                fwrite(fidbinR,app.GriddedFault.TriangleList(:,3),   'int32');%patch
                fwrite(fidbinR,app.GriddedFault.TriangleList(:,4),   'int32');%patch
                fwrite(fidbinR,app.GriddedFault.TriangleList(:,5),   'int32');%patch
                fwrite(fidbinR,app.GriddedFault.TriangleList(:,7),   'single');%patch
                fwrite(fidbinR,app.GriddedFault.TriangleList(:,8),   'single');%patch
                fwrite(fidbinR,app.GriddedFault.TriangleList(:,9),   'single');%patch
                fwrite(fidbinR,app.GriddedFault.VertexList(:,1),     'single');%vertex
                fwrite(fidbinR,app.GriddedFault.VertexList(:,2),     'single');%vertex
                fwrite(fidbinR,app.GriddedFault.RoughPosVals(:,1),   'single');%vertex
                fwrite(fidbinR,app.GriddedFault.RoughPosVals(:,2),   'single');%vertex
                fwrite(fidbinR,app.GriddedFault.RoughPosVals(:,3),   'single');%vertex
                fwrite(fidbinR,app.GriddedFault.RoughPosVals(:,4),   'single');%vertex
                fwrite(fidbinR,app.GriddedFault.RoughCentVals(:,1),  'single');%patch
                fwrite(fidbinR,app.GriddedFault.RoughCentVals(:,2),  'single');%patch
                fwrite(fidbinR,app.GriddedFault.RoughCentVals(:,3),  'single');%patch
                   
                fwrite(fidbinS,size(app.GriddedFault.TriangleList,1),'int32');
                fwrite(fidbinS,app.GriddedFault.FricVals(:,1),       'single'); %realization of static coeff. of friction
                fwrite(fidbinS,app.GriddedFault.FricVals(:,2),       'single'); %realization of dynamic friction coeff.
                fwrite(fidbinS,app.GriddedFault.FricVals(:,3),       'single'); %normal stress on point
                fwrite(fidbinS,app.GriddedFault.FricVals(:,4),       'single'); %Dc on point
                fwrite(fidbinS,app.GriddedFault.FricVals(:,5),       'single'); %variability of mu_s
                fwrite(fidbinS,app.GriddedFault.FricVals(:,6),       'single'); %variability of mu_d
                fwrite(fidbinS,app.GriddedFault.FricVals(:,7),       'single'); %variability of D_c
                fwrite(fidbinS,app.GriddedFault.FricVals(:,8),       'single'); %manual modification of mu_s
                fwrite(fidbinS,app.GriddedFault.FricVals(:,9),       'single'); %manual modification of mu_d
                fwrite(fidbinS,app.GriddedFault.FricVals(:,10),      'single'); %manual modification of sigma_n
                fwrite(fidbinS,app.GriddedFault.FricVals(:,11),      'single'); %manual modification of D_c
                %------------------------------%---------------
                fclose(fidbinR);
                fclose(fidbinS);
                %------------------------------%------------------------------
            end
            %--------------------------------------%-----------------------------------
            app.CallStrengthFromSave = 0;
        end

        % Value changed function: StrengthUseFractalDistrib_CheckBox
        function StrengthUseFractalDistrib_CheckBoxValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,11) = app.StrengthUseFractalDistrib_CheckBox.Value;
            end
        end

        % Value changed function: StrengthSeed_DropDown
        function StrengthSeed_DropDownValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,12) = str2double(app.StrengthSeed_DropDown.Value);
            end
        end

        % Value changed function: StrengthCorrLengthStrike_EditField
        function StrengthCorrLengthStrike_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,13) = app.StrengthCorrLengthStrike_EditField.Value;
            end
        end

        % Value changed function: StrengthCorrLengthDip_EditField
        function StrengthCorrLengthDip_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,14) = app.StrengthCorrLengthDip_EditField.Value;
            end
        end

        % Value changed function: StrengthHurstExp_EditField
        function StrengthHurstExp_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,15) = app.StrengthHurstExp_EditField.Value;
            end
        end

        % Value changed function: StrengthWaterLevel_EditField
        function StrengthWaterLevel_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,16) = app.StrengthWaterLevel_EditField.Value;
            end
        end

        % Button pushed function: LoadRoughnessStrength_Button
        function LoadRoughnessStrength_ButtonPushed(app, event)
            %only to be able to visualize... => means to load the strength etc files
            
            [DataName,DataPath]  = uigetfile(strcat(app.SaveName_EditField.Value,'*_Roughn.dat'),'Load gridded Fault Fault Model');
            if     (DataName ~=0),      DataName = extractBefore(DataName,'_Roughn.dat');        CompletePath = strcat(DataPath,DataName);
            elseif (DataName ==0),      return;
            end
            
            Name4binary_R        = strcat(CompletePath,'_Roughn.dat');
            Name4binary_S        = strcat(CompletePath,'_Strgth.dat');
            %-----------------------
            fidbinR    = fopen(Name4binary_R,'rb');
            if (fidbinR == -1), errordlg('The entered filename has not been found. 2');    return;   end
            fidbinS    = fopen(Name4binary_S ,'rb');
            if (fidbinS == -1), errordlg('The entered filename has not been found. 3');    return;   end
            %-----------------------
      
            PatchNum = fread(fidbinR,1,'int');
            VertNum  = fread(fidbinR,1,'int');
            app.GriddedFault.meanLegLengths  = fread(fidbin,1,'single');
            app.GriddedFault.meanPatchAreas  = fread(fidbin,1,'single');  
            if ((PatchNum ~= app.GriddedFault.SizeTrigList) || (VertNum ~= app.GriddedFault.SizeVertList))
                disp('patch and/or vertex numbers are not consistent');
                return;
            end
            app.GriddedFault.TriangleList(:,1)     = fread(fidbinR,PatchNum,'int32');
            app.GriddedFault.TriangleList(:,2)     = fread(fidbinR,PatchNum,'int32');
            app.GriddedFault.TriangleList(:,3)     = fread(fidbinR,PatchNum,'int32');
            app.GriddedFault.TriangleList(:,4)     = fread(fidbinR,PatchNum,'int32');
            app.GriddedFault.TriangleList(:,5)     = fread(fidbinR,PatchNum,'int32');
            app.GriddedFault.TriangleList(:,6)     = 1:PatchNum;
            app.GriddedFault.TriangleList(:,7)     = fread(fidbinR,PatchNum,'single'); %stressing rate
            app.GriddedFault.TriangleList(:,8)     = fread(fidbinR,PatchNum,'single'); %slip rate
            app.GriddedFault.TriangleList(:,9)     = fread(fidbinR,PatchNum,'single'); %rake
            app.GriddedFault.VertexList(:,1)       = fread(fidbinR,VertNum,'single');
            app.GriddedFault.VertexList(:,2)       = fread(fidbinR,VertNum,'single');
            app.GriddedFault.RoughPosVals(:,1)     = fread(fidbinR,VertNum,'single');
            app.GriddedFault.RoughPosVals(:,2)     = fread(fidbinR,VertNum,'single');
            app.GriddedFault.RoughPosVals(:,3)     = fread(fidbinR,VertNum,'single');
            app.GriddedFault.RoughPosVals(:,4)     = fread(fidbinR,VertNum,'single');
            app.GriddedFault.RoughCentVals(:,1)    = fread(fidbinR,PatchNum,'single');
            app.GriddedFault.RoughCentVals(:,2)    = fread(fidbinR,PatchNum,'single');
            app.GriddedFault.RoughCentVals(:,3)    = fread(fidbinR,PatchNum,'single');
            
            app.GriddedFault.GlobalVertices        = app.GriddedFault.RoughPosVals(:,1:3);
            app.GriddedFault.GlobTrigCentr         = app.GriddedFault.RoughCentVals;
            
            PatchNumS = fread(fidbinS,1,'int32'); %not needed now... -still need to load to advance the file pointer...
            app.GriddedFault.FricVals(:,1)        = fread(fidbinS,PatchNumS,'single');
            app.GriddedFault.FricVals(:,2)        = fread(fidbinS,PatchNumS,'single');
            app.GriddedFault.FricVals(:,3)        = fread(fidbinS,PatchNumS,'single');
            app.GriddedFault.FricVals(:,4)        = fread(fidbinS,PatchNumS,'single');
            app.GriddedFault.FricVals(:,5)        = fread(fidbinS,PatchNumS,'single');
            app.GriddedFault.FricVals(:,6)        = fread(fidbinS,PatchNumS,'single');
            app.GriddedFault.FricVals(:,7)        = fread(fidbinS,PatchNumS,'single');
            app.GriddedFault.FricVals(:,8)        = fread(fidbinS,PatchNumS,'single');
            app.GriddedFault.FricVals(:,9)        = fread(fidbinS,PatchNumS,'single');
            app.GriddedFault.FricVals(:,10)       = fread(fidbinS,PatchNumS,'single');
            app.GriddedFault.FricVals(:,11)       = fread(fidbinS,PatchNumS,'single');
            app.GriddedFault.FricVals(:,12)       = ones(size(PatchNumS,1),1);

            fclose(fidbinR);
            fclose(fidbinS);
            
            app.GridImported       = 1;
            app.StrengthGenerated  = 1;
            app.RoughnessGenerated = 1;
             
        end

        % Button pushed function: ExtractSave_SurfTrace_Button
        function ExtractSave_SurfTrace_ButtonPushed(app, event)
            if (app.RoughnessGenerated == 0),   return;             end
            
            [DataName,DataPath]  = uiputfile('FaultTraces.mat','Save/Extract fault traces');
            if     (DataName ~=0),      CompletePath = strcat(DataPath,DataName);
            elseif (DataName ==0),      return;
            end
            
            FltIDUniq   = unique(app.FaultValues(:,20));
            FaultNumber = size(FltIDUniq,1);
            FaultTrace  = struct;

            for (ii = 1 : FaultNumber)
                ii
                FaultTrace(ii).TraceVals     = app.SurfTraceVals(ii).TraceVals;
                FaultTrace(ii).NewSeed       = 1;
                FaultTrace(ii).RoughType     = app.RoughParasPerFault(ii,2);
                FaultTrace(ii).H_varityp     = app.RoughParasPerFault(ii,3);
                FaultTrace(ii).PreF_varityp  = app.RoughParasPerFault(ii,4);
                FaultTrace(ii).CLgth_varityp = app.RoughParasPerFault(ii,5);
                FaultTrace(ii).CLgth_stk     = app.RoughParasPerFault(ii,6);
                FaultTrace(ii).CLgth_dip     = app.RoughParasPerFault(ii,7);
                FaultTrace(ii).CLgth_vari    = app.RoughParasPerFault(ii,8);
                FaultTrace(ii).H_stk         = app.RoughParasPerFault(ii,9);
                FaultTrace(ii).H_dip         = app.RoughParasPerFault(ii,10);
                FaultTrace(ii).H_vari        = app.RoughParasPerFault(ii,11);
                FaultTrace(ii).FracDim       = app.RoughParasPerFault(ii,12);
                FaultTrace(ii).CornWaveNum   = app.RoughParasPerFault(ii,13);
                FaultTrace(ii).RMDAmpl       = app.RoughParasPerFault(ii,14);
                FaultTrace(ii).RMDDecay      = app.RoughParasPerFault(ii,15);
                FaultTrace(ii).NonLinExp     = app.RoughParasPerFault(ii,16);
                FaultTrace(ii).PreFact       = app.RoughParasPerFault(ii,17);
                FaultTrace(ii).PreFact_vari  = app.RoughParasPerFault(ii,18);
                FaultTrace(ii).FaultIDnum    = app.RoughParasPerFault(ii,19);
            end
            save(CompletePath,'FaultTrace','FaultNumber');
            app.SurfTraceImported = 1;
        end

        % Button pushed function: Load_SurfTrace_Button
        function Load_SurfTrace_ButtonPushed(app, event)
            if (app.GridImported == 0),   return;             end
            
            [DataName,DataPath]  = uigetfile('FaultTraces.mat','Load fault trace');
            if     (DataName ~=0),      CompletePath = strcat(DataPath,DataName);
            elseif (DataName ==0),      return;
            end
            
            load(CompletePath);
            
            for (ii = 1 : FaultNumber)
                app.SurfTraceVals(ii).TraceVals = FaultTrace(ii).TraceVals;
                app.RoughParasPerFault(ii,1)    = 1;
                if (isfield(FaultTrace,'RoughType')),          app.RoughParasPerFault(ii,2)    = FaultTrace(ii).RoughType;             end  
                if (isfield(FaultTrace,'H_varityp')),          app.RoughParasPerFault(ii,3)    = FaultTrace(ii).H_varityp;             end
                if (isfield(FaultTrace,'PreF_varityp')),       app.RoughParasPerFault(ii,4)    = FaultTrace(ii).PreF_varityp;          end
                if (isfield(FaultTrace,'CLgth_varityp')),      app.RoughParasPerFault(ii,5)    = FaultTrace(ii).CLgth_varityp;         end
                if (isfield(FaultTrace,'CLgth_stk')),          app.RoughParasPerFault(ii,6)    = FaultTrace(ii).CLgth_stk;             end
                if (isfield(FaultTrace,'CLgth_dip')),          app.RoughParasPerFault(ii,7)    = FaultTrace(ii).CLgth_dip;             end
                if (isfield(FaultTrace,'CLgth_vari')),         app.RoughParasPerFault(ii,8)    = FaultTrace(ii).CLgth_vari;            end
                if (isfield(FaultTrace,'H_stk')),              app.RoughParasPerFault(ii,9)    = FaultTrace(ii).H_stk;                 end
                if (isfield(FaultTrace,'H_dip')),              app.RoughParasPerFault(ii,10)   = FaultTrace(ii).H_dip;                 end
                if (isfield(FaultTrace,'H_vari')),             app.RoughParasPerFault(ii,11)   = FaultTrace(ii).H_vari;                end
                if (isfield(FaultTrace,'FracDim')),            app.RoughParasPerFault(ii,12)   = FaultTrace(ii).FracDim;               end
                if (isfield(FaultTrace,'CornWaveNum')),        app.RoughParasPerFault(ii,13)   = FaultTrace(ii).CornWaveNum;           end
                if (isfield(FaultTrace,'RMDAmpl')),            app.RoughParasPerFault(ii,14)   = FaultTrace(ii).RMDAmpl;               end
                if (isfield(FaultTrace,'RMDDecay')),           app.RoughParasPerFault(ii,15)   = FaultTrace(ii).RMDDecay;              end
                if (isfield(FaultTrace,'NonLinExp')),          app.RoughParasPerFault(ii,16)   = FaultTrace(ii).NonLinExp;             end
                if (isfield(FaultTrace,'PreFact')),            app.RoughParasPerFault(ii,17)   = FaultTrace(ii).PreFact;               end
                if (isfield(FaultTrace,'PreFact_vari')),       app.RoughParasPerFault(ii,18)   = FaultTrace(ii).PreFact_vari;          end
                if (isfield(FaultTrace,'FaultIDnum')),         app.RoughParasPerFault(ii,19)   = FaultTrace(ii).FaultIDnum;            end
            end
            
            FaultList_ListBoxValueChanged(app, event);
            app.SurfTraceImported = 1;
        end

        % Button pushed function: SetStrengthFieldButton
        function SetStrengthFieldButtonPushed(app, event)
       
            app.Manual_Strength = [] ; app.Manual_Strength2 = [] ; app.Manual_Strength3 = [] ;
            app.Manual_Strength.how_big = get(0,'ScreenSize') ; if app.Manual_Strength.how_big(1,3) > 1800 , app.Manual_Strength.a_si(1) = 18 ; app.Manual_Strength.a_si(2) = 10 ; app.Manual_Strength.a_si(3) = 10 ; app.Manual_Strength.a_si(4) = 2 ;  else , app.Manual_Strength.a_si(1) = 8 ;  app.Manual_Strength.a_si(2) = 4 ; app.Manual_Strength.a_si(3) = 2 ; app.Manual_Strength.a_si(4) = 4 ;   end
            if (app.GridImported       == 0),   app.dlga = warndlg('No Fault model has been imported' , 'ATTENTION')        ;  set(app.dlga,'Units','normalized','Position', [ .46 .46 .06 .06 ] )    ,      return;                             end
               app.Manual_Strength.xn = [] ; app.Manual_Strength.MinStkLngthVal = [] ;
            app.Manual_Strength.yn = [] ; app.Manual_Strength.MaxStkLngthVal = [] ;
            app.Manual_Strength.X = [] ; app.Manual_Strength.MinDipLngthVal = [ ] ;
            app.Manual_Strength.Y = [] ; app.Manual_Strength.MaxDipLngthVal = [ ] ;
            app.Manual_Strength.haa =  1 ; app.Strength_Field = [] ;
            app.Strength_Field.es_a1 = [] ;
            FaultNumber     = size(unique(app.FaultValues(:,20)),1);
             for ii = 1 : FaultNumber
                 SelFaultID      = app.RoughParasPerFault(ii,19);
                     SelectedPatches = app.GriddedFault(1).TriangleList( (app.GriddedFault(1).TriangleList(:,5) == SelFaultID) ,1:3);
                            SelVerticesList = unique(SelectedPatches(:));
                            SeletedVerts    = app.GriddedFault(1).LocalPosVals(SelVerticesList,:); %this is in local coordinate system that is best fitting plane to all fault sections of a given fault               
                            app.Manual_Strength.MinStkLngthVal(ii)  = min(SeletedVerts(:,2));                                app.Manual_Strength.MinDipLngthVal(ii) = min(SeletedVerts(:,3));
                            app.Manual_Strength.MaxStkLngthVal(ii)  = max(SeletedVerts(:,2));                                app.Manual_Strength.MaxDipLngthVal(ii) = max(SeletedVerts(:,3));
                            app.Manual_Strength.FaultLength(ii)     = (  app.Manual_Strength.MaxStkLngthVal(ii) - app.Manual_Strength.MinStkLngthVal(ii)  );                       app.Manual_Strength.FaultWidth(ii)      = (  app.Manual_Strength.MaxDipLngthVal(ii) -app.Manual_Strength.MinDipLngthVal(ii)  );
            app.Manual_Strength.xn{ii}=linspace(app.Manual_Strength.MinStkLngthVal(ii), app.Manual_Strength.MaxStkLngthVal(ii), round(app.Manual_Strength.FaultLength(ii) ) * 10 );
             app.Manual_Strength.yn{ii}=linspace(app.Manual_Strength.MinDipLngthVal(ii),app.Manual_Strength.MaxDipLngthVal(ii), round(  app.Manual_Strength.FaultWidth(ii)   )  * 10 );
             [app.Manual_Strength.X{ii} , app.Manual_Strength.Y{ii}]=meshgrid(app.Manual_Strength.xn{ii} , app.Manual_Strength.yn{  ii  }  )  ;
             for ia = 1 : 4
         app.Manual_Strength.select_st{ ii , ia } = 'red' ;
         app.Manual_Strength.select_se( ii , ia ) = 0 ;
             end
             end
             
            ii = 1 ;
            app.Manual_Strength.geaga = 2 ;
            current = cd;
            hf=figure;
            set(gcf,'NumberTitle','off','Name',' MCQSim - Build Structure of Strength Field ' , 'men' , 'no' ,  'Units'  ,  'normalized' , 'Position' , [  0.06    0.09    0.84    0.79]  )
            set(gca,'Box','on' , 'units' , 'normalized' , 'OuterPosition' , [  0 0 1 .86  ]  )
            app.Manual_Strength2.main.vardraw = [];
            cd(current)
            app.Manual_Strength.ic=1;
            axis equal
                axis([  app.Manual_Strength.MinStkLngthVal(ii) app.Manual_Strength.MaxStkLngthVal(ii)  app.Manual_Strength.MinDipLngthVal(ii)  app.Manual_Strength.MaxDipLngthVal(ii)  ]  )
            set(gca,'YDir','reverse' )
            app.Manual_Strength.deletehist = [] ;
            app.Manual_Strength.hma = 1 ;
            app.Manual_Strength2.main.h.title = title('Structure of Strength Field - Friction coefficient \mu_{S} and/or \mu_{D} - Stress \sigma in MPa - D_{C} in meters. ') ;
                   hmf   = unique(app.FaultValues(:,20));
                   faultid  = size(hmf,1);  
                   app.Manual_Strength.z{1,  faultid  } = []  ;
                   app.Manual_Strength.filteredZ{1,  faultid  } = []  ;
                   app.Manual_Strength.inpo = []  ;
                        for iaa = 1 : FaultNumber
                            SelFaultID      = app.RoughParasPerFault(  iaa  ,19);
              st2a{iaa} = [  'Fault ID ' num2str(  SelFaultID  ) ' out of '  num2str(faultid )  ] ;
              for ia = 1 : 4
              app.Manual_Strength.z{ia, iaa  } = zeros( size(app.Manual_Strength.X{iaa}) )  ;
              app.Manual_Strength.filteredZ{ia,  iaa  } =   zeros( size(app.Manual_Strength.X{iaa}) )   ;
              app.Manual_Strength.inpo{ ia , faultid  ,  iaa  } =   zeros( size(app.Manual_Strength.X{iaa}) )   ;
              app.Manual_Strength.select_sta{  ia, iaa  } = ' '  ;
              end
                        end            
                        app.Manual_Strength.ai(1) = uipanel('Title','Fault Plane Selection Panel','FontSize',8,...
                         'BackgroundColor', get(gcf,'color')   ,  'fontsize' , app.Manual_Strength.a_si(1) ,  'fontweight','bold',...
                         'Position',[  .02 .86 .16 .12  ]);
            app.Manual_Strength2.a1 = uicontrol('style','popupmenu','parent' , app.Manual_Strength.ai(1) , 'un','norm',...
                'pos',  [  .06 .6 .88 .12 ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str', st2a  , 'callb',   {@smo2,app , 2}   );
            app.Manual_Strength2.t2 = uicontrol('style','text','parent' , app.Manual_Strength.ai(1) ,'un','norm',...
                'pos',  [ .002 .91 .06 .02  ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str','Fault plane Selection' , 'callb',' ');
                  app.Manual_Strength.ai(2) = uipanel('Title','Manual Strength Field','FontSize',8,...
                         'BackgroundColor', get(gcf,'color')   ,  'fontsize' , app.Manual_Strength.a_si(1) ,  'fontweight','bold',...
                         'Position',[  .202 .86 .3 .12   ]);
            app.Manual_Strength.ai(4) = uicontrol('style','pushbutton','parent' , app.Manual_Strength.ai(2) , 'un','norm',...
                'pos',  [  .02 .2 .16 .6 ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str', 'Polygon Area'   , 'callb',  {@drawpolygon,app , 1 }    );
            app.Manual_Strength.ai(5) = uicontrol('style','pushbutton','parent' , app.Manual_Strength.ai(2) , 'un','norm',...
                'pos',  [  .2 .2 .16 .6 ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str', 'Square Area'   , 'callb',   {@drawpolygon,app , 2 }    );
              app.Manual_Strength.ai(19) = uicontrol('style','pushbutton','parent' , app.Manual_Strength.ai(2) , 'un','norm',...
                'pos',  [  .38 .2 .16 .6 ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str', '<html><center>Entire<br />Fault Plane</center> </html>'   , 'callb',   {@draw_all,app , 2 }    );
               app.Manual_Strength2.a4 = uicontrol('style','pushbutton','parent' , app.Manual_Strength.ai(2)  , 'un','norm',...
                'pos',  [  .56 .2 .16 .6 ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str',  '<html><center>Add<br />Ascii file</center> </html>'  , 'callb', {@manual_area_asc ,app , 2}  ) ;
         
            app.Manual_Strength.ai(6) = uicontrol('style','pushbutton','parent' , app.Manual_Strength.ai(2) , 'un','norm',...
                'pos',  [  .74 .56 .24 .48 ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str', 'Clear Patch'   , 'callb',   {@clear_a,app , 1 }   );
            app.Manual_Strength.ai(7) = uicontrol('style','pushbutton','parent' , app.Manual_Strength.ai(2) , 'un','norm',...
                'pos',  [  .74 .06 .24 .48 ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str', 'Clear entire plane'   , 'callb',   {@clear_a,app , 2 }   );
             app.Manual_Strength.ai(3) = uipanel('Title','Information of 2D Field','FontSize',8,...
                         'BackgroundColor', get(gcf,'color')   ,  'fontsize' , app.Manual_Strength.a_si(1) ,  'fontweight','bold',...
                         'Position',[  .53 .86 .2 .12  ]);
             app.Manual_Strength.ai(8) = uicontrol('style','text','parent' , app.Manual_Strength.ai(3) ,'un','norm',...
                'pos',  [ .02 .64 .96 .26  ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str','Current Fault plane ID size:' , 'callb',' ');
             app.Manual_Strength.ai(9) = uicontrol('style','text','parent' , app.Manual_Strength.ai(3) ,'un','norm',...
                'pos',  [ .02 .32 .96 .3  ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str','Nmuber of elements to be fitted:' , 'callb',' ');
             app.Manual_Strength.ai(12) = uicontrol('style','text','parent' , app.Manual_Strength.ai(3) ,'un','norm',...
                'pos',  [ .02 .01 .96 .3  ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str','Nmuber of assigned sub-areas:' , 'callb',' ');
            app.Manual_Strength.ai(14) = uipanel('Title','Set Strength Field','FontSize',8,...
                         'BackgroundColor', get(gcf,'color')   ,  'fontsize' , app.Manual_Strength.a_si(1) ,  'fontweight','bold',...
                         'Position',[  .76 .86 .2 .12   ]);
            app.Manual_Strength2.ed1 = uicontrol('style','edit','parent' , app.Manual_Strength.ai(14)  , 'un','norm',...
                'pos',[  .02 .2 .2 .4 ],  'fontsize' , app.Manual_Strength.a_si(1) ,  'str','1' , 'callb',' ');
            app.Manual_Strength2.t1 = uicontrol('style','text','parent' , app.Manual_Strength.ai(14)  ,'un','norm',...
                'pos',  [  .02 .7 .2 .24  ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str','Smooth ' , 'callb',' ');
              app.Manual_Strength2.t2 = uicontrol('style','text','parent' , app.Manual_Strength.ai(14)  ,'un','norm',...
                'pos',  [  .28 .7 .22 .24  ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str','Property' , 'callb',' ');
           app.Manual_Strength.ai(17) = uicontrol('style','pushbutton','parent' , app.Manual_Strength.ai(14) , 'un','norm',...
                'pos',  [  .56  .68 .2 .36 ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str', 'Add'   , 'callb',  {@set_segments,app , 1 }    );
              app.Manual_Strength.ai(18) = uicontrol('style','pushbutton','parent' , app.Manual_Strength.ai(14) , 'un','norm',...
                'pos',  [  .78  .68 .2 .36 ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str', 'Remove'   , 'callb',  {@set_segments,app , 2 }    );
            app.Manual_Strength.ai(16) = uicontrol('style','pushbutton','parent' , app.Manual_Strength.ai(14) , 'un','norm',...
                'pos',  [  .56  .08 .42 .52 ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str', 'Run model'   , 'callb',  {@smo,app , 2}    );
            app.Manual_Strength2.a2 = uicontrol('style','popupmenu','parent' , app.Manual_Strength.ai(14) , 'un','norm',...
                'pos',  [  .28  .22 .24 .34  ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str', {'<html><FONT COLOR="black" SIZE="10" FACE="ARIAL">μs<html>','μD','σ','Dc'}  , 'callb', {@smo2,app , 2}  ) ;
             app.Manual_Strength2.a6 = uicontrol('style','pushbutton','parent' , gcf , 'un','norm',...
                'pos',  [  .126 .77 .04 .04 ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str',  'Add' , 'callb', {@manual_area ,app , 2}  ) ;
            app.Manual_Strength2.aea(1) = uicontrol('style','edit','parent' , gcf , 'un','norm',...
                'pos',  [  .01 .8 .046 .04 ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str',  'X1 Y1' , 'callb',  ' ' ) ;    
           app.Manual_Strength2.aea(2) = uicontrol('style','edit','parent' , gcf , 'un','norm',...
                'pos',  [  .01 .74 .046 .04 ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str',  'X2 Y2' , 'callb',  ' ' ) ;
          app.Manual_Strength2.aea(3) = uicontrol('style','edit','parent' , gcf , 'un','norm',...
                'pos',  [  .07 .74 .046 .04 ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str',  'X3 Y3' , 'callb',  ' ' ) ;
          app.Manual_Strength2.aea(4) = uicontrol('style','edit','parent' , gcf , 'un','norm',...
                'pos',  [  .07 .8 .046 .04 ] , 'fontsize' , app.Manual_Strength.a_si(1) ,  'backg' , [  get(gcf,'color') ] ,  'str',  'X4 Y4' , 'callb',  ' ' ) ;
 
            smo2(1 , 2 ,app , 2) 

             app.Manual_Strength.eai = text(1,1,[' ']) ;
            
            aa = 1 ;


            function manual_area(  src , evt , app , td )

app.Manual_Strength.atgx = zeros(1,4) ; app.Manual_Strength.atgy = zeros(1,4) ;
for i = 1 : 4
              app.Manual_Strength.atg(i) =  find(get(app.Manual_Strength2.aea(i) ,'string')==' ') ;
              sep = [] ; sep = get(app.Manual_Strength2.aea(i) ,'string') ;
              app.Manual_Strength.atgx(i) = str2num( sep(1:app.Manual_Strength.atg(i) - 1 ) ) ; app.Manual_Strength.atgy(i) = str2num( sep( app.Manual_Strength.atg(i) : end ) ) ;
end
app.Manual_Strength.gg= 1 ; app.Manual_Strength.gg=app.Manual_Strength.gg+1;
            app.Manual_Strength3.hpatch = patch( app.Manual_Strength.atgx , app.Manual_Strength.atgy ,'r');
            set( app.Manual_Strength3.hpatch ,'FaceAlpha',.2)
            app.Manual_Strength2.main.vardraw=1;
            app.Manual_Strength3.datetime = datestr(clock);       
         app.Manual_Strength.deletehist = 2 ;
            name = inputdlg('Set Strength Value  ', 'Details', [1 30]);
            namedata = (name{:});
            if isempty(  namedata  )
            warndlg('No data provided'  ,  'ATTENTION'  )  , return
            else  
            app.Manual_Strength3.infodetails = str2num(  namedata  )  ;
       [app.Manual_Strength.in,app.Manual_Strength.on] = inpolygon( app.Manual_Strength.X{ get(app.Manual_Strength2.a1,'value') }  ,  app.Manual_Strength.Y{  get(app.Manual_Strength2.a1,'value')  }   ,  app.Manual_Strength.atgx ,  app.Manual_Strength.atgy ) ;
       app.Manual_Strength.z{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')}( app.Manual_Strength.in == 1 ) =  app.Manual_Strength3.infodetails  ;
       app.Manual_Strength.filteredZ{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')} = app.Manual_Strength.z{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')}  ;
      app.Manual_Strength.hma = app.Manual_Strength.hma + 1 ; app.Manual_Strength.haa = app.Manual_Strength.haa + 1 ;
       app.Manual_Strength.sele(app.Manual_Strength.hma) = 2 ;
       smo2(1 , 2 ,app , 2) 
            end
   app.Manual_Strength.inpo{ get(app.Manual_Strength2.a2,'value') , get(app.Manual_Strength2.a1,'value'), app.Manual_Strength.hma }  =  app.Manual_Strength.in ;
           app.Manual_Strength.gg = app.Manual_Strength.gg + 1;
           smo2(1 , 2 ,app , 2) 

        end


  function manual_area_asc(  src , evt , app , td )

      try
[app.Strength_Field.es_a1, app.Strength_Field.es_a2, app.Strength_Field.es_a3 ] = uigetfile('*.*') ; 
cd(app.Strength_Field.es_a2) ;
[ app.Strength_Field.fid , app.Strength_Field.msg ] = fopen( app.Strength_Field.es_a1 , 'rt' ) ;
assert( app.Strength_Field.fid>=3, app.Strength_Field.msg) 
i = 1 ;
while ~feof( app.Strength_Field.fid)
    app.Strength_Field.hdr{i} = fgetl( app.Strength_Field.fid);
i = i + 1 ;
end
fclose(  app.Strength_Field.fid);
ii = 1 ;
for i = 12 : length(app.Strength_Field.hdr )
    try
if strcmp(  app.Strength_Field.hdr{i}(1:3),'---'  )
    app.Strength_Field.lines_add(ii) = i ;
    ii = ii + 1 ;
end
    end
end
ia = 1 ;
for i = 1 : length(  app.Strength_Field.lines_add  )-1
    app.Manual_Strength.atgx =  [ ]  ; app.Manual_Strength.atgy = [  ]  ;
 for ii = app.Strength_Field.lines_add(i)+2 :  app.Strength_Field.lines_add(i+1)-1
   app.Manual_Strength.aaea = [] ;
     app.Manual_Strength.aaea =  split(  app.Strength_Field.hdr{  ii  }  ) ;
             if length( app.Manual_Strength.aaea ) > 2
             app.Manual_Strength.atgx(ia) = str2num( app.Manual_Strength.aaea{2} ) ; app.Manual_Strength.atgy(ia) = str2num( app.Manual_Strength.aaea{3} ) ;
             else
              app.Manual_Strength.atgx(ia) = str2num( app.Manual_Strength.aaea{1} ) ; app.Manual_Strength.atgy(ia) = str2num( app.Manual_Strength.aaea{2} ) ;
             end
              ia = ia + 1 ;
 end
 app.Manual_Strength.aaea = [] ;  
 app.Manual_Strength.aaea =  split(   app.Strength_Field.hdr{   app.Strength_Field.lines_add(i)+1  }  ) ;
 if length( app.Manual_Strength.aaea ) > 3
     app.Manual_Strength.field1 =  str2num( app.Manual_Strength.aaea{2}  ) ;
      app.Manual_Strength.field2 =  str2num( app.Manual_Strength.aaea{3}  ) ;
       app.Manual_Strength.field4 =  str2num( app.Manual_Strength.aaea{4}  ) ;
 else
     app.Manual_Strength.field1 =  str2num( app.Manual_Strength.aaea{1}  ) ;
      app.Manual_Strength.field2 =  str2num( app.Manual_Strength.aaea{2}  ) ;
       app.Manual_Strength.field4 =  str2num( app.Manual_Strength.aaea{3}  ) ;
 end
       set( app.Manual_Strength2.a2,'value' ,   app.Manual_Strength.field2 ) ;
set( app.Manual_Strength2.a1,'value' ,   app.Manual_Strength.field1 ) ;
app.Manual_Strength.gg= 1 ; app.Manual_Strength.gg=app.Manual_Strength.gg+1;
            app.Manual_Strength3.hpatch = patch( app.Manual_Strength.atgx , app.Manual_Strength.atgy ,'r');
            set( app.Manual_Strength3.hpatch ,'FaceAlpha',.2)
            app.Manual_Strength2.main.vardraw=1;
            app.Manual_Strength3.datetime = datestr(clock);       
         app.Manual_Strength.deletehist = 2 ;
            app.Manual_Strength3.infodetails =  app.Manual_Strength.field4    ;
       [app.Manual_Strength.in,app.Manual_Strength.on] = inpolygon( app.Manual_Strength.X{ get(app.Manual_Strength2.a1,'value') }  ,  app.Manual_Strength.Y{  get(app.Manual_Strength2.a1,'value')  }   ,  app.Manual_Strength.atgx ,  app.Manual_Strength.atgy ) ;
       app.Manual_Strength.z{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')}( app.Manual_Strength.in == 1 ) =  app.Manual_Strength3.infodetails  ;
       app.Manual_Strength.filteredZ{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')} = app.Manual_Strength.z{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')}  ;
      app.Manual_Strength.hma = app.Manual_Strength.hma + 1 ; app.Manual_Strength.haa = app.Manual_Strength.haa + 1 ;
       app.Manual_Strength.sele(app.Manual_Strength.hma) = 2 ;
       smo2(1 , 2 ,app , 2)     
   app.Manual_Strength.inpo{ get(app.Manual_Strength2.a2,'value') , get(app.Manual_Strength2.a1,'value'), app.Manual_Strength.hma }  =  app.Manual_Strength.in ;
           app.Manual_Strength.gg = app.Manual_Strength.gg + 1;
           smo2(1 , 2 ,app , 2) 
app.Manual_Strength.atgx = [] ; app.Manual_Strength.atgy = [] ;
ia =  1 ;
app.Manual_Strength.atgx = [] ; app.Manual_Strength.atgy = [] ;
set( app.Manual_Strength.eai , 'visible' , 'off'  )
end

      catch
 warndlg('Please try again','ATTENTION')
 app.Strength_Field = [] ;
            app.Strength_Field.es_a1 = [] ;
      end

        end


                 function drawpolygon(  src , evt , app , td )
                  
            app.Manual_Strength3 = [] ;

            if td == 2 ,   app.Manual_Strength2.main.cfpos=[];   else  ,  app.Manual_Strength2.main.cfpos=.1;    end
             set(gcf,'Pointer','crosshair') 
             app.Manual_Strength.deletehist = 1;
            app.Manual_Strength.closepolygon = [];
            
             set(gcf,'WindowKeyPressFcn', {@wbd_1_p_fcn , app   } ) 
            if isempty(app.Manual_Strength2.main.vardraw)
            else 
               app.Manual_Strength.gg = 1;
               clear app.Manual_Strength3
               app.Manual_Strength3 = [] ;
            end
            app.Manual_Strength.gg = 1;
            set(gcf,'WindowButtonMotionFcn',...
            {@wbm_1_p_fcn, app   } )
            set(gcf,'WindowButtonDownFcn', {@wbd_1_p_fcn , app  } )
     end


   function draw_all(  src , evt , app , td ) 

 name = inputdlg('Set Strength Value  ', 'Details', [1 30]);
            namedata = (name{:});
            if isempty(  namedata  )
            warndlg('No data provided'  ,  'ATTENTION'  )  , return
            else     
            app.Manual_Strength3.infodetails = str2num(  namedata  )  ;
      app.Manual_Strength.deletehist = 2 ;
      app.Manual_Strength.z{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')}( :,: ) =  app.Manual_Strength3.infodetails  ;
    app.Manual_Strength.filteredZ{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')} = app.Manual_Strength.z{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')}  ;
      app.Manual_Strength.filteredZ{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')} = app.Manual_Strength.z{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')}  ;
    
    app.Manual_Strength.hma = app.Manual_Strength.hma + 1 ;  app.Manual_Strength.haa = app.Manual_Strength.haa + 1 ;
      app.Manual_Strength.sele(app.Manual_Strength.hma) = 2 ;
      smo2(1 , 2 ,app , 4  ) 
            end
         app.Manual_Strength.inpo{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value'), app.Manual_Strength.hma }  =  logical( ones( size(app.Manual_Strength.X{get(app.Manual_Strength2.a1,'value')}) ) )  ;
          
app.Manual_Strength.select_sta{  get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')  } = ' Plane' ;
   end


 function wbm_1_p_fcn(  a , s , app  )

    set( app.Manual_Strength.eai , 'visible' , 'on'  )
            if ~isempty(app.Manual_Strength2.main.cfpos)
            coord = get(gca,'CurrentPoint');
            if app.Manual_Strength.gg~=1
                set(app.Manual_Strength3.line(app.Manual_Strength.gg-1),'xdata',[app.Manual_Strength3.x(app.Manual_Strength.gg-1) coord(1,1)],'ydata',[app.Manual_Strength3.y(app.Manual_Strength.gg-1) coord(1,2)],'color',[1 0 0])
              if app.Manual_Strength.gg>2
                if coord(1,1) >= app.Manual_Strength3.x(1) - .02 & coord(1,1) <= app.Manual_Strength3.x(1) + .02 & coord(1,2) >= app.Manual_Strength3.y(1) - .02 & coord(1,2) <= app.Manual_Strength3.y(1) + .02
                    set(gcf,'color',[1 0 0])
                    app.Manual_Strength.closepolygon=1;
                else
                    app.Manual_Strength.closepolygon=[];
                end
              end
            end
            else  
            coord = get(gca,'CurrentPoint');
            if app.Manual_Strength.gg~=1
                
                if app.Manual_Strength.gg==2
                        set(app.Manual_Strength3.line(app.Manual_Strength.gg-1),'xdata',[app.Manual_Strength3.x(app.Manual_Strength.gg-1) app.Manual_Strength3.x(app.Manual_Strength.gg-1)],'ydata',[app.Manual_Strength3.y(app.Manual_Strength.gg-1) coord(1,2)],'color',[1 0 0])
                elseif app.Manual_Strength.gg==3
                        set(app.Manual_Strength3.line(app.Manual_Strength.gg-1),'xdata',[app.Manual_Strength3.x(app.Manual_Strength.gg-1) coord(1,1)],'ydata',[app.Manual_Strength3.y(app.Manual_Strength.gg-1) app.Manual_Strength3.y(app.Manual_Strength.gg-1)],'color',[1 0 0])
                elseif app.Manual_Strength.gg==4
                        set(app.Manual_Strength3.line(app.Manual_Strength.gg-1),'xdata',[app.Manual_Strength3.x(app.Manual_Strength.gg-1) app.Manual_Strength3.x(app.Manual_Strength.gg-1)],'ydata',[app.Manual_Strength3.y(app.Manual_Strength.gg-1) coord(1,2)],'color',[1 0 0])
                else
                    app.Manual_Strength.closepolygon=1;
                end
            end  
               if app.Manual_Strength.gg==4
                   app.Manual_Strength.closepolygon=1;
               end
            end
    if coord(1,1)+ abs( ( app.Manual_Strength.Y{    1    ,get(app.Manual_Strength2.a1,'value')}( end ,1) ) - ( app.Manual_Strength.Y{    1    ,get(app.Manual_Strength2.a1,'value')}( 1 ,1) ) )/app.Manual_Strength.a_si(2) > app.Manual_Strength.X{    1    ,get(app.Manual_Strength2.a1,'value')}( 1 , end )
     set( app.Manual_Strength.eai , 'position' , [  coord(1,1)- abs( ( app.Manual_Strength.Y{    1    ,get(app.Manual_Strength2.a1,'value')}( end ,1) ) - ( app.Manual_Strength.Y{    1    ,get(app.Manual_Strength2.a1,'value')}( 1 ,1) ) )/app.Manual_Strength.a_si(4) coord(1,2) ] , 'string' , [ 'X: ' num2str(round( coord(1,1) , 2 ) ) , 10 , 'Y: ' num2str(round( coord(1,2) , 2 ) )  ] ,  'EdgeColor',  [  1 1 1 ]  ,  'Color',  [  1 1 1 ]  ,  'LineWidth',2,'FontSize', app.Manual_Strength.a_si(1)  )     
     else
            set( app.Manual_Strength.eai , 'position' , [  coord(1,1)+ abs( ( app.Manual_Strength.Y{    1    ,get(app.Manual_Strength2.a1,'value')}( end ,1) ) - ( app.Manual_Strength.Y{      1    ,get(app.Manual_Strength2.a1,'value')  }( 1 ,1) ) )/app.Manual_Strength.a_si(3) coord(1,2) ] , 'string' , [ 'X: ' num2str(round( coord(1,1) , 2 ) ) , 10 , 'Y: ' num2str(round( coord(1,2) , 2 ) )  ] ,  'EdgeColor',  [  1 1 1 ]  ,  'Color',  [  1 1 1 ]  ,  'LineWidth',2,'FontSize', app.Manual_Strength.a_si(1)  )    
    end
 end


 function wbd_1_p_fcn( a , b , app   )

        wdia =    get(gcf,'SelectionType') ;
        if ~strcmp(wdia,'normal') , app.Manual_Strength.closepolygon=.1; end
    coord = get(gca,'CurrentPoint');
    app.Manual_Strength3.x(app.Manual_Strength.gg) = coord(1,1);
    app.Manual_Strength3.y(app.Manual_Strength.gg) = coord(1,2); 
    if app.Manual_Strength.gg==1
        app.Manual_Strength3.line(1) = line([coord(1,1) coord(1,1)],[coord(1,2) coord(1,2)]);
    else
        hold on
        if ~isempty(app.Manual_Strength.closepolygon)
            if ~isempty(app.Manual_Strength2.main.cfpos)
            app.Manual_Strength3.x(app.Manual_Strength.gg) = app.Manual_Strength3.x(1);
            app.Manual_Strength3.y(app.Manual_Strength.gg) = app.Manual_Strength3.y(1); 
            app.Manual_Strength3.line(app.Manual_Strength.gg) = line([app.Manual_Strength3.x(app.Manual_Strength.gg-1) app.Manual_Strength3.x(1)],[app.Manual_Strength3.y(app.Manual_Strength.gg-1) app.Manual_Strength3.y(1)]);
            set(gcf,'WindowButtonMotionFcn','')
            set(gcf,'WindowButtonDownFcn','')
            set(gcf,'WindowKeyPressFcn','')
            delete(app.Manual_Strength3.line(end-1))
            for i = 1 : length(app.Manual_Strength3.line)
              if i~=length(app.Manual_Strength3.line)-1
            delete(app.Manual_Strength3.line(i))
              end
            end
            app.Manual_Strength3.hpatch = patch(app.Manual_Strength3.x,app.Manual_Strength3.y,'r');
            set(app.Manual_Strength3.hpatch,'FaceAlpha',.2)
            app.Manual_Strength2.main.vardraw=1;
            app.Manual_Strength3.datetime = datestr(clock); 
            name = inputdlg('Set Strength Value  ', 'Details', [1 30]);
            namedata = (name{:});
            if isempty(  namedata  )
            warndlg('No data provided'  ,  'ATTENTION'  )  , return
            else     
            app.Manual_Strength3.infodetails = str2num(  namedata  )  ;
      app.Manual_Strength.deletehist = 2 ;
      [app.Manual_Strength.in,app.Manual_Strength.on] = inpolygon( app.Manual_Strength.X{ get(app.Manual_Strength2.a1,'value') }  ,  app.Manual_Strength.Y{  get(app.Manual_Strength2.a1,'value')  }  , app.Manual_Strength3.x, app.Manual_Strength3.y  ) ;
      % [app.Manual_Strength.in,app.Manual_Strength.on] = inpolygon( app.Manual_Strength.X{ get(app.Manual_Strength2.a1,'value') }  ,  app.Manual_Strength.Y{  get(app.Manual_Strength2.a1,'value')  }  , app.Manual_Strength3.x- (app.Manual_Strength.X{1,1}(1,2)-app.Manual_Strength.X{1,1}(1,1) ),app.Manual_Strength3.y - ( ( app.Manual_Strength.Y{1,1}( 2 ,1) ) - ( app.Manual_Strength.Y{1,1}( 1 ,1) ) ) ) ;
      app.Manual_Strength.z{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')}( app.Manual_Strength.in == 1 ) =  app.Manual_Strength3.infodetails  ;
      app.Manual_Strength.X_pol = app.Manual_Strength.X{ get(app.Manual_Strength2.a1,'value') }(app.Manual_Strength.in==1);
      app.Manual_Strength.Y_pol = app.Manual_Strength.Y{ get(app.Manual_Strength2.a1,'value') }(app.Manual_Strength.in==1);
      app.Manual_Strength.filteredZ{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')} = app.Manual_Strength.z{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')}  ;
     app.Manual_Strength.hma = app.Manual_Strength.hma + 1 ;  app.Manual_Strength.haa = app.Manual_Strength.haa + 1 ;
      app.Manual_Strength.sele(app.Manual_Strength.hma) = 2 ;
      smo2(1 , 2 ,app , 2) 
      set( app.Manual_Strength.eai , 'visible' , 'off'  )
            end
           s_polygon(app.Manual_Strength.hma).x =  app.Manual_Strength3.x ;
          s_polygon(app.Manual_Strength.hma).y =  app.Manual_Strength3.y ;
           app.Manual_Strength.inpo{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value'), app.Manual_Strength.hma }  =  app.Manual_Strength.in ;
            else
                hold on
                app.Manual_Strength3.line(app.Manual_Strength.gg) = line([app.Manual_Strength3.x(app.Manual_Strength.gg-1) app.Manual_Strength3.x(app.Manual_Strength.gg-1)],[app.Manual_Strength3.y(1) app.Manual_Strength3.y(1)]);
                       set(app.Manual_Strength3.line(app.Manual_Strength.gg),'color',[1 0 0]) 
                       app.Manual_Strength3.x(app.Manual_Strength.gg) = app.Manual_Strength3.x(app.Manual_Strength.gg-1);
                       app.Manual_Strength3.y(app.Manual_Strength.gg) = app.Manual_Strength3.y(1);
                hold on
                app.Manual_Strength.gg=app.Manual_Strength.gg+1;
                app.Manual_Strength3.x(app.Manual_Strength.gg) = app.Manual_Strength3.x(1);
            app.Manual_Strength3.y(app.Manual_Strength.gg) = app.Manual_Strength3.y(1); 
            app.Manual_Strength3.line(app.Manual_Strength.gg) = line([app.Manual_Strength3.x(app.Manual_Strength.gg-1) app.Manual_Strength3.x(1)],[app.Manual_Strength3.y(app.Manual_Strength.gg-1) app.Manual_Strength3.y(1)]);
            set(gcf,'WindowButtonMotionFcn','')
            set(gcf,'WindowButtonDownFcn','')
            set(gcf,'WindowKeyPressFcn','')
            delete(app.Manual_Strength3.line(end-1))
            for i = 1 : length(app.Manual_Strength3.line)
              if i~=length(app.Manual_Strength3.line)-1
            delete(app.Manual_Strength3.line(i))
              end
            end
            app.Manual_Strength3.hpatch = patch(app.Manual_Strength3.x,app.Manual_Strength3.y,'r');
            set( app.Manual_Strength3.hpatch ,'FaceAlpha',.2)
            app.Manual_Strength2.main.vardraw=1;
            app.Manual_Strength3.datetime = datestr(clock);       
         app.Manual_Strength.deletehist = 2 ;
            name = inputdlg('Set Strength Value  ', 'Details', [1 30]);
            namedata = (name{:});
            if isempty(  namedata  )
            warndlg('No data provided'  ,  'ATTENTION'  )  , return
            else  
        
            app.Manual_Strength3.infodetails = str2num(  namedata  )  ;
       [app.Manual_Strength.in,app.Manual_Strength.on] = inpolygon( app.Manual_Strength.X{ get(app.Manual_Strength2.a1,'value') }  ,  app.Manual_Strength.Y{  get(app.Manual_Strength2.a1,'value')  }   , app.Manual_Strength3.x,app.Manual_Strength3.y ) ;
       app.Manual_Strength.z{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')}( app.Manual_Strength.in == 1 ) =  app.Manual_Strength3.infodetails  ;
       app.Manual_Strength.filteredZ{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')} = app.Manual_Strength.z{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')}  ;
      app.Manual_Strength.hma = app.Manual_Strength.hma + 1 ; app.Manual_Strength.haa = app.Manual_Strength.haa + 1 ;
       app.Manual_Strength.sele(app.Manual_Strength.hma) = 2 ;
       smo2(1 , 2 ,app , 2) 
       set( app.Manual_Strength.eai , 'visible' , 'off'  )
            end
          s_polygon(app.Manual_Strength.hma).x =  app.Manual_Strength3.x ;
          s_polygon(app.Manual_Strength.hma).y =  app.Manual_Strength3.y ;
           app.Manual_Strength.inpo{ get(app.Manual_Strength2.a2,'value') , get(app.Manual_Strength2.a1,'value'), app.Manual_Strength.hma }  =  app.Manual_Strength.in ;
            end
        else 
            if ~isempty(app.Manual_Strength2.main.cfpos)
            app.Manual_Strength3.line(app.Manual_Strength.gg) = line([app.Manual_Strength3.x(app.Manual_Strength.gg-1) app.Manual_Strength3.x(app.Manual_Strength.gg)],[app.Manual_Strength3.y(app.Manual_Strength.gg-1) app.Manual_Strength3.y(app.Manual_Strength.gg)]);
            set(app.Manual_Strength3.line(app.Manual_Strength.gg),'color',[1 0 0])
            else   
                if app.Manual_Strength.gg ==2
                       app.Manual_Strength3.line(app.Manual_Strength.gg) = line([app.Manual_Strength3.x(app.Manual_Strength.gg-1) app.Manual_Strength3.x(app.Manual_Strength.gg-1)],[app.Manual_Strength3.y(app.Manual_Strength.gg-1) app.Manual_Strength3.y(app.Manual_Strength.gg)]);
                       set(app.Manual_Strength3.line(app.Manual_Strength.gg),'color',[1 0 0]) 
                       app.Manual_Strength3.x(app.Manual_Strength.gg) = app.Manual_Strength3.x(app.Manual_Strength.gg-1);
                       app.Manual_Strength3.y(app.Manual_Strength.gg) = coord(1,2);
                elseif app.Manual_Strength.gg==3
                       app.Manual_Strength3.line(app.Manual_Strength.gg) = line([app.Manual_Strength3.x(app.Manual_Strength.gg-1) app.Manual_Strength3.x(app.Manual_Strength.gg)],[app.Manual_Strength3.y(app.Manual_Strength.gg-1) app.Manual_Strength3.y(app.Manual_Strength.gg-1)]);
                       set(app.Manual_Strength3.line(app.Manual_Strength.gg),'color',[1 0 0])  
                       app.Manual_Strength3.x(app.Manual_Strength.gg) = coord(1,1);
                       app.Manual_Strength3.y(app.Manual_Strength.gg) = app.Manual_Strength3.y(app.Manual_Strength.gg-1);
                else
                        app.Manual_Strength3.line(app.Manual_Strength.gg) = line([app.Manual_Strength3.x(app.Manual_Strength.gg-1) app.Manual_Strength3.x(app.Manual_Strength.gg-1)],[app.Manual_Strength3.y(g1) app.Manual_Strength3.y(1)]);
                       set(app.Manual_Strength3.line(app.Manual_Strength.gg),'color',[1 0 0]) 
                       app.Manual_Strength3.x(app.Manual_Strength.gg) = app.Manual_Strength3.x(app.Manual_Strength.gg-1);
                       app.Manual_Strength3.y(app.Manual_Strength.gg) = app.Manual_Strength3.y(1);
                       app.Manual_Strength.closepolygon = .1;
                app.Manual_Strength3.x(app.Manual_Strength.gg) = app.Manual_Strength3.x(1);
            app.Manual_Strength3.y(app.Manual_Strength.gg) = app.Manual_Strength3.y(1); 
            app.Manual_Strength3.line(app.Manual_Strength.gg) = line([app.Manual_Strength3.x(app.Manual_Strength.gg-1) app.Manual_Strength3.x(1)],[app.Manual_Strength3.y(app.Manual_Strength.gg-1) app.Manual_Strength3.y(1)]);
            set(gcf,'WindowButtonMotionFcn','')
            set(gcf,'WindowButtonDownFcn','')
            set(gcf,'WindowKeyPressFcn','')
            delete(app.Manual_Strength3.line(end-1))
            for i = 1 : length(app.Manual_Strength3.line)
              if i~=length(app.Manual_Strength3.line)-1
            delete(app.Manual_Strength3.line(i))
              end
            end
            app.Manual_Strength3.hpatch = patch(app.Manual_Strength3.x,app.Manual_Strength3.y,'r');
            set(app.Manual_Strength3.hpatch,'FaceAlpha',.2)
            app.Manual_Strength2.main.vardraw=1;
            app.Manual_Strength3.datetime = datestr(clock); 
            datatostore = length(history.data);
            name = inputdlg('Set Strength Value  ', 'Details', [1 30]);
                   namedata = (name{:});
            if isempty(  namedata  )
            warndlg('No data provided'  ,  'ATTENTION'  )  , return
            else  
    
            app.Manual_Strength3.infodetails = str2num(  namedata  )  ;esa
       [app.Manual_Strength.in,app.Manual_Strength.on] = inpolygon( app.Manual_Strength.X{ get(app.Manual_Strength2.a1,'value') }  ,  app.Manual_Strength.Y{  get(app.Manual_Strength2.a1,'value')  }   , app.Manual_Strength3.x,app.Manual_Strength3.y ) ;
       app.Manual_Strength.z{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')}( app.Manual_Strength.in == 1 ) =  app.Manual_Strength3.infodetails  ;
       app.Manual_Strength.filteredZ{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')} = app.Manual_Strength.z{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')}  ;
         app.Manual_Strength.filteredZ{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')} = app.Manual_Strength.z{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')}  ;
    
       app.Manual_Strength.sele(app.Manual_Strength.hma) = 2 ;
       smo2(1 , 2 ,app , 2) 
       app.Manual_Strength.inpo{ get(app.Manual_Strength2.a2,'value') , get(app.Manual_Strength2.a1,'value'), app.Manual_Strength.hma }  =  app.Manual_Strength.in ;
    
            end
                end
              
            end
        end
    end
    app.Manual_Strength.gg = app.Manual_Strength.gg + 1;

        end


            function set_segments(a , b , app , td )
           
app.Manual_Strength.ae =  get(app.Manual_Strength2.a1, 'value')  ;
app.Manual_Strength.ae2 =  get(app.Manual_Strength2.a2, 'value')  ;
if td ==1  
    app.Manual_Strength.select_se(app.Manual_Strength.ae , app.Manual_Strength.ae2 ) = 1 ;
    app.Manual_Strength.select_st{app.Manual_Strength.ae , app.Manual_Strength.ae2 } = 'green' ;
else
app.Manual_Strength.select_se(app.Manual_Strength.ae , app.Manual_Strength.ae2 ) = 0 ;
app.Manual_Strength.select_st{app.Manual_Strength.ae , app.Manual_Strength.ae2 } = 'red' ;
end
refre_displa_fields(a , b , app , 2 )
    
            end


 function refre_displa_fields(a , b , app , td )

app.Manual_Strength.ae =  get(app.Manual_Strength2.a1, 'value')  ;
app.Manual_Strength.ae2 =  get(app.Manual_Strength2.a2, 'value')  ;
   set( app.Manual_Strength2.a2  ,  'str', {['<html><FONT color="white" bgcolor="'  app.Manual_Strength.select_st{app.Manual_Strength.ae , 1 }  '" SIZE="10" FACE="ARIAL">μs<html>'],['<html><FONT color="white" bgcolor="'  app.Manual_Strength.select_st{app.Manual_Strength.ae , 2 }  '" SIZE="10" FACE="ARIAL">μD<html>'],['<html><FONT color="white" bgcolor="'  app.Manual_Strength.select_st{app.Manual_Strength.ae , 3 }  '" SIZE="10" FACE="ARIAL">σ<html>'],['<html><FONT color="white" bgcolor="'  app.Manual_Strength.select_st{app.Manual_Strength.ae , 4 }  '" SIZE="10" FACE="ARIAL">Dc<html>']}  ) ;  

 end


            function smo(a , b , app , td )
 
           GenerateStrength_ButtonPushed(app, event)
           
        CombinewGridButtonPushed(app, event)        
PlotStrength_ButtonPushed(app, event)  


            end


              function smo2(a , b , app , td )
 
           hold off
           

%            fault = find( app.GriddedFault.TriangleList(:,5) == get(app.Manual_Strength2.a1,'value')} ) ;
%            surf(  AllV1s(fault)',AllV2s(fault)',AllV3s(fault)',PlottedComp(fault,1)')  
% 
%            hold on

% manual_RoughFric       = interp2( app.Manual_Strength.Xa , app.Manual_Strength.Ya , app.GriddedFault.FricVals(:,get(app.Manual_Strength2.a2,'value')) ,app.Manual_Strength.xn{get(app.Manual_Strength2.a1,'value')},app.Manual_Strength.yn{get(app.Manual_Strength2.a1,'value')} );
%   surf( app.Manual_Strength.X{ get(app.Manual_Strength2.a1,'value') }  ,  app.Manual_Strength.Y{  get(app.Manual_Strength2.a1,'value')  }  , manual_RoughFric  )   ;
% 
%   hold on

       app.Manual_Strength.smoa = get(app.Manual_Strength2.ed1,'string');
        app.Manual_Strength.inae = str2num(app.Manual_Strength.smoa)  ; 
    H = ones( app.Manual_Strength.inae )./( app.Manual_Strength.inae ^ 2 ) ; 
    app.Manual_Strength.filteredZ{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')}  = filter2(H,app.Manual_Strength.z{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')} );
    app.Manual_Strength.filteredZ{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')}  = app.Manual_Strength.filteredZ{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')}  ; 
    app.Manual_Strength.a2 = surf( app.Manual_Strength.X{ get(app.Manual_Strength2.a1,'value') }  ,  app.Manual_Strength.Y{  get(app.Manual_Strength2.a1,'value')  }  ,app.Manual_Strength.filteredZ{get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')}  )   ;
     shading interp 
    view( 0 , -90 ) 
   xlabel(' Along Strike (km)','FontSize',app.Manual_Strength.a_si(1),'FontWeight','bold'); 
    ylabel(' Along Dip (km)' ,'FontSize',app.Manual_Strength.a_si(1),'FontWeight','bold'); 
    zlabel('Strength  (A.U.)' ,'FontSize',app.Manual_Strength.a_si(1),'FontWeight','bold');
    ca = colorbar  ;
    ca.Label.String = 'Strength Field '  ;
    ca.FontSize = app.Manual_Strength.a_si(1) ;
    ca.FontWeight = 'bold' ;
    a22.YAxis.FontWeight = 'bold';
    a22.XAxis.FontWeight = 'bold'; 
    a22.ZAxis.FontWeight = 'bold';
    ga.FontSize = app.Manual_Strength.a_si(1) ; 
    ga.FontWeight = 'bold'  ;
  set(gca,'ydir','normal' , 'color' , [ .6 .3 0 ] )
  app.Manual_Strength2.main.h.title = title('Structure of Strength Field - Friction coefficient \mu_{S} and/or \mu_{D} - Stress \sigma in MPa - D_{C} in meters. ') ;
  axis equal
 app.Manual_Strength.aa_si = size(app.Manual_Strength.X{get(app.Manual_Strength2.a1,'value')})  ;
 set( app.Manual_Strength.ai(8),'str',['Current Fault plane ID size: ', num2str(  app.Manual_Strength.aa_si(1)  ) ' X ' num2str(  app.Manual_Strength.aa_si(2)  ) ])
 set( app.Manual_Strength.ai(9),'str',['Number of elements to be fitted: ', num2str(app.GriddedFault.SizeTrigList) ])
 set( app.Manual_Strength.ai(12),'str',['Number of assigned sub-areas: ', num2str(app.Manual_Strength.haa-1) ])
 
  % axis([  app.Manual_Strength.MinStkLngthVal(get(app.Manual_Strength2.a1,'value'))  app.Manual_Strength.MaxStkLngthVal(get(app.Manual_Strength2.a1,'value'))  app.Manual_Strength.MinDipLngthVal(get(app.Manual_Strength2.a1,'value'))  app.Manual_Strength.MaxDipLngthVal(get(app.Manual_Strength2.a1,'value'))  ]  )
 

  axis([  app.Manual_Strength.MinStkLngthVal(get(app.Manual_Strength2.a1,'value'))- abs(app.Manual_Strength.X{1,1}(1,end)-app.Manual_Strength.X{1,1}(1,1) )/40 app.Manual_Strength.MaxStkLngthVal(get(app.Manual_Strength2.a1,'value'))+ abs(app.Manual_Strength.X{1,1}(1,end)-app.Manual_Strength.X{1,1}(1,1) )/40  app.Manual_Strength.MinDipLngthVal(get(app.Manual_Strength2.a1,'value'))- abs( ( app.Manual_Strength.Y{1,1}( end ,1) ) - ( app.Manual_Strength.Y{1,1}( 1 ,1) ) )/20  app.Manual_Strength.MaxDipLngthVal(get(app.Manual_Strength2.a1,'value'))+ abs( ( app.Manual_Strength.Y{1,1}( end ,1) ) - ( app.Manual_Strength.Y{1,1}( 1 ,1) ) )/20  ]  )
 
 
  % pos = axis ; axis( [  pos(1)-((pos(2)-pos(1))/20) pos(2)+((pos(2)-pos(1))/20) pos(3)-((pos(4)-pos(3))/20) pos(4)+((pos(4)-pos(3))/20)  ] )
   app.Manual_Strength.eai = text(1,1,[' ']) ;
  
  set( app.Manual_Strength.eai , 'visible' , 'on'  )
  
 refre_displa_fields(a , b , app , 2 )
          
              end

        

            function  clear_a(a , b , app , td )


              %  app.Manual_Strength.filteredZ{ get(app.Manual_Strength2.a2,'value'),get(app.Manual_Strength2.a1,'value')}( app.Manual_Strength.inpo{ get(gco,'userdata') } )   = 0 ;


                if td == 2 
smo2(1 , 2 ,app , 2)   
for i = 2 : length( app.Manual_Strength.inpo( get(app.Manual_Strength2.a2,'value'), get(app.Manual_Strength2.a1,'value'),:) ) 
    if islogical(  app.Manual_Strength.inpo{ get(app.Manual_Strength2.a2,'value'), get(app.Manual_Strength2.a1,'value'), i }  )
    if   app.Manual_Strength.sele(i) ~= 1
app.Manual_Strength.filteredZ{ get(app.Manual_Strength2.a2,'value'), get(app.Manual_Strength2.a1,'value')}( app.Manual_Strength.inpo{ get(app.Manual_Strength2.a2,'value') , get(app.Manual_Strength2.a1,'value'), i } == 1 ) = 0 ;
app.Manual_Strength.z{ get(app.Manual_Strength2.a2,'value'), get(app.Manual_Strength2.a1,'value')}( app.Manual_Strength.inpo{ get(app.Manual_Strength2.a2,'value') , get(app.Manual_Strength2.a1,'value'), i } == 1 ) = 0 ;
   app.Manual_Strength.haa = app.Manual_Strength.haa - 1 ;
   app.Manual_Strength.sele(i) = 1 ;
    end
    end
end
smo2(1 , 2 ,app , 2) 
                else
[loca_x , loca_y ] = ginput(1) ;
for i = 2 : length( app.Manual_Strength.inpo( get(app.Manual_Strength2.a2,'value'), get(app.Manual_Strength2.a1,'value'),:) ) 
    if ~isempty(  app.Manual_Strength.inpo{ get(app.Manual_Strength2.a2,'value'), get(app.Manual_Strength2.a1,'value'), i }  )
app.Manual_Strength.poa = find(app.Manual_Strength.xn{get(app.Manual_Strength2.a1,'value')}>=round(loca_x)  );
app.Manual_Strength.poaa = find(app.Manual_Strength.yn{get(app.Manual_Strength2.a1,'value')}>=round(loca_y)  );
    if app.Manual_Strength.inpo{ get(app.Manual_Strength2.a2,'value'), get( app.Manual_Strength2.a1,'value'), i }(    app.Manual_Strength.poaa(1) , app.Manual_Strength.poa(1)  )  ==  1 &&  app.Manual_Strength.sele(i) ~= 1
app.Manual_Strength.filteredZ{ get(app.Manual_Strength2.a2,'value'), get(app.Manual_Strength2.a1,'value')}( app.Manual_Strength.inpo{ get(app.Manual_Strength2.a2,'value') , get(app.Manual_Strength2.a1,'value'), i } == 1 ) = 0 ;
app.Manual_Strength.z{ get(app.Manual_Strength2.a2,'value'), get(app.Manual_Strength2.a1,'value')}( app.Manual_Strength.inpo{ get(app.Manual_Strength2.a2,'value') , get(app.Manual_Strength2.a1,'value'), i } == 1 ) = 0 ;
   app.Manual_Strength.haa = app.Manual_Strength.haa - 1 ;
   app.Manual_Strength.sele(i) = 1 ;
    end
    end
end
smo2(1 , 2 ,app , 2) 
                end

            end


        end

        % Callback function
        function CombinewGridButtonPushed(app, event)
           

            FltIDUniq   = unique(app.FaultValues(:,20));
            FaultIDnum  = size(FltIDUniq,1);
            %------------------------------------
            for ii = 1 : FaultIDnum
                for ia = 1 : 4
                filteredZ = app.Manual_Strength.filteredZ{ia,ii}  ;
                app.Strength_Field(ii).FltSurface = [] ; app.Strength_Field(ii).FltSurface = flipud(  filteredZ  ) ;
                SelSections     = find(app.StrgthParasPerSectn(:,17) == FltIDUniq(ii,1));
                SelStrgthValues = app.StrgthParasPerSectn(SelSections(1,1),:);
                if (1 == 1) %use fractal strength distribution
                    acf              = 'AK';
                    UseNewSeed       = SelStrgthValues(1,12);
                    CorrInStk        = SelStrgthValues(1,13);
                    CorrInDip        = SelStrgthValues(1,14);
                    HurstExp         = SelStrgthValues(1,15);
                    WaterLevel       = SelStrgthValues(1,16);
                    
                    SelectedPatchPos = find(app.GriddedFault.TriangleList(:,5) == FltIDUniq(ii,1));
                    SelectedVertIndx = app.GriddedFault.TriangleList(SelectedPatchPos,1:3);
                    SelVerticesList  = unique(SelectedVertIndx(:));
                    SeletedVerts     = app.GriddedFault.LocalPosVals(SelVerticesList,:);
                

                    MinStkLngthVal   = min(SeletedVerts(:,2));                                MaxStkLngthVal = max(SeletedVerts(:,2));
                    MinDipLngthVal   = min(SeletedVerts(:,3));                                MaxDipLngthVal = max(SeletedVerts(:,3));
                    FaultLength      = (MaxStkLngthVal-MinStkLngthVal);                       FaultWidth     = (MaxDipLngthVal-MinDipLngthVal);
                    
                    ApproxSquareNum  = (FaultLength*FaultWidth)/app.GriddedFault.MeanPatchSize;
                    AspectRatio      = FaultLength/FaultWidth;
                    PtchNumInDip     = round(sqrt((ApproxSquareNum*10)/AspectRatio));
                    PtchNumInStk     = round((ApproxSquareNum*10)/PtchNumInDip);
                    %------------------------------
                    if (UseNewSeed == 1)
                        app.RandParasStrgt(1).Vals = rng('shuffle'); %give all sections of a fault the same random numbers for field generation, is actually used only once -for the entire fault with all its sections- but seems cleaner to do it that way
                        for (jj = 2 : size(SelSections,1))
                            app.RandParasStrgt(jj).Vals = app.RandParasStrgt(1).Vals;
                        end
                    end
                    %------------------------------
                    sampsize   = [FaultWidth/PtchNumInDip, FaultLength/PtchNumInStk];
                    %---------------------------------------
                    corr       = [CorrInDip,    CorrInStk, HurstExp];%default == 1;        ak (van Karman)
                    %--------------------------------------
                    % GENERATE THE SPATIAL RANDOM FIELDS
                    RoughFric =  app.Strength_Field(ii).FltSurface ; %  SpecSyn2(app, [FaultWidth FaultLength],sampsize,corr,acf,1, 1, app.RandParasStrgt(SelSections(1,1)).Vals); % the "100" is a dummy for figure number, not used
                    % MADE THE SPATIAL RANDOM FIELD
                    %---------------------------------------
                   
                    PatchPosSelGrid     = find(app.GriddedFault.TriangleList(:,5) == FltIDUniq(ii,1));
                    LocalPosSelGrid     = app.GriddedFault.LocalCentVals(PatchPosSelGrid,:);
                    MeanPatchSize       = app.GriddedFault.MeanPatchSize;
                    
                    ApproxSquareNum     = (FaultLength*FaultWidth)/MeanPatchSize;
                    AspectRatio         = FaultLength/FaultWidth;
                    PtchNumInDipregrid  = round(sqrt((ApproxSquareNum)/AspectRatio));
                    PtchNumInStkregrid  = round((ApproxSquareNum)/PtchNumInDipregrid);
                    %-----------------------------
                    VectAlongStkregrid  = linspace(MinStkLngthVal,MaxStkLngthVal,PtchNumInStkregrid);
                    VectAlongDipregrid  = linspace(MinDipLngthVal,MaxDipLngthVal,PtchNumInDipregrid);
                    %regridding....regridding....regridding....regridding....
                    RegriddedRoughFric  = imresize(RoughFric,[PtchNumInDipregrid,PtchNumInStkregrid],'nearest');
                    %regridding....regridding....regridding....regridding....
                  %  RegriddedRoughFric  = RegriddedRoughFric - min(RegriddedRoughFric(:));
                    %interpolatinginterpolatinginterpolatinginterpolatinginterpolatinginterpolating
                    [X,Y]               = meshgrid(VectAlongStkregrid, VectAlongDipregrid);
                    manual_RoughFric       = interp2(X,Y, RegriddedRoughFric ,LocalPosSelGrid(:,1),LocalPosSelGrid(:,2),'nearest');
                    %interpolatinginterpolatinginterpolatinginterpolatinginterpolatinginterpolating
                    %-----------------------------

                    IsInSeis          = app.GriddedFault.FricVals(PatchPosSelGrid, 2)  > 0; %those with an actual stress drop
                    IsSeisIndex       = PatchPosSelGrid(IsInSeis);
                    
        if ia == 1
                    if app.Manual_Strength.select_se( ii , ia ) == 1
                app.GriddedFault.FricVals(IsSeisIndex,8) = manual_RoughFric  ;
                    else
                app.GriddedFault.FricVals(IsSeisIndex,8) = 0  ;
                    end
        elseif ia == 2
                    if app.Manual_Strength.select_se( ii , ia ) == 1
                app.GriddedFault.FricVals(IsSeisIndex,9) = manual_RoughFric  ;
                    else
                app.GriddedFault.FricVals(IsSeisIndex,9) = 0  ;
                    end
        elseif ia == 3
                    if app.Manual_Strength.select_se( ii , ia ) == 1
                app.GriddedFault.FricVals(IsSeisIndex,10) = manual_RoughFric  ;
                    else
                app.GriddedFault.FricVals(IsSeisIndex,10) = 0  ;
                    end
        else
                    if app.Manual_Strength.select_se( ii , ia ) == 1
                app.GriddedFault.FricVals(IsSeisIndex,11) = manual_RoughFric  ;
                    else
                app.GriddedFault.FricVals(IsSeisIndex,11) = 0  ;
                    end
        end


                end
            end

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.LoadFaults_Button = uibutton(app.Panel, 'push');
            app.LoadFaults_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadFaults_ButtonPushed, true);
            app.LoadFaults_Button.Text = 'Load faults';
            app.FaultlistListBoxLabel = uilabel(app.Panel);
            app.FaultlistListBoxLabel.Text = 'Fault list';
            app.FaultList_ListBox = uilistbox(app.Panel);
            app.FaultList_ListBox.Items = {'Fault name'};
            app.FaultList_ListBox.ItemsData = {'1'};
            app.FaultList_ListBox.ValueChangedFcn = createCallbackFcn(app, @FaultList_ListBoxValueChanged, true);
            app.FaultList_ListBox.Value = '1';
            app.ViewangleEditFieldLabel = uilabel(app.Panel);
            app.ViewangleEditFieldLabel.Text = 'View angle';
            app.ViewAngle_EditField = uieditfield(app.Panel, 'text');
            app.ViewAngle_EditField.Value = '40 20';
            app.FigEditFieldLabel = uilabel(app.Panel);
            app.FigEditFieldLabel.Text = 'Fig #';
            app.FigNum_EditField = uieditfield(app.Panel, 'numeric');
            app.FigNum_EditField.Limits = [1 Inf];
            app.FigNum_EditField.RoundFractionalValues = 'on';
            app.FigNum_EditField.ValueDisplayFormat = '%d';
            app.FigNum_EditField.Value = 2;
            app.ElementEditFieldLabel = uilabel(app.Panel);
            app.ElementEditFieldLabel.Text = 'Element #';
            app.ElementEditField = uieditfield(app.Panel, 'numeric');
            app.ElementEditField.RoundFractionalValues = 'on';
            app.ElementEditField.ValueDisplayFormat = '%d';
            app.ElementEditField.Editable = 'off';
            app.Plotfaults_Button = uibutton(app.Panel, 'push');
            app.Plotfaults_Button.ButtonPushedFcn = createCallbackFcn(app, @Plotfaults_ButtonPushed, true);
            app.Plotfaults_Button.Text = 'Plot faults';
            app.Panel_2 = uipanel(app.UIFigure);
            app.RoughSeed_DropDown = uidropdown(app.Panel_2);
            app.RoughSeed_DropDown.Items = {'New seed', 'Previous seed'};
            app.RoughSeed_DropDown.ItemsData = {'1', '2'};
            app.RoughSeed_DropDown.ValueChangedFcn = createCallbackFcn(app, @RoughSeed_DropDownValueChanged, true);
            app.RoughSeed_DropDown.Value = '1';
            app.RoughType_DropDown = uidropdown(app.Panel_2);
            app.RoughType_DropDown.Items = {'anisotrop. von Karman ACF', 'exponential ACF', 'fractal roughness', 'Gaussian roughness', 'anisotrop., Candela et al. 2009', 'random midpoint displ.'};
            app.RoughType_DropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.RoughType_DropDown.ValueChangedFcn = createCallbackFcn(app, @RoughType_DropDownValueChanged, true);
            app.RoughType_DropDown.Value = '1';
            app.andEditFieldLabel = uilabel(app.Panel_2);
            app.andEditFieldLabel.Text = 'and';
            app.RoughCorrLengthStrike_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughCorrLengthStrike_EditField.Limits = [0 Inf];
            app.RoughCorrLengthStrike_EditField.ValueDisplayFormat = '%4.2f';
            app.RoughCorrLengthStrike_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughCorrLengthStrike_EditFieldValueChanged, true);
            app.RoughCorrLengthStrike_EditField.Value = 10;
            app.CorrlengthinstrikeanddipkmEditFieldLabel = uilabel(app.Panel_2);
            app.CorrlengthinstrikeanddipkmEditFieldLabel.Text = 'Corr-length (in strike and dip, km)';
            app.RoughCorrLengthDip_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughCorrLengthDip_EditField.Limits = [0 Inf];
            app.RoughCorrLengthDip_EditField.ValueDisplayFormat = '%4.2f';
            app.RoughCorrLengthDip_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughCorrLengthDip_EditFieldValueChanged, true);
            app.RoughCorrLengthDip_EditField.Value = 5;
            app.CorrlengthvariabilitykmEditFieldLabel = uilabel(app.Panel_2);
            app.CorrlengthvariabilitykmEditFieldLabel.Text = 'Corr-length variability (km)';
            app.RoughCorrLengthVari_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughCorrLengthVari_EditField.Limits = [0 Inf];
            app.RoughCorrLengthVari_EditField.ValueDisplayFormat = '%4.2f';
            app.RoughCorrLengthVari_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughCorrLengthVari_EditFieldValueChanged, true);
            app.RoughCorrLengthVariType_DropDown = uidropdown(app.Panel_2);
            app.RoughCorrLengthVariType_DropDown.Items = {'uniform', 'normal'};
            app.RoughCorrLengthVariType_DropDown.ItemsData = {'1', '2'};
            app.RoughCorrLengthVariType_DropDown.ValueChangedFcn = createCallbackFcn(app, @RoughCorrLengthVariType_DropDownValueChanged, true);
            app.RoughCorrLengthVariType_DropDown.Value = '1';
            app.RoughHurstVariType_DropDown = uidropdown(app.Panel_2);
            app.RoughHurstVariType_DropDown.Items = {'uniform', 'normal'};
            app.RoughHurstVariType_DropDown.ItemsData = {'1', '2'};
            app.RoughHurstVariType_DropDown.ValueChangedFcn = createCallbackFcn(app, @RoughHurstVariType_DropDownValueChanged, true);
            app.RoughHurstVariType_DropDown.Value = '1';
            app.andEditField_2Label = uilabel(app.Panel_2);
            app.andEditField_2Label.Text = 'and';
            app.RoughHurstStrike_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughHurstStrike_EditField.Limits = [0 1];
            app.RoughHurstStrike_EditField.ValueDisplayFormat = '%2.2f';
            app.RoughHurstStrike_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughHurstStrike_EditFieldValueChanged, true);
            app.RoughHurstStrike_EditField.Value = 0.8;
            app.HurstexpinstrikeanddipLabel = uilabel(app.Panel_2);
            app.HurstexpinstrikeanddipLabel.Text = 'Hurst exp. (in strike and dip)';
            app.RoughHurstDip_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughHurstDip_EditField.Limits = [0 1];
            app.RoughHurstDip_EditField.ValueDisplayFormat = '%2.2f';
            app.RoughHurstDip_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughHurstDip_EditFieldValueChanged, true);
            app.RoughHurstDip_EditField.Value = 0.8;
            app.HurstexpvariabilityLabel = uilabel(app.Panel_2);
            app.HurstexpvariabilityLabel.Text = 'Hurst exp. variability';
            app.RoughHurstVari_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughHurstVari_EditField.Limits = [0 1];
            app.RoughHurstVari_EditField.ValueDisplayFormat = '%2.2f';
            app.RoughHurstVari_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughHurstVari_EditFieldValueChanged, true);
            app.FractaldimensionEditFieldLabel = uilabel(app.Panel_2);
            app.FractaldimensionEditFieldLabel.Text = 'Fractal dimension';
            app.RoughFractalDimension_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughFractalDimension_EditField.Limits = [0 Inf];
            app.RoughFractalDimension_EditField.ValueDisplayFormat = '%4.2f';
            app.RoughFractalDimension_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughFractalDimension_EditFieldValueChanged, true);
            app.RoughFractalDimension_EditField.Value = 2;
            app.CornerwavenumberEditFieldLabel = uilabel(app.Panel_2);
            app.CornerwavenumberEditFieldLabel.Text = 'Corner wave number';
            app.RoughCornerWaveNum_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughCornerWaveNum_EditField.Limits = [0 Inf];
            app.RoughCornerWaveNum_EditField.ValueDisplayFormat = '%4.2f';
            app.RoughCornerWaveNum_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughCornerWaveNum_EditFieldValueChanged, true);
            app.RoughCornerWaveNum_EditField.Value = 0.01;
            app.nonlinscalingexpEditFieldLabel = uilabel(app.Panel_2);
            app.nonlinscalingexpEditFieldLabel.Text = 'non-lin. scaling exp.';
            app.RoughNonLinScalingExp_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughNonLinScalingExp_EditField.Limits = [0 Inf];
            app.RoughNonLinScalingExp_EditField.ValueDisplayFormat = '%4.2f';
            app.RoughNonLinScalingExp_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughNonLinScalingExp_EditFieldValueChanged, true);
            app.RoughNonLinScalingExp_EditField.Value = 1;
            app.PrefactorforunitlengthisfractionEditFieldLabel = uilabel(app.Panel_2);
            app.PrefactorforunitlengthisfractionEditFieldLabel.Text = 'Pre-factor (for unit length, is fraction)';
            app.RoughPreFact_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughPreFact_EditField.Limits = [0 Inf];
            app.RoughPreFact_EditField.ValueDisplayFormat = '%4.3f';
            app.RoughPreFact_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughPreFact_EditFieldValueChanged, true);
            app.RoughPreFact_EditField.Value = 0.01;
            app.PrefactorvariabilityEditFieldLabel = uilabel(app.Panel_2);
            app.PrefactorvariabilityEditFieldLabel.Text = 'Pre-factor variability';
            app.RoughPreFactVari_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughPreFactVari_EditField.ValueDisplayFormat = '%4.3f';
            app.RoughPreFactVari_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughPreFactVari_EditFieldValueChanged, true);
            app.RoughPreFactVariType_DropDown = uidropdown(app.Panel_2);
            app.RoughPreFactVariType_DropDown.Items = {'uniform', 'normal'};
            app.RoughPreFactVariType_DropDown.ItemsData = {'1', '2'};
            app.RoughPreFactVariType_DropDown.ValueChangedFcn = createCallbackFcn(app, @RoughPreFactVariType_DropDownValueChanged, true);
            app.RoughPreFactVariType_DropDown.Value = '1';
            app.GenerateRoughness_Button = uibutton(app.Panel_2, 'push');
            app.GenerateRoughness_Button.ButtonPushedFcn = createCallbackFcn(app, @GenerateRoughness_ButtonPushed, true);
            app.GenerateRoughness_Button.Text = 'Generate Roughness';
            app.CombinewGrid_Button = uibutton(app.Panel_2, 'push');
            app.CombinewGrid_Button.ButtonPushedFcn = createCallbackFcn(app, @CombinewGrid_ButtonPushed, true);
            app.CombinewGrid_Button.Text = 'Combine w/ Grid';
            app.RMSLEditFieldLabel = uilabel(app.Panel_2);
            app.RMSLEditFieldLabel.Text = 'RMS/L';
            app.RMSL_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RMSL_EditField.Limits = [0 Inf];
            app.RMSL_EditField.ValueDisplayFormat = '%4.4f';
            app.PlotRoughness_CheckBox = uicheckbox(app.Panel_2);
            app.PlotRoughness_CheckBox.Text = 'Plot roughness';
            app.PlotRoughness_CheckBox.Value = true;
            app.ShowRoughnessStats_CheckBox = uicheckbox(app.Panel_2);
            app.ShowRoughnessStats_CheckBox.Text = 'Show roughness stats';
            app.normalizeprefactorwithlengthkmLabel = uilabel(app.Panel_2);
            app.normalizeprefactorwithlengthkmLabel.Text = 'normalize pre-factor with length (km)';
            app.RoughNormalizePreFact_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughNormalizePreFact_EditField.Limits = [0 Inf];
            app.RoughNormalizePreFact_EditField.ValueDisplayFormat = '%4.2f';
            app.RoughNormalizePreFact_EditField.Value = 40;
            app.RMDdecayLabel = uilabel(app.Panel_2);
            app.RMDdecayLabel.Text = 'RMD decay';
            app.RoughRMD_decay_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughRMD_decay_EditField.Limits = [0 Inf];
            app.RoughRMD_decay_EditField.ValueDisplayFormat = '%4.2f';
            app.RoughRMD_decay_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughRMD_decay_EditFieldValueChanged, true);
            app.RoughRMD_decay_EditField.Value = 0.8;
            app.Load_SurfTrace_Button = uibutton(app.Panel_2, 'push');
            app.Load_SurfTrace_Button.ButtonPushedFcn = createCallbackFcn(app, @Load_SurfTrace_ButtonPushed, true);
            app.Load_SurfTrace_Button.Enable = 'off';
            app.Load_SurfTrace_Button.Visible = 'off';
            app.Load_SurfTrace_Button.Text = 'Load digitized fault surface trace(s)';
            app.Use_SurfTrace_CheckBox = uicheckbox(app.Panel_2);
            app.Use_SurfTrace_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Use_SurfTrace_CheckBoxValueChanged, true);
            app.Use_SurfTrace_CheckBox.Enable = 'off';
            app.Use_SurfTrace_CheckBox.Visible = 'off';
            app.Use_SurfTrace_CheckBox.Text = 'utilize trace(s)';
            app.ExtractSave_SurfTrace_Button = uibutton(app.Panel_2, 'push');
            app.ExtractSave_SurfTrace_Button.ButtonPushedFcn = createCallbackFcn(app, @ExtractSave_SurfTrace_ButtonPushed, true);
            app.ExtractSave_SurfTrace_Button.Enable = 'off';
            app.ExtractSave_SurfTrace_Button.Visible = 'off';
            app.ExtractSave_SurfTrace_Button.Text = 'Extract/Save fault surface trace(s)';
            app.Use_SurfTraceCopyDown_CheckBox = uicheckbox(app.Panel_2);
            app.Use_SurfTraceCopyDown_CheckBox.Enable = 'off';
            app.Use_SurfTraceCopyDown_CheckBox.Visible = 'off';
            app.Use_SurfTraceCopyDown_CheckBox.Text = 'copy trace down';
            app.kmroughnesslengthscaleLabel = uilabel(app.Panel_2);
            app.kmroughnesslengthscaleLabel.Text = 'km, roughness length scale';
            app.RoughLengthScale_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughLengthScale_EditField.Limits = [0 Inf];
            app.RoughLengthScale_EditField.ValueDisplayFormat = '%5.1f';
            app.RoughLengthScale_EditField.Value = 1;
            app.Use_OnlyCopy_CheckBox = uicheckbox(app.Panel_2);
            app.Use_OnlyCopy_CheckBox.Enable = 'off';
            app.Use_OnlyCopy_CheckBox.Visible = 'off';
            app.Use_OnlyCopy_CheckBox.Text = 'only copy';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Tooltip = {'this is given as a FRACTION of  "mean leg length" => if that is 1000m, then a 3e-5 gives a D_c value of 0.03m'};
            app.staticfrictioncoeffEditFieldLabel = uilabel(app.Panel_3);
            app.staticfrictioncoeffEditFieldLabel.Text = 'static friction coeff. +/-';
            app.FricStatFric_EditField = uieditfield(app.Panel_3, 'numeric');
            app.FricStatFric_EditField.Limits = [0 Inf];
            app.FricStatFric_EditField.ValueDisplayFormat = '%4.2f';
            app.FricStatFric_EditField.ValueChangedFcn = createCallbackFcn(app, @FricStatFric_EditFieldValueChanged, true);
            app.FricStatFric_EditField.Value = 0.6;
            app.variationEditFieldLabel = uilabel(app.Panel_3);
            app.variationEditFieldLabel.Text = '% variation';
            app.FricStatFricVari_EditField = uieditfield(app.Panel_3, 'numeric');
            app.FricStatFricVari_EditField.Limits = [0 Inf];
            app.FricStatFricVari_EditField.ValueDisplayFormat = '%4.2f';
            app.FricStatFricVari_EditField.ValueChangedFcn = createCallbackFcn(app, @FricStatFricVari_EditFieldValueChanged, true);
            app.FricStatFricVari_EditField.Value = 3;
            app.dynfrictioncoeffLabel = uilabel(app.Panel_3);
            app.dynfrictioncoeffLabel.Text = 'dyn. friction coeff. +/-';
            app.FricDynFric_EditField = uieditfield(app.Panel_3, 'numeric');
            app.FricDynFric_EditField.Limits = [0 Inf];
            app.FricDynFric_EditField.ValueDisplayFormat = '%4.2f';
            app.FricDynFric_EditField.ValueChangedFcn = createCallbackFcn(app, @FricDynFric_EditFieldValueChanged, true);
            app.FricDynFric_EditField.Value = 0.54;
            app.FricUseAmB_CheckBox = uicheckbox(app.Panel_3);
            app.FricUseAmB_CheckBox.ValueChangedFcn = createCallbackFcn(app, @FricUseAmB_CheckBoxValueChanged, true);
            app.FricUseAmB_CheckBox.Text = 'use (a-b) profile';
            app.FricUseAmB_CheckBox.Value = true;
            app.FricAmBType_DropDown = uidropdown(app.Panel_3);
            app.FricAmBType_DropDown.Items = {'parabolic', 'piecewise linear'};
            app.FricAmBType_DropDown.ItemsData = {'1', '2'};
            app.FricAmBType_DropDown.ValueChangedFcn = createCallbackFcn(app, @FricAmBType_DropDownValueChanged, true);
            app.FricAmBType_DropDown.Value = '1';
            app.FricShowAmB_ShowButton = uibutton(app.Panel_3, 'push');
            app.FricShowAmB_ShowButton.ButtonPushedFcn = createCallbackFcn(app, @FricShowAmB_ShowButtonPushed, true);
            app.FricShowAmB_ShowButton.Text = 'Show';
            app.geothermgradientKkmforabprofileEditFieldLabel = uilabel(app.Panel_3);
            app.geothermgradientKkmforabprofileEditFieldLabel.Text = 'geotherm gradient (K/km), for (a-b) profile';
            app.FricGeoTherm_EditField = uieditfield(app.Panel_3, 'numeric');
            app.FricGeoTherm_EditField.Limits = [0 Inf];
            app.FricGeoTherm_EditField.ValueDisplayFormat = '%4.2f';
            app.FricGeoTherm_EditField.ValueChangedFcn = createCallbackFcn(app, @FricGeoTherm_EditFieldValueChanged, true);
            app.FricGeoTherm_EditField.Value = 20;
            app.GenerateStrength_Button = uibutton(app.Panel_3, 'push');
            app.GenerateStrength_Button.ButtonPushedFcn = createCallbackFcn(app, @GenerateStrength_ButtonPushed, true);
            app.GenerateStrength_Button.Text = 'Generate Strength';
            app.PlotStrength_Button = uibutton(app.Panel_3, 'push');
            app.PlotStrength_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotStrength_ButtonPushed, true);
            app.PlotStrength_Button.Text = 'Plot strength';
            app.forweakeinguseLabel = uilabel(app.Panel_3);
            app.forweakeinguseLabel.Text = 'for weakeing use:';
            app.D_cLabel = uilabel(app.Panel_3);
            app.D_cLabel.Tooltip = {'this is given as a FRACTION of  "mean leg length" => if that is 1000m, then a 3e-5 gives a D_c value of 0.03m'};
            app.D_cLabel.Text = 'D_c (*) +/-';
            app.FricDc_EditField = uieditfield(app.Panel_3, 'numeric');
            app.FricDc_EditField.Limits = [0 Inf];
            app.FricDc_EditField.ValueDisplayFormat = '%2.2e';
            app.FricDc_EditField.ValueChangedFcn = createCallbackFcn(app, @FricDc_EditFieldValueChanged, true);
            app.FricDc_EditField.Tooltip = {'this is given as a FRACTION of  "mean leg length" => if that is 1000m, then a 3e-5 gives a D_c value of 0.03m'};
            app.FricDc_EditField.Value = 7e-05;
            app.variEditFieldLabel = uilabel(app.Panel_3);
            app.variEditFieldLabel.Text = '% vari.';
            app.FricDcVari_EditField = uieditfield(app.Panel_3, 'numeric');
            app.FricDcVari_EditField.Limits = [0 Inf];
            app.FricDcVari_EditField.ValueDisplayFormat = '%4.2f';
            app.FricDcVari_EditField.ValueChangedFcn = createCallbackFcn(app, @FricDcVari_EditFieldValueChanged, true);
            app.FricDcVari_EditField.Value = 30;
            app.FricAllowReset_CheckBox = uicheckbox(app.Panel_3);
            app.FricAllowReset_CheckBox.Text = 'reset friction parameters after slip-event on cell';
            app.FricAllowReset_CheckBox.Value = true;
            app.degreestartingtemperatureatsurfaceLabel = uilabel(app.Panel_3);
            app.degreestartingtemperatureatsurfaceLabel.Text = 'degree starting temperature (at surface)';
            app.FricGeoThermStartTemp_EditField = uieditfield(app.Panel_3, 'numeric');
            app.FricGeoThermStartTemp_EditField.ValueDisplayFormat = '%4.2f';
            app.FricGeoThermStartTemp_EditField.Value = 10;
            app.sameforallsectionsoffaultPanel = uipanel(app.Panel_3);
            app.sameforallsectionsoffaultPanel.Title = 'same for all sections of fault';
            app.StrengthUseFractalDistrib_CheckBox = uicheckbox(app.sameforallsectionsoffaultPanel);
            app.StrengthUseFractalDistrib_CheckBox.ValueChangedFcn = createCallbackFcn(app, @StrengthUseFractalDistrib_CheckBoxValueChanged, true);
            app.StrengthUseFractalDistrib_CheckBox.Text = 'use fractal strength distribution';
            app.StrengthSeed_DropDown = uidropdown(app.sameforallsectionsoffaultPanel);
            app.StrengthSeed_DropDown.Items = {'New seed', 'Previous seed'};
            app.StrengthSeed_DropDown.ItemsData = {'1', '2'};
            app.StrengthSeed_DropDown.ValueChangedFcn = createCallbackFcn(app, @StrengthSeed_DropDownValueChanged, true);
            app.StrengthSeed_DropDown.Value = '1';
            app.andEditField_3Label = uilabel(app.sameforallsectionsoffaultPanel);
            app.andEditField_3Label.Text = 'and';
            app.StrengthCorrLengthStrike_EditField = uieditfield(app.sameforallsectionsoffaultPanel, 'numeric');
            app.StrengthCorrLengthStrike_EditField.Limits = [0 Inf];
            app.StrengthCorrLengthStrike_EditField.ValueDisplayFormat = '%4.2f';
            app.StrengthCorrLengthStrike_EditField.ValueChangedFcn = createCallbackFcn(app, @StrengthCorrLengthStrike_EditFieldValueChanged, true);
            app.StrengthCorrLengthStrike_EditField.Value = 10;
            app.CorrlengthinstrikeanddipkmEditField_2Label = uilabel(app.sameforallsectionsoffaultPanel);
            app.CorrlengthinstrikeanddipkmEditField_2Label.Text = 'Corr-length (in strike and dip, km)';
            app.StrengthCorrLengthDip_EditField = uieditfield(app.sameforallsectionsoffaultPanel, 'numeric');
            app.StrengthCorrLengthDip_EditField.Limits = [0 Inf];
            app.StrengthCorrLengthDip_EditField.ValueDisplayFormat = '%4.2f';
            app.StrengthCorrLengthDip_EditField.ValueChangedFcn = createCallbackFcn(app, @StrengthCorrLengthDip_EditFieldValueChanged, true);
            app.StrengthCorrLengthDip_EditField.Value = 5;
            app.HurstexponentEditFieldLabel = uilabel(app.sameforallsectionsoffaultPanel);
            app.HurstexponentEditFieldLabel.Text = 'Hurst exponent';
            app.StrengthHurstExp_EditField = uieditfield(app.sameforallsectionsoffaultPanel, 'numeric');
            app.StrengthHurstExp_EditField.Limits = [0 1];
            app.StrengthHurstExp_EditField.ValueDisplayFormat = '%2.2f';
            app.StrengthHurstExp_EditField.ValueChangedFcn = createCallbackFcn(app, @StrengthHurstExp_EditFieldValueChanged, true);
            app.StrengthHurstExp_EditField.Value = 0.999;
            app.waterlevelcutoffLabel = uilabel(app.sameforallsectionsoffaultPanel);
            app.waterlevelcutoffLabel.Text = '"water-level" cutoff (%)';
            app.StrengthWaterLevel_EditField = uieditfield(app.sameforallsectionsoffaultPanel, 'numeric');
            app.StrengthWaterLevel_EditField.Limits = [0 99];
            app.StrengthWaterLevel_EditField.ValueDisplayFormat = '%4.2f';
            app.StrengthWaterLevel_EditField.ValueChangedFcn = createCallbackFcn(app, @StrengthWaterLevel_EditFieldValueChanged, true);
            app.PlotStrength_CheckBox = uicheckbox(app.Panel_3);
            app.PlotStrength_CheckBox.Text = 'Plot strength';
            app.PlotStrength_CheckBox.Value = true;
            app.averagestrengthacrossallcellsEditFieldLabel = uilabel(app.Panel_3);
            app.averagestrengthacrossallcellsEditFieldLabel.Text = 'average strength across all cells';
            app.AverageStrength_EditField = uieditfield(app.Panel_3, 'numeric');
            app.AverageStrength_EditField.Limits = [0 Inf];
            app.AverageStrength_EditField.ValueDisplayFormat = '%4.2f';
            app.PlotWithRoughness_CheckBox = uicheckbox(app.Panel_3);
            app.PlotWithRoughness_CheckBox.Text = 'Plot with roughness';
            app.SetStrengthFieldButton = uibutton(app.Panel_3, 'push');
            app.SetStrengthFieldButton.ButtonPushedFcn = createCallbackFcn(app, @SetStrengthFieldButtonPushed, true);
            app.SetStrengthFieldButton.Text = 'Set Strength Field';
            app.SetTextArea = uitextarea(app.Panel_3);
            app.SetTextArea.Value = {'Manual Strength Field'};
            app.SetTextArea_2 = uitextarea(app.Panel_3);
            app.SetTextArea_2.Value = {'Random Strength Field '};
            app.variationLabel = uilabel(app.Panel_3);
            app.variationLabel.Text = '%  variation';
            app.FricDynFricVari_EditField = uieditfield(app.Panel_3, 'numeric');
            app.FricDynFricVari_EditField.Limits = [0 Inf];
            app.FricDynFricVari_EditField.ValueDisplayFormat = '%4.2f';
            app.FricDynFricVari_EditField.ValueChangedFcn = createCallbackFcn(app, @FricDynFricVari_EditFieldValueChanged, true);
            app.FricDynFricVari_EditField.Value = 3;
            app.ofmodelstogenerateEditFieldLabel = uilabel(app.UIFigure);
            app.ofmodelstogenerateEditFieldLabel.Text = '# of models to generate';
            app.SaveModels2Generate_EditField = uieditfield(app.UIFigure, 'numeric');
            app.SaveModels2Generate_EditField.Limits = [1 Inf];
            app.SaveModels2Generate_EditField.RoundFractionalValues = 'on';
            app.SaveModels2Generate_EditField.ValueDisplayFormat = '%d';
            app.SaveModels2Generate_EditField.Value = 1;
            app.currmodelLabel = uilabel(app.UIFigure);
            app.currmodelLabel.Text = 'curr. model';
            app.SaveCurrentModelNum_EditField = uieditfield(app.UIFigure, 'numeric');
            app.SaveCurrentModelNum_EditField.Limits = [1 Inf];
            app.SaveCurrentModelNum_EditField.RoundFractionalValues = 'on';
            app.SaveCurrentModelNum_EditField.ValueDisplayFormat = '%d';
            app.SaveCurrentModelNum_EditField.Editable = 'off';
            app.SaveCurrentModelNum_EditField.Value = 1;
            app.SaveRoughStrenth_Button = uibutton(app.UIFigure, 'push');
            app.SaveRoughStrenth_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveRoughStrenth_ButtonPushed, true);
            app.SaveRoughStrenth_Button.Text = 'Save model';
            app.addLabel = uilabel(app.UIFigure);
            app.addLabel.Text = 'add';
            app.tooverburdenstressMPaEditFieldLabel = uilabel(app.UIFigure);
            app.tooverburdenstressMPaEditFieldLabel.Text = 'to overburden stress (MPa)';
            app.HalfspaceOverburden_EditField = uieditfield(app.UIFigure, 'numeric');
            app.HalfspaceOverburden_EditField.Limits = [0 Inf];
            app.HalfspaceOverburden_EditField.ValueDisplayFormat = '%4.2f';
            app.HalfspaceOverburden_EditField.Value = 5;
            app.shearmodulusGPaLabel = uilabel(app.UIFigure);
            app.shearmodulusGPaLabel.Text = 'shear modulus (GPa)';
            app.HalfspaceShearMod_EditField = uieditfield(app.UIFigure, 'numeric');
            app.HalfspaceShearMod_EditField.Limits = [0 Inf];
            app.HalfspaceShearMod_EditField.ValueDisplayFormat = '%4.2f';
            app.HalfspaceShearMod_EditField.Value = 30;
            app.PoissonsratioEditFieldLabel = uilabel(app.UIFigure);
            app.PoissonsratioEditFieldLabel.Text = 'Poisson''s ratio';
            app.HalfspacePoisson_EditField = uieditfield(app.UIFigure, 'numeric');
            app.HalfspacePoisson_EditField.Limits = [0 Inf];
            app.HalfspacePoisson_EditField.ValueDisplayFormat = '%4.2f';
            app.HalfspacePoisson_EditField.Value = 0.25;
            app.SavenameLabel = uilabel(app.UIFigure);
            app.SavenameLabel.Text = 'Save name';
            app.SaveName_EditField = uieditfield(app.UIFigure, 'text');
            app.LoadRoughnessStrength_Button = uibutton(app.UIFigure, 'push');
            app.LoadRoughnessStrength_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadRoughnessStrength_ButtonPushed, true);
            app.LoadRoughnessStrength_Button.Text = 'Load previous';
            app.halfspacedensitykgm3EditFieldLabel = uilabel(app.UIFigure);
            app.halfspacedensitykgm3EditFieldLabel.Text = 'half-space density (kg/m^3)';
            app.HalfspaceDensity_EditField = uieditfield(app.UIFigure, 'numeric');
            app.HalfspaceDensity_EditField.Limits = [0 Inf];
            app.HalfspaceDensity_EditField.ValueDisplayFormat = '%5.1f';
            app.HalfspaceDensity_EditField.Value = 2700;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = B_RoughStrength_MCQsim_v1_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end